classdef SPectrA < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        button_load                   matlab.ui.control.Button
        button_save                   matlab.ui.control.Button
        status_loaded                 matlab.ui.control.Lamp
        status_saved                  matlab.ui.control.Lamp
        label_visualize_edit          matlab.ui.control.Label
        button_spectra                matlab.ui.control.Button
        button_functionalization_app  matlab.ui.control.Button
        DatasetsinloadedexperimentLabel  matlab.ui.control.Label
        listbox_status                matlab.ui.control.TextArea
        button_edit                   matlab.ui.control.Button
        label_filename                matlab.ui.control.Label
        label_dir                     matlab.ui.control.Label
        button_go_to_dir              matlab.ui.control.Button
        button_timetrace              matlab.ui.control.Button
        button_photoluminescence      matlab.ui.control.Button
        label_name                    matlab.ui.control.Label
    end


    properties (Access = private)
        child_app                   % Options dialog app
    end
    
    
    properties (Access = public)
        app_dir
        file_results
        file_metadata
        file_dir
        filename
        load_last_dir
        tts
        hsms
    end
    
    methods (Access = public)
        
        function CloseChildApp(app)
            delete(app.child_app)
            app.status_saved.Color = 'red';
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
         
        % finds the folder of the running processing engine and sets the path    
        app.app_dir = strrep(mfilename('fullpath'),mfilename,''); 
        cd(app.app_dir); 
        
        addpath(app.app_dir);
        addpath(genpath(app.app_dir));
        app.load_last_dir = app.app_dir;
                       
        end

        % Button pushed function: button_load
        function DatasetLoad(app, event)
            % saved to red
            app.status_saved.Color = 'Red'; 
        
            % check if already loaded in results
            if isempty(app.file_results) == 0
                answer = questdlg('This will erase the currently loaded in experiment. Are you sure?', ...
                'Currently loaded experiment will be cleared', ...
                'Yes', 'No', 'Yes');
                switch answer
                    case 'Yes'
                        app.listbox_status.Value = '';
                        app.file_results = [];
                        app.file_metadata = [];
                    case 'No'
                        return
                end
            end
        
            app.status_loaded.Color = 'Yellow';
            
            % check if user clicked folder
            [tmp_filename, tmp_pathname] = uigetfile({'*.mat';'*.*'},'Select results to load in. This can be both PLASMON as SPectrA results'); 
            if all(tmp_pathname == 0)
                app.status_loaded.Color = 'Red';
                warndlg('You have to specify a file!','!! Warning !!')
                return
            end
            
            % check valid directory
            if ~exist([tmp_pathname '\Metadata.mat'], 'file')
                app.status_loaded.Color = 'Red';
                warndlg('Metadata.mat missing from directory, cannot be loaded! It has to be named Metadata.mat!','!! Warning !!')
                return
            end
            
            % set variables for new dataset
            app.label_dir.Text = tmp_pathname;            
            app.file_dir = tmp_pathname;
            app.filename = tmp_filename;
            app.load_last_dir = tmp_pathname;
            split_filename = split(app.file_dir,'\');
            app.label_filename.Text = split_filename(end-1);
            
            % load data
            app.file_results = load([app.file_dir '\' app.filename]);
            app.file_metadata = load([ app.file_dir '\Metadata.mat']);
            
            % find a ROI with all the datasets in it
            rois = fieldnames(app.file_results);
            n_datasets_max = 0;
            n_datasets_max_index = -1;
            for i=1:size(rois)
                n_datasets = length((fieldnames(app.file_results.(rois{i}))));
                if n_datasets > n_datasets_max
                    n_datasets_max = n_datasets;
                    n_datasets_max_index = i;
                end
            end
            
            
            % find datasets
            datasets = app.file_results.(['ROI_' int2str(n_datasets_max_index)]);
            % remove x, y and index (those are ROI specific, not results)
            fields = ["index", "x", "y"];
            datasets = rmfield(datasets, fields);
            % remove SingleParticle and SignificantParticle there
            try
                datasets = rmfield(datasets, ["SingleParticle", "SignificantParticle"]);
            catch
                % if not there, do not remove
            end
            
            % make list of TTs and HSMs
            datasets_names = fieldnames(datasets);
            app.tts = {};
            app.hsms = {};
            for i=1:numel(datasets_names)
                if strcmp(datasets.(datasets_names{i}).type, 'TT')
                    app.tts{end+1} = datasets_names{i};
                elseif strcmp(datasets.(datasets_names{i}).type, 'HSM')
                    app.hsms{end+1} = datasets_names{i};
                end
            end
            % update listbox
            
            app.listbox_status.Value = datasets_names;
            
            % update buttons
            app.status_loaded.Color = 'Green';
            set(app.button_edit, 'Enable','on')
            set(app.button_save, 'Enable','on')           
            set(app.button_functionalization_app, 'Enable','on')
            set(app.button_photoluminescence, 'Enable','on')
            set(app.button_spectra, 'Enable','on')
            set(app.button_timetrace, 'Enable','on')            
        end

        % Button pushed function: button_save
        function DatasetSave(app, event)
                        
        app.status_saved.Color = 'Yellow'; 
        
        tmp_struct = app.file_results;
        save([app.file_dir '\Results_SPectrA.mat'], '-struct', 'tmp_struct', '-v7.3');
        clear tmp_struct
        
        app.status_saved.Color = 'Green'; 
           
        end

        % Button pushed function: button_spectra
        function ShowSpectra(app, event)
            
        %self.app = ParticleSpectraApp(app);    
                  
        end

        % Button pushed function: button_go_to_dir
        function OpenFileDir(app, event)
            
        winopen(app.file_dir);    
                        
        end

        % Button pushed function: button_functionalization_app
        function ShowFunctionalizationApp(app, event)
            
        %self.app = FunctionalizationApp(app);    
           
        end

        % Button pushed function: button_edit
        function DatasetEdit(app, event)
        
        %self.app = EditFile(app);  
            
        end

        % Button pushed function: button_timetrace
        function ShowTimetrace(app, event)
            
        app.child_app = Page_TT(app);                        
            
        end

        % Button pushed function: button_photoluminescence
        function ShowPhotoluminescence(app, event)
        
        %self.app = PhotoluminescenceAnal(app);     
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.button_load = uibutton(app.UIFigure, 'push');
            app.button_load.ButtonPushedFcn = createCallbackFcn(app, @DatasetLoad, true);
            app.button_load.Text = 'Load';
            app.button_save = uibutton(app.UIFigure, 'push');
            app.button_save.ButtonPushedFcn = createCallbackFcn(app, @DatasetSave, true);
            app.button_save.Enable = 'off';
            app.button_save.Text = 'Save';
            app.status_loaded = uilamp(app.UIFigure);
            app.status_saved = uilamp(app.UIFigure);
            app.label_visualize_edit = uilabel(app.UIFigure);
            app.label_visualize_edit.Text = 'Visualize & Edit ...';
            app.button_spectra = uibutton(app.UIFigure, 'push');
            app.button_spectra.ButtonPushedFcn = createCallbackFcn(app, @ShowSpectra, true);
            app.button_spectra.Enable = 'off';
            app.button_spectra.Text = {'Show'; 'Spectra'};
            app.button_functionalization_app = uibutton(app.UIFigure, 'push');
            app.button_functionalization_app.ButtonPushedFcn = createCallbackFcn(app, @ShowFunctionalizationApp, true);
            app.button_functionalization_app.Enable = 'off';
            app.button_functionalization_app.Text = {'Functionalization'; 'App'};
            app.DatasetsinloadedexperimentLabel = uilabel(app.UIFigure);
            app.DatasetsinloadedexperimentLabel.Text = 'Datasets in loaded experiment:';
            app.listbox_status = uitextarea(app.UIFigure);
            app.button_edit = uibutton(app.UIFigure, 'push');
            app.button_edit.ButtonPushedFcn = createCallbackFcn(app, @DatasetEdit, true);
            app.button_edit.Enable = 'off';
            app.button_edit.Text = 'Edit';
            app.label_filename = uilabel(app.UIFigure);
            app.label_filename.Text = 'File Name';
            app.label_dir = uilabel(app.UIFigure);
            app.label_dir.Text = 'Folder dir';
            app.button_go_to_dir = uibutton(app.UIFigure, 'push');
            app.button_go_to_dir.ButtonPushedFcn = createCallbackFcn(app, @OpenFileDir, true);
            app.button_go_to_dir.Text = '>';
            app.button_timetrace = uibutton(app.UIFigure, 'push');
            app.button_timetrace.ButtonPushedFcn = createCallbackFcn(app, @ShowTimetrace, true);
            app.button_timetrace.Enable = 'off';
            app.button_timetrace.Text = {'Timetrace'; 'Analysis'};
            app.button_photoluminescence = uibutton(app.UIFigure, 'push');
            app.button_photoluminescence.ButtonPushedFcn = createCallbackFcn(app, @ShowPhotoluminescence, true);
            app.button_photoluminescence.Enable = 'off';
            app.button_photoluminescence.Text = 'Photoluminescence Analysis';
            app.label_name = uilabel(app.UIFigure);
            app.label_name.Text = 'SPectrA 5.0 pre-1';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SPectrA

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end