classdef SPectrA_4 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        NewButton                   matlab.ui.control.Button
        LoadButton                  matlab.ui.control.Button
        SaveButton                  matlab.ui.control.Button
        Loaded                      matlab.ui.control.Lamp
        Saved                       matlab.ui.control.Lamp
        AddLabel                    matlab.ui.control.Label
        VisualizeEditLabel          matlab.ui.control.Label
        AddHSMButton                matlab.ui.control.Button
        AddTimetraceButton          matlab.ui.control.Button
        ShowSpectraButton           matlab.ui.control.Button
        FunctionalizationAppButton  matlab.ui.control.Button
        StatusTextAreaLabel         matlab.ui.control.Label
        StatusTextArea              matlab.ui.control.TextArea
        EditButton                  matlab.ui.control.Button
        FileNameLabel               matlab.ui.control.Label
        FolderdirLabel              matlab.ui.control.Label
        GoToFolderButton            matlab.ui.control.Button
        TimetraceAnalysisButton     matlab.ui.control.Button
        PhotoluminescenceAnalysisButton  matlab.ui.control.Button
        AddPOLButton                matlab.ui.control.Button
    end


    properties (Access = private)
        
%         ParticleFile
%         FileFolder
%         FileName
        PopUpApp                   % Options dialog app
    end
    
    
    properties (Access = public)
        ProgramFolder
        ParticleFile
        FileFolder
        FileName
    end

    methods (Access = public) 
        
        function LoadInitialization(app,filename,filefolder,FileND2,threshold)  
             
             app.FileName = filename;
             app.FileFolder = filefolder;
            
             delete(app.PopUpApp)  
             run('scripts\initialize_file.m')
             
                 loadedVars =  load([ app.FileFolder '\' app.FileName '.mat'  ]);
                 app.ParticleFile = loadedVars.Particle;  
                 
                 
             app.FolderdirLabel.Text = app.FileFolder;
             app.FileNameLabel.Text = app.FileName;
             app.StatusTextArea.Value = fieldnames(app.ParticleFile);
             app.Loaded.Color = 'Green'; 
             app.Saved.Color = 'Green'; 
                       
         end         
                
        function RunHSMAnal(app,newFieldName,FileND2,FileSpecCorr,ROI_size_gauss,PclesToAnal,DoSingle)  
            
            delete(app.PopUpApp)  
            run('scripts\run_hsm.m')
            
            app.StatusTextArea.Value = fieldnames(app.ParticleFile);
            app.Saved.Color = 'Red'; 
                      
        end
    
        function RunPOLAnal(app,newFieldName,POLFileND2,ROI_size_gauss,MethodToUse, Pol)  
            
            delete(app.PopUpApp)  
            run('scripts\run_pol.m')
            
            app.StatusTextArea.Value = fieldnames(app.ParticleFile);
            app.Saved.Color = 'Red'; 
                      
        end  
   
        function RunTimetraceAnal(app,newFieldName,FileND2,ROI_size_gauss,DriftCorrection,IfCrop, MethodToUse,PclesToAnal)  
            
            delete(app.PopUpApp)  
            run('scripts\run_timetrace.m')
            
            app.StatusTextArea.Value = fieldnames(app.ParticleFile);
            app.Saved.Color = 'Red';
            
        end
        
        function CallBack(app)  
             
             delete(app.PopUpApp)
                                
             app.StatusTextArea.Value = fieldnames(app.ParticleFile);
             app.Saved.Color = 'Red'; 
                       
         end 

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
         
        % finds the folder of the running processing engine and sets the path    
        app.ProgramFolder = strrep(mfilename('fullpath'),mfilename,''); 
        cd(app.ProgramFolder); 
        
        addpath(app.ProgramFolder);
        addpath(genpath(app.ProgramFolder));
                       
        end

        % Button pushed function: AddTimetraceButton
        function AddTimetraceButtonPushed(app, event)

        % Open the options dialog and pass inputs   
        app.PopUpApp = SettingsTimetrace(app,app.FileFolder, app.FileName);

        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
             
            app.Loaded.Color = 'Yellow';
            
            [filename, pathname] = uigetfile({'*.mat';'*.*'},'File Selector');            
            app.FolderdirLabel.Text = pathname;            
            app.FileFolder = pathname(1:end-1);
            app.FileName = filename(1:end-4);
            app.FileNameLabel.Text = app.FileName;
                         
             IsThereFile = dir([ app.FileFolder '\' app.FileName '.mat' ]);
             
             if isempty(IsThereFile) == 1
                 warndlg('You have to specify the folder and the file!','!! Warning !!')
                 app.Loaded.Color = 'Red';
             else
                 % load the main file - Particle
                 loadedVars =  load([ app.FileFolder '\' app.FileName '.mat'  ]);
                 app.ParticleFile = loadedVars.Particle;                            
                 app.StatusTextArea.Value = fieldnames(app.ParticleFile);
                 app.Loaded.Color = 'Green';                 
             end 
                           
         
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
                        
        app.Saved.Color = 'Yellow'; 
            
            Population = load([app.FileFolder '\' app.FileName '_CoordinatesFile.mat' ]);        
            
                for i = 1:Population.N_Object       
                    Population.Pcles.Single(i) = app.ParticleFile(i).SinglePart;       
                    Population.Pcles.Significant(i) = app.ParticleFile(i).SignificantPart;           
                end

            save( [app.FileFolder '\' app.FileName '_CoordinatesFile.mat' ] ,'-struct', 'Population','-v7.3');  % saving the coordinates file

            Particle = app.ParticleFile;
            
                save( [app.FileFolder '\' app.FileName '.mat' ] , 'Particle','-v7.3');  % saving the coordinates file
            
            app.Saved.Color = 'Green'; 
            
            clear Particle;
            clear Population;
            
        end

        % Button pushed function: AddHSMButton
        function AddHSMButtonPushed(app, event)
             
        % Open the options dialog and pass inputs   
        app.PopUpApp = SettingsHSM(app,app.FileFolder, app.FileName);
                                  
        end

        % Button pushed function: NewButton
        function NewButtonPushed(app, event)
             
        % Open the options dialog and pass inputs   
        app.PopUpApp = InitializeFiles(app);

        end

        % Button pushed function: ShowSpectraButton
        function ShowSpectraButtonPushed(app, event)
            
        app.PopUpApp = ParticleSpectraApp(app);    
                  
        end

        % Button pushed function: GoToFolderButton
        function GoToFolderButtonPushed(app, event)
            
        winopen(app.FileFolder);    
                        
        end

        % Button pushed function: FunctionalizationAppButton
        function FunctionalizationAppButtonPushed(app, event)
            
        app.PopUpApp = FunctionalizationApp(app);    
           
        end

        % Button pushed function: EditButton
        function EditButtonPushed(app, event)
        
        app.PopUpApp = EditFile(app);  
            
        end

        % Button pushed function: TimetraceAnalysisButton
        function TimetraceAnalysisButtonPushed(app, event)
            
        app.PopUpApp = TimetraceAnalApp(app);                        
            
        end

        % Button pushed function: PhotoluminescenceAnalysisButton
        function PhotoluminescenceAnalysisButtonPushed(app, event)
        
        app.PopUpApp = PhotoluminescenceAnal(app);     
            
        end

        % Button pushed function: AddPOLButton
        function AddPOLButtonPushed(app, event)
            
        % Open the options dialog and pass inputs   
        app.PopUpApp = SettingsPOL(app,app.FileFolder, app.FileName);
        
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.NewButton = uibutton(app.UIFigure, 'push');
            app.NewButton.ButtonPushedFcn = createCallbackFcn(app, @NewButtonPushed, true);
            app.NewButton.Text = 'New';
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.Loaded = uilamp(app.UIFigure);
            app.Saved = uilamp(app.UIFigure);
            app.AddLabel = uilabel(app.UIFigure);
            app.AddLabel.Text = 'Add ...';
            app.VisualizeEditLabel = uilabel(app.UIFigure);
            app.VisualizeEditLabel.Text = 'Visualize & Edit ...';
            app.AddHSMButton = uibutton(app.UIFigure, 'push');
            app.AddHSMButton.ButtonPushedFcn = createCallbackFcn(app, @AddHSMButtonPushed, true);
            app.AddHSMButton.Text = 'Add HSM';
            app.AddTimetraceButton = uibutton(app.UIFigure, 'push');
            app.AddTimetraceButton.ButtonPushedFcn = createCallbackFcn(app, @AddTimetraceButtonPushed, true);
            app.AddTimetraceButton.Text = 'Add Timetrace';
            app.ShowSpectraButton = uibutton(app.UIFigure, 'push');
            app.ShowSpectraButton.ButtonPushedFcn = createCallbackFcn(app, @ShowSpectraButtonPushed, true);
            app.ShowSpectraButton.Text = {'Show'; 'Spectra'};
            app.FunctionalizationAppButton = uibutton(app.UIFigure, 'push');
            app.FunctionalizationAppButton.ButtonPushedFcn = createCallbackFcn(app, @FunctionalizationAppButtonPushed, true);
            app.FunctionalizationAppButton.Text = {'Functionalization'; 'App'};
            app.StatusTextAreaLabel = uilabel(app.UIFigure);
            app.StatusTextAreaLabel.Text = 'Status:';
            app.StatusTextArea = uitextarea(app.UIFigure);
            app.EditButton = uibutton(app.UIFigure, 'push');
            app.EditButton.ButtonPushedFcn = createCallbackFcn(app, @EditButtonPushed, true);
            app.EditButton.Text = 'Edit';
            app.FileNameLabel = uilabel(app.UIFigure);
            app.FileNameLabel.Text = 'File Name';
            app.FolderdirLabel = uilabel(app.UIFigure);
            app.FolderdirLabel.Text = 'Folder dir';
            app.GoToFolderButton = uibutton(app.UIFigure, 'push');
            app.GoToFolderButton.ButtonPushedFcn = createCallbackFcn(app, @GoToFolderButtonPushed, true);
            app.GoToFolderButton.Text = '>';
            app.TimetraceAnalysisButton = uibutton(app.UIFigure, 'push');
            app.TimetraceAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @TimetraceAnalysisButtonPushed, true);
            app.TimetraceAnalysisButton.Text = {'Timetrace'; 'Analysis'};
            app.PhotoluminescenceAnalysisButton = uibutton(app.UIFigure, 'push');
            app.PhotoluminescenceAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @PhotoluminescenceAnalysisButtonPushed, true);
            app.PhotoluminescenceAnalysisButton.Text = 'Photoluminescence Analysis';
            app.AddPOLButton = uibutton(app.UIFigure, 'push');
            app.AddPOLButton.ButtonPushedFcn = createCallbackFcn(app, @AddPOLButtonPushed, true);
            app.AddPOLButton.Text = 'Add POL';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SPectrA_4

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end