classdef EditFile < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        MeasurementsListBoxLabel  matlab.ui.control.Label
        MeasurementsList          matlab.ui.control.ListBox
        ParticlestokeepPanel      matlab.ui.container.Panel
        NoneCheckBox              matlab.ui.control.CheckBox
        SingleCheckBox            matlab.ui.control.CheckBox
        SignificantCheckBox       matlab.ui.control.CheckBox
        NoneLamp                  matlab.ui.control.Lamp
        SingleLamp                matlab.ui.control.Lamp
        SignificantLamp           matlab.ui.control.Lamp
        EraseButton               matlab.ui.control.Button
        ParametersLabel           matlab.ui.control.Label
        ParametersList            matlab.ui.control.ListBox
        RenamefieldPanel          matlab.ui.container.Panel
        NewnameEditFieldLabel     matlab.ui.control.Label
        NewNameEditField          matlab.ui.control.EditField
        RenameButton              matlab.ui.control.Button
    end


    properties (Access = private)

    end
        
    properties (Access = public)
         MainApp
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
        
        clc;    
            
        app.MainApp = mainapp;
        clear mainapp;
        
        app.NoneLamp.Color = 'Green';
        
        app.SingleLamp.Color = 'Red';
        app.SingleCheckBox.Enable = 'off';
        
        app.SignificantLamp.Color = 'Red';
        app.SignificantCheckBox.Enable = 'off';
                
        app.MeasurementsList.Items = fieldnames(app.MainApp.ParticleFile); 
          
        
        FieldPresence = 0;
        SinglePresence = 0;
        SignificantPresence = 0;
        
        for i = app.MainApp.ParticleFile(1).Number:app.MainApp.ParticleFile(end).Number
            
            if isstruct(app.MainApp.ParticleFile(i).(app.MeasurementsList.Value)) == 1
                
                FieldPresence = 1;
                index = i;
                
                if SinglePresence < 2                                   
                    if app.MainApp.ParticleFile(i).SinglePart == 1
                        SinglePresence = SinglePresence + 1;
                    end  
                end
                
                if SignificantPresence < 2                                   
                    if app.MainApp.ParticleFile(i).SignificantPart == 1
                        SignificantPresence = SignificantPresence + 1;
                    end  
                end
                
            end                
        end   

        if SinglePresence >= 1
            app.SingleCheckBox.Enable = 'on';
            app.SingleLamp.Color = 'Green';
        else
            app.SingleCheckBox.Enable = 'off';
            app.SingleLamp.Color = 'Red';            
        end    
        
        if SignificantPresence >= 1
            app.SignificantCheckBox.Enable = 'on';
            app.SignificantLamp.Color = 'Green';
        else
            app.SignificantCheckBox.Enable = 'off';
            app.SignificantLamp.Color = 'Red';            
        end         
        
        if FieldPresence == 1                         
            app.ParametersList.Items = [ 'All fields...' ; fieldnames(app.MainApp.ParticleFile(index).(app.MeasurementsList.Value)) ]; 
            
            app.RenameButton.Enable = 'on';
            app.EraseButton.Enable = 'on'; 
        
        else     
            app.ParametersList.Items = [ {'All fields...'} ];  
            
            if isequal(app.MeasurementsList.Value,'Number') == 1 | isequal(app.MeasurementsList.Value,'SinglePart') == 1 | isequal(app.MeasurementsList.Value,'SignificantPart') == 1 
                app.RenameButton.Enable = 'off';
                app.EraseButton.Enable = 'off';
            else
                app.RenameButton.Enable = 'on';
                app.EraseButton.Enable = 'on';    
            end                
        end     
        
        app.NewNameEditField.Value = app.MeasurementsList.Value;
        
        
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
        
        CallBack(app.MainApp);        
        delete(app) 
            
        end

        % Value changed function: MeasurementsList
        function MeasurementsListValueChanged(app, event)
        
        clc;
            
        FieldPresence = 0;
        SinglePresence = 0;
        SignificantPresence = 0;
        
        for i = app.MainApp.ParticleFile(1).Number:app.MainApp.ParticleFile(end).Number
            
            if isstruct(app.MainApp.ParticleFile(i).(app.MeasurementsList.Value)) == 1
                
                FieldPresence = 1;
                index = i;
                
                if SinglePresence < 2                                   
                    if app.MainApp.ParticleFile(i).SinglePart == 1
                        SinglePresence = SinglePresence + 1;
                    end  
                end
                
                if SignificantPresence < 2                                   
                    if app.MainApp.ParticleFile(i).SignificantPart == 1
                        SignificantPresence = SignificantPresence + 1;
                    end  
                end
                
            end                
        end   

        if SinglePresence >= 1
            app.SingleCheckBox.Enable = 'on';
            app.SingleLamp.Color = 'Green';
        else
            app.SingleCheckBox.Enable = 'off';
            app.SingleLamp.Color = 'Red';            
        end    
        
        if SignificantPresence >= 1
            app.SignificantCheckBox.Enable = 'on';
            app.SignificantLamp.Color = 'Green';
        else
            app.SignificantCheckBox.Enable = 'off';
            app.SignificantLamp.Color = 'Red';            
        end         
        
        if FieldPresence == 1                         
            app.ParametersList.Items = [ 'All fields...' ; fieldnames(app.MainApp.ParticleFile(index).(app.MeasurementsList.Value)) ]; 
            
            app.RenameButton.Enable = 'on';
            app.EraseButton.Enable = 'on'; 
        
        else     
            app.ParametersList.Items = [ {'All fields...'} ];  
            
            if isequal(app.MeasurementsList.Value,'Number') == 1 | isequal(app.MeasurementsList.Value,'SinglePart') == 1 | isequal(app.MeasurementsList.Value,'SignificantPart') == 1 
                app.RenameButton.Enable = 'off';
                app.EraseButton.Enable = 'off';
            else
                app.RenameButton.Enable = 'on';
                app.EraseButton.Enable = 'on';    
            end                
        end   
                  
        app.NewNameEditField.Value = app.MeasurementsList.Value;
        
                  
        end

        % Value changed function: NoneCheckBox
        function NoneCheckBoxValueChanged(app, event)
            
        if app.NoneCheckBox.Value == 1    
            app.SingleCheckBox.Value = 0;
            app.SignificantCheckBox.Value = 0;  
        end    
            
        end

        % Value changed function: SingleCheckBox
        function SingleCheckBoxValueChanged(app, event)
            
        if app.SingleCheckBox.Value == 1    
            app.NoneCheckBox.Value = 0;  
        end             
                        
        end

        % Value changed function: SignificantCheckBox
        function SignificantCheckBoxValueChanged(app, event)

        if app.SignificantCheckBox.Value == 1    
            app.NoneCheckBox.Value = 0;  
        end             
             
        end

        % Value changed function: ParametersList
        function ParametersListValueChanged(app, event)
            
        if isequal(app.ParametersList.Value,'All fields...') == 1     
            app.NewNameEditField.Value = app.MeasurementsList.Value;            
        else
            app.NewNameEditField.Value = app.ParametersList.Value;           
        end   
        
        end

        % Button pushed function: RenameButton
        function RenameButtonPushed(app, event)

        clc;    
            
        if isequal(app.ParametersList.Value,'All fields...') == 1     
            [app.MainApp.ParticleFile(:).(app.NewNameEditField.Value)] = app.MainApp.ParticleFile(:).(app.MeasurementsList.Value);
            app.MainApp.ParticleFile = rmfield(app.MainApp.ParticleFile,app.MeasurementsList.Value);    
        else
            for i = app.MainApp.ParticleFile(1).Number:app.MainApp.ParticleFile(end).Number     
                if isfield(app.MainApp.ParticleFile(i),app.MeasurementsList.Value) == 1
                    if isfield(app.MainApp.ParticleFile(i).(app.MeasurementsList.Value),app.ParametersList.Value) == 1
                        [app.MainApp.ParticleFile(i).(app.MeasurementsList.Value).(app.NewNameEditField.Value)] = app.MainApp.ParticleFile(i).(app.MeasurementsList.Value).(app.ParametersList.Value);
                        app.MainApp.ParticleFile(i).(app.MeasurementsList.Value) = rmfield(app.MainApp.ParticleFile(i).(app.MeasurementsList.Value),app.ParametersList.Value);                        
                    end
                end
            end 
        end


        app.NoneLamp.Color = 'Green';
        
        app.SingleLamp.Color = 'Red';
        app.SingleCheckBox.Enable = 'off';
        
        app.SignificantLamp.Color = 'Red';
        app.SignificantCheckBox.Enable = 'off';
                
        app.MeasurementsList.Items = fieldnames(app.MainApp.ParticleFile); 
          
        
        FieldPresence = 0;
        SinglePresence = 0;
        SignificantPresence = 0;
        
        for i = app.MainApp.ParticleFile(1).Number:app.MainApp.ParticleFile(end).Number
            
            if isstruct(app.MainApp.ParticleFile(i).(app.MeasurementsList.Value)) == 1
                
                FieldPresence = 1;
                index = i;
                
                if SinglePresence < 2                                   
                    if app.MainApp.ParticleFile(i).SinglePart == 1
                        SinglePresence = SinglePresence + 1;
                    end  
                end
                
                if SignificantPresence < 2                                   
                    if app.MainApp.ParticleFile(i).SignificantPart == 1
                        SignificantPresence = SignificantPresence + 1;
                    end  
                end
                
            end                
        end   

        if SinglePresence >= 1
            app.SingleCheckBox.Enable = 'on';
            app.SingleLamp.Color = 'Green';
        else
            app.SingleCheckBox.Enable = 'off';
            app.SingleLamp.Color = 'Red';            
        end    
        
        if SignificantPresence >= 1
            app.SignificantCheckBox.Enable = 'on';
            app.SignificantLamp.Color = 'Green';
        else
            app.SignificantCheckBox.Enable = 'off';
            app.SignificantLamp.Color = 'Red';            
        end         
        
        if FieldPresence == 1                         
            app.ParametersList.Items = [ 'All fields...' ; fieldnames(app.MainApp.ParticleFile(index).(app.MeasurementsList.Value)) ]; 
            
            app.RenameButton.Enable = 'on';
            app.EraseButton.Enable = 'on'; 
        
        else     
            app.ParametersList.Items = [ {'All fields...'} ];  
            
            if isequal(app.MeasurementsList.Value,'Number') == 1 | isequal(app.MeasurementsList.Value,'SinglePart') == 1 | isequal(app.MeasurementsList.Value,'SignificantPart') == 1 
                app.RenameButton.Enable = 'off';
                app.EraseButton.Enable = 'off';
            else
                app.RenameButton.Enable = 'on';
                app.EraseButton.Enable = 'on';    
            end                
        end     
        
        app.ParametersList.Value = app.ParametersList.Items{1};
        app.NewNameEditField.Value = app.MeasurementsList.Value;
            
        end

        % Button pushed function: EraseButton
        function EraseButtonPushed(app, event)
        
        clc;
            
        if app.NoneCheckBox.Value == 1    
            
            if isequal(app.ParametersList.Value,'All fields...') == 1     
                app.MainApp.ParticleFile = rmfield(app.MainApp.ParticleFile,app.MeasurementsList.Value);    
            else
                for i = app.MainApp.ParticleFile(1).Number:app.MainApp.ParticleFile(end).Number     
                    if isfield(app.MainApp.ParticleFile(i),app.MeasurementsList.Value) == 1
                        if isfield(app.MainApp.ParticleFile(i).(app.MeasurementsList.Value),app.ParametersList.Value) == 1
                            app.MainApp.ParticleFile(i).(app.MeasurementsList.Value) = rmfield(app.MainApp.ParticleFile(i).(app.MeasurementsList.Value),app.ParametersList.Value);                        
                        end
                    end
                end 
            end
                        
        else    
            
            ToKeep = [];    
            if app.SingleCheckBox.Value == 1    
                for i = app.MainApp.ParticleFile(1).Number:app.MainApp.ParticleFile(end).Number
                    if app.MainApp.ParticleFile(i).SinglePart == 1
                            ToKeep = [ ToKeep i ]; 
                    end
                end
            end 
            
            if app.SignificantCheckBox.Value == 1    
                for i = app.MainApp.ParticleFile(1).Number:app.MainApp.ParticleFile(end).Number
                    if app.MainApp.ParticleFile(i).SignificantPart == 1
                            ToKeep = [ ToKeep i ]; 
                    end
                end
            end
            
            ToKeep = sort(unique(ToKeep)); 
            ToErase = setdiff([app.MainApp.ParticleFile(1).Number:app.MainApp.ParticleFile(end).Number],ToKeep);
            
                for i = ToErase                    
                    if isequal(app.ParametersList.Value,'All fields...') == 1     
                        app.MainApp.ParticleFile(i) = rmfield(app.MainApp.ParticleFile(i),app.MeasurementsList.Value);    
                    else                    
                        if isfield(app.MainApp.ParticleFile(i),app.MeasurementsList.Value) == 1
                            if isfield(app.MainApp.ParticleFile(i).(app.MeasurementsList.Value),app.ParametersList.Value) == 1
                                app.MainApp.ParticleFile(i).(app.MeasurementsList.Value) = rmfield(app.MainApp.ParticleFile(i).(app.MeasurementsList.Value),app.ParametersList.Value);                        
                            end
                        end
                    end
                end
      
        end    
        
        
        app.NoneLamp.Color = 'Green';
        
        app.SingleLamp.Color = 'Red';
        app.SingleCheckBox.Enable = 'off';
        
        app.SignificantLamp.Color = 'Red';
        app.SignificantCheckBox.Enable = 'off';
                
        app.MeasurementsList.Items = fieldnames(app.MainApp.ParticleFile); 
          
        
        FieldPresence = 0;
        SinglePresence = 0;
        SignificantPresence = 0;
        
        for i = app.MainApp.ParticleFile(1).Number:app.MainApp.ParticleFile(end).Number
            
            if isstruct(app.MainApp.ParticleFile(i).(app.MeasurementsList.Value)) == 1
                
                FieldPresence = 1;
                index = i;
                
                if SinglePresence < 2                                   
                    if app.MainApp.ParticleFile(i).SinglePart == 1
                        SinglePresence = SinglePresence + 1;
                    end  
                end
                
                if SignificantPresence < 2                                   
                    if app.MainApp.ParticleFile(i).SignificantPart == 1
                        SignificantPresence = SignificantPresence + 1;
                    end  
                end
                
            end                
        end   

        if SinglePresence >= 1
            app.SingleCheckBox.Enable = 'on';
            app.SingleLamp.Color = 'Green';
        else
            app.SingleCheckBox.Enable = 'off';
            app.SingleLamp.Color = 'Red';            
        end    
        
        if SignificantPresence >= 1
            app.SignificantCheckBox.Enable = 'on';
            app.SignificantLamp.Color = 'Green';
        else
            app.SignificantCheckBox.Enable = 'off';
            app.SignificantLamp.Color = 'Red';            
        end         
        
        if FieldPresence == 1                         
            app.ParametersList.Items = [ 'All fields...' ; fieldnames(app.MainApp.ParticleFile(index).(app.MeasurementsList.Value)) ]; 
            
            app.RenameButton.Enable = 'on';
            app.EraseButton.Enable = 'on'; 
        
        else     
            app.ParametersList.Items = [ {'All fields...'} ];  
            
            if isequal(app.MeasurementsList.Value,'Number') == 1 | isequal(app.MeasurementsList.Value,'SinglePart') == 1 | isequal(app.MeasurementsList.Value,'SignificantPart') == 1 
                app.RenameButton.Enable = 'off';
                app.EraseButton.Enable = 'off';
            else
                app.RenameButton.Enable = 'on';
                app.EraseButton.Enable = 'on';    
            end                
        end     
        
        app.ParametersList.Value = app.ParametersList.Items{1};
        app.NewNameEditField.Value = app.MeasurementsList.Value;


        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.MeasurementsListBoxLabel = uilabel(app.UIFigure);
            app.MeasurementsListBoxLabel.Text = 'Measurements';
            app.MeasurementsList = uilistbox(app.UIFigure);
            app.MeasurementsList.ValueChangedFcn = createCallbackFcn(app, @MeasurementsListValueChanged, true);
            app.ParticlestokeepPanel = uipanel(app.UIFigure);
            app.ParticlestokeepPanel.Title = 'Particles to keep';
            app.NoneCheckBox = uicheckbox(app.ParticlestokeepPanel);
            app.NoneCheckBox.ValueChangedFcn = createCallbackFcn(app, @NoneCheckBoxValueChanged, true);
            app.NoneCheckBox.Text = ' None';
            app.NoneCheckBox.Value = true;
            app.SingleCheckBox = uicheckbox(app.ParticlestokeepPanel);
            app.SingleCheckBox.ValueChangedFcn = createCallbackFcn(app, @SingleCheckBoxValueChanged, true);
            app.SingleCheckBox.Text = ' Single';
            app.SignificantCheckBox = uicheckbox(app.ParticlestokeepPanel);
            app.SignificantCheckBox.ValueChangedFcn = createCallbackFcn(app, @SignificantCheckBoxValueChanged, true);
            app.SignificantCheckBox.Text = ' Significant';
            app.NoneLamp = uilamp(app.ParticlestokeepPanel);
            app.SingleLamp = uilamp(app.ParticlestokeepPanel);
            app.SignificantLamp = uilamp(app.ParticlestokeepPanel);
            app.EraseButton = uibutton(app.UIFigure, 'push');
            app.EraseButton.ButtonPushedFcn = createCallbackFcn(app, @EraseButtonPushed, true);
            app.EraseButton.Text = 'Erase';
            app.ParametersLabel = uilabel(app.UIFigure);
            app.ParametersLabel.Text = 'Parameters';
            app.ParametersList = uilistbox(app.UIFigure);
            app.ParametersList.ValueChangedFcn = createCallbackFcn(app, @ParametersListValueChanged, true);
            app.RenamefieldPanel = uipanel(app.UIFigure);
            app.RenamefieldPanel.Title = 'Rename field';
            app.NewnameEditFieldLabel = uilabel(app.RenamefieldPanel);
            app.NewnameEditFieldLabel.Text = 'New name:';
            app.NewNameEditField = uieditfield(app.RenamefieldPanel, 'text');
            app.RenameButton = uibutton(app.RenamefieldPanel, 'push');
            app.RenameButton.ButtonPushedFcn = createCallbackFcn(app, @RenameButtonPushed, true);
            app.RenameButton.Text = 'Rename';
        end
    end

    methods (Access = public)

        % Construct app
        function app = EditFile(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end