classdef FunctionalizationApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        ActionsPanel              matlab.ui.container.Panel
        ContrastButton            matlab.ui.control.Button
        PlasmonShiftButton        matlab.ui.control.Button
        SaveparticleButton        matlab.ui.control.Button
        SaveallButton             matlab.ui.control.Button
        NormalizeButton           matlab.ui.control.StateButton
        ReffrmLabel               matlab.ui.control.Label
        RefFrameField             matlab.ui.control.NumericEditField
        FrmLabel                  matlab.ui.control.Label
        ContrastFrameField        matlab.ui.control.NumericEditField
        RmvspikesButton           matlab.ui.control.StateButton
        TimetracePlot             matlab.ui.control.UIAxes
        FOVPlot                   matlab.ui.control.UIAxes
        SpectraPlot               matlab.ui.control.UIAxes
        LabelSPlambda             matlab.ui.control.Label
        LabelSPshift              matlab.ui.control.Label
        FrameLabel                matlab.ui.control.Label
        FrameSlider               matlab.ui.control.Slider
        FrameSpinnerLabel         matlab.ui.control.Label
        FrameSpinner              matlab.ui.control.Spinner
        ParticlePanel             matlab.ui.container.Panel
        PartSpinner               matlab.ui.control.Spinner
        SwitchSingle              matlab.ui.control.Switch
        LampSingle                matlab.ui.control.Lamp
        SignificantparticlePanel  matlab.ui.container.Panel
        SwitchSignificant         matlab.ui.control.Switch
        LampSignificant           matlab.ui.control.Lamp
        TimesEditFieldLabel       matlab.ui.control.Label
        TimeField                 matlab.ui.control.NumericEditField
        MeasurementsPanel         matlab.ui.container.Panel
        LoadTimetrace             matlab.ui.control.Lamp
        TimetraceDropDownLabel    matlab.ui.control.Label
        TimetraceDropDown         matlab.ui.control.DropDown
        LoadHSM1                  matlab.ui.control.Lamp
        InitialHSMLabel           matlab.ui.control.Label
        HSM1DropDown              matlab.ui.control.DropDown
        LoadHSM2                  matlab.ui.control.Lamp
        FinalHSMLabel             matlab.ui.control.Label
        HSM2DropDown              matlab.ui.control.DropDown
    end

    properties (Access = private)

         TimetraceName
         HSMXName
         HSMYName
         WorkFieldName
         TimetraceToPlot
    end

    properties (Access = public)
         MainApp
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
        
        app.MainApp = mainapp;
        clear mainapp;
                     
        app.LoadTimetrace.Color = 'Red';
        app.LoadHSM1.Color = 'Red';
        app.LoadHSM2.Color = 'Red';
            
        app.TimetraceDropDown.Items = fieldnames(app.MainApp.ParticleFile);
        app.HSM1DropDown.Items = fieldnames(app.MainApp.ParticleFile);
        app.HSM2DropDown.Items = fieldnames(app.MainApp.ParticleFile);
                
        app.SwitchSingle.ItemsData = [ 0 , 1 ];
        app.SwitchSignificant.ItemsData = [ 0 , 1 ];
        
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
        
        CallBack(app.MainApp);        
        delete(app)
            
        end

        % Value changed function: TimetraceDropDown
        function TimetraceDropDownValueChanged(app, event)
                    
            app.LoadTimetrace.Color = 'Yellow'; 
            app.TimetraceName = app.TimetraceDropDown.Value;
            
            app.PartSpinner.Limits = [app.MainApp.ParticleFile(1).Number  app.MainApp.ParticleFile(end).Number];
            
            app.LoadTimetrace.Color = 'Green';            
            
        end

        % Value changed function: HSM1DropDown
        function HSM1DropDownValueChanged(app, event)
            
            app.LoadHSM1.Color = 'Yellow'; 
            app.HSMXName = app.HSM1DropDown.Value;            
            app.LoadHSM1.Color = 'Green';
                     
        end

        % Value changed function: HSM2DropDown
        function HSM2DropDownValueChanged(app, event)
            
            app.LoadHSM2.Color = 'Yellow'; 
            app.HSMYName = app.HSM2DropDown.Value;            
            app.LoadHSM2.Color = 'Green';
            
        end

        % Value changed function: PartSpinner
        function PartSpinnerValueChanged(app, event)
          
           
            clc;
            cla(app.TimetracePlot);
            app.SwitchSingle.Value = app.MainApp.ParticleFile(app.PartSpinner.Value).SinglePart;
            app.SwitchSignificant.Value = app.MainApp.ParticleFile(app.PartSpinner.Value).SignificantPart;
            
            if app.SwitchSingle.Value == 1
                app.LampSingle.Color = 'Green';
            else
                app.LampSingle.Color = 'Red';
            end
            
            if app.SwitchSignificant.Value == 1
                app.LampSignificant.Color = 'Cyan';
            else
                app.LampSignificant.Color = 'Red';
            end    
                        

            Lorentz_eV = linspace(1248/(min(app.MainApp.ParticleFile(app.PartSpinner.Value).(app.HSMXName).Lambda)-30),1248/(max(app.MainApp.ParticleFile(app.PartSpinner.Value).(app.HSMXName).Lambda)+30),100);                                              
            
                plot(app.SpectraPlot,app.MainApp.ParticleFile(app.PartSpinner.Value).(app.HSMXName).Lambda,app.MainApp.ParticleFile(app.PartSpinner.Value).(app.HSMXName).Intensity,'ro',1248./Lorentz_eV,Lorentzfunction(app.MainApp.ParticleFile(app.PartSpinner.Value).(app.HSMXName).Lorentz,Lorentz_eV),'r-', app.MainApp.ParticleFile(app.PartSpinner.Value).(app.HSMYName).Lambda,app.MainApp.ParticleFile(app.PartSpinner.Value).(app.HSMYName).Intensity,'bo',1248./Lorentz_eV,Lorentzfunction(app.MainApp.ParticleFile(app.PartSpinner.Value).(app.HSMYName).Lorentz,Lorentz_eV),'b-');    
                                
            app.SpectraPlot.XLim = [ (min(app.MainApp.ParticleFile(app.PartSpinner.Value).(app.HSMXName).Lambda)-30)  (max(app.MainApp.ParticleFile(app.PartSpinner.Value).(app.HSMXName).Lambda)+30) ];
            app.TimetracePlot.Title.String = [];
            
            app.LabelSPlambda.Text = ['λ = ' num2str(round(app.MainApp.ParticleFile(app.PartSpinner.Value).(app.HSMXName).SPlambda,1)) ' nm'];
            app.LabelSPshift.Text = ['ΔSP = ' num2str( round( app.MainApp.ParticleFile(app.PartSpinner.Value).(app.HSMYName).SPlambda - app.MainApp.ParticleFile(app.PartSpinner.Value).(app.HSMXName).SPlambda , 1)) ' nm'];
             
            app.FrameSlider.Limits = [ 1 length(app.MainApp.ParticleFile(app.PartSpinner.Value).(app.TimetraceName).Time)  ];
            app.FrameSlider.Value = 1; 
            app.FrameSpinner.Limits = [  1 length(app.MainApp.ParticleFile(app.PartSpinner.Value).(app.TimetraceName).Time) ];
            app.FrameSpinner.Value = 1;
            app.TimeField.Value = app.MainApp.ParticleFile(app.PartSpinner.Value).(app.TimetraceName).Time(1);
            app.ContrastFrameField.Value = length(app.MainApp.ParticleFile(app.PartSpinner.Value).(app.TimetraceName).Time);
            
            
                app.TimetraceToPlot = app.MainApp.ParticleFile(app.PartSpinner.Value).(app.TimetraceName).Intensity;
                             
                if app.NormalizeButton.Value == 1                
                    ref = mean(hampel(app.TimetraceToPlot(3:app.RefFrameField.Value),5));
                    app.TimetraceToPlot = app.TimetraceToPlot ./ ref;                    
                end
                
                if app.RmvspikesButton.Value == 1                                   
                    app.TimetraceToPlot = hampel(app.TimetraceToPlot,5);                    
                end
                
                plot(app.TimetracePlot,app.MainApp.ParticleFile(app.PartSpinner.Value).(app.TimetraceName).Time, app.TimetraceToPlot ,'-','color', [1 0.5 0.5]);    
               
                app.TimetracePlot.Title.String = [ app.TimetraceName ': Particle no. ' num2str(app.PartSpinner.Value) ];

                        
            imagesc(app.FOVPlot,app.MainApp.ParticleFile(app.PartSpinner.Value).(app.TimetraceName).RawData(:,:,round(app.FrameSlider.Value)));          
            app.FOVPlot.XLim = [ 1 size(app.MainApp.ParticleFile(app.PartSpinner.Value).(app.TimetraceName).RawData,1)  ];
            app.FOVPlot.YLim = [ 1 size(app.MainApp.ParticleFile(app.PartSpinner.Value).(app.TimetraceName).RawData,2)  ];
            app.FOVPlot.XTick = [];
            app.FOVPlot.YTick = [];


        end

        % Value changed function: RefFrameField
        function RefFrameFieldValueChanged(app, event)
                 
                app.TimetraceToPlot = app.MainApp.ParticleFile(app.PartSpinner.Value).(app.TimetraceName).Intensity;
                
                if app.NormalizeButton.Value == 1                
                    ref = mean(hampel(app.TimetraceToPlot(3:app.RefFrameField.Value),5));
                    app.TimetraceToPlot = app.TimetraceToPlot ./ ref;                    
                end
                
                if app.RmvspikesButton.Value == 1                                   
                    app.TimetraceToPlot = hampel(app.TimetraceToPlot,5);                    
                end
                
                plot(app.TimetracePlot,app.MainApp.ParticleFile(app.PartSpinner.Value).(app.TimetraceName).Time, app.TimetraceToPlot ,'-','color', [1 0.5 0.5]);    
               
                app.TimetracePlot.Title.String = [ app.TimetraceName ': Particle no. ' num2str(app.PartSpinner.Value) ];          
            
        end

        % Value changed function: FrameSlider
        function FrameSliderValueChanged(app, event)
            
            app.FrameSpinner.Value = round(app.FrameSlider.Value);
            app.TimeField.Value = app.MainApp.ParticleFile(app.PartSpinner.Value).(app.TimetraceName).Time(round(app.FrameSlider.Value));
            
            imagesc(app.FOVPlot,app.MainApp.ParticleFile(app.PartSpinner.Value).(app.TimetraceName).RawData(:,:,round(app.FrameSlider.Value)));           

            plot(app.TimetracePlot,app.MainApp.ParticleFile(app.PartSpinner.Value).(app.TimetraceName).Time(1:round(app.FrameSlider.Value)), app.TimetraceToPlot(1:round(app.FrameSlider.Value)) ,'-','color', [1 0 0]);    
            hold(app.TimetracePlot);
            plot(app.TimetracePlot,app.MainApp.ParticleFile(app.PartSpinner.Value).(app.TimetraceName).Time(round(app.FrameSlider.Value):end), app.TimetraceToPlot(round(app.FrameSlider.Value):end) ,'-','color', [1 0.5 0.5]);  
            hold(app.TimetracePlot);
            
            
        end

        % Value changed function: NormalizeButton
        function NormalizeButtonValueChanged(app, event)
                
                app.TimetraceToPlot = app.MainApp.ParticleFile(app.PartSpinner.Value).(app.TimetraceName).Intensity;
                
                if app.NormalizeButton.Value == 1                
                    ref = mean(hampel(app.TimetraceToPlot(3:app.RefFrameField.Value),5));
                    app.TimetraceToPlot = app.TimetraceToPlot ./ ref;                    
                end
                
                if app.RmvspikesButton.Value == 1                                   
                    app.TimetraceToPlot = hampel(app.TimetraceToPlot,5);                    
                end
                
                plot(app.TimetracePlot,app.MainApp.ParticleFile(app.PartSpinner.Value).(app.TimetraceName).Time, app.TimetraceToPlot ,'-','color', [1 0.5 0.5]);    
               
                app.TimetracePlot.Title.String = [ app.TimetraceName ': Particle no. ' num2str(app.PartSpinner.Value) ];  
            
        end

        % Value changing function: FrameSlider
        function FrameSliderValueChanging(app, event)
            
            app.FrameSpinner.Value = round(event.Value);
            app.TimeField.Value = app.MainApp.ParticleFile(app.PartSpinner.Value).(app.TimetraceName).Time(round(event.Value));
            
            imagesc(app.FOVPlot,app.MainApp.ParticleFile(app.PartSpinner.Value).(app.TimetraceName).RawData(:,:,round(event.Value)));           

            plot(app.TimetracePlot,app.MainApp.ParticleFile(app.PartSpinner.Value).(app.TimetraceName).Time(1:round(event.Value)), app.TimetraceToPlot(1:round(event.Value)) ,'-','color', [1 0 0]);    
            hold(app.TimetracePlot);
            plot(app.TimetracePlot,app.MainApp.ParticleFile(app.PartSpinner.Value).(app.TimetraceName).Time(round(event.Value):end), app.TimetraceToPlot(round(event.Value):end) ,'-','color', [1 0.5 0.5]);  
            hold(app.TimetracePlot);
                        
        end

        % Value changed function: RmvspikesButton
        function RmvspikesButtonValueChanged(app, event)
                
                app.TimetraceToPlot = app.MainApp.ParticleFile(app.PartSpinner.Value).(app.TimetraceName).Intensity;
                
                if app.NormalizeButton.Value == 1                
                    ref = mean(hampel(app.TimetraceToPlot(3:app.RefFrameField.Value),5));
                    app.TimetraceToPlot = app.TimetraceToPlot ./ ref;                    
                end
                
                if app.RmvspikesButton.Value == 1                                   
                    app.TimetraceToPlot = hampel(app.TimetraceToPlot,5);                    
                end
                
                plot(app.TimetracePlot,app.MainApp.ParticleFile(app.PartSpinner.Value).(app.TimetraceName).Time, app.TimetraceToPlot ,'-','color', [1 0.5 0.5]);    
               
                app.TimetracePlot.Title.String = [ app.TimetraceName ': Particle no. ' num2str(app.PartSpinner.Value) ];  

        end

        % Callback function
        function LeaveButtonPushed(app, event)
                        
            CallBack(app.MainApp);        
            delete(app)   
           
        end

        % Value changed function: SwitchSingle
        function SwitchSingleValueChanged(app, event)
            
            app.MainApp.ParticleFile(app.PartSpinner.Value).SinglePart = app.SwitchSingle.Value;
                        
            if app.SwitchSingle.Value == 1
                app.LampSingle.Color = 'Green';
            else
                app.LampSingle.Color = 'Red';
            end    
        
        end

        % Value changed function: SwitchSignificant
        function SwitchSignificantValueChanged(app, event)
        
            app.MainApp.ParticleFile(app.PartSpinner.Value).SignificantPart = app.SwitchSignificant.Value;
  
            if app.SwitchSignificant.Value == 1
                app.LampSignificant.Color = 'Cyan';
            else
                app.LampSignificant.Color = 'Red';
            end
            
        end

        % Value changed function: FrameSpinner
        function FrameSpinnerValueChanged(app, event)
            
            app.FrameSlider.Value = app.FrameSpinner.Value;
            app.TimeField.Value = app.MainApp.ParticleFile(app.PartSpinner.Value).(app.TimetraceName).Time(round(app.FrameSpinner.Value));
            
            imagesc(app.FOVPlot,app.MainApp.ParticleFile(app.PartSpinner.Value).(app.TimetraceName).RawData(:,:,round(app.FrameSpinner.Value)));           

            plot(app.TimetracePlot,app.MainApp.ParticleFile(app.PartSpinner.Value).(app.TimetraceName).Time(1:round(app.FrameSpinner.Value)), app.TimetraceToPlot(1:round(app.FrameSpinner.Value)) ,'-','color', [1 0 0]);    
            hold(app.TimetracePlot);
            plot(app.TimetracePlot,app.MainApp.ParticleFile(app.PartSpinner.Value).(app.TimetraceName).Time(round(app.FrameSpinner.Value):end), app.TimetraceToPlot(round(app.FrameSpinner.Value):end) ,'-','color', [1 0.5 0.5]);  
            hold(app.TimetracePlot);
            
        end

        % Button pushed function: PlasmonShiftButton
        function PlasmonShiftButtonPushed(app, event)
            
        inx = 1;    
        for i = app.MainApp.ParticleFile(1).Number:app.MainApp.ParticleFile(end).Number     
        
            if app.MainApp.ParticleFile(i).SinglePart == 1   
            
                lambda(inx) = app.MainApp.ParticleFile(i).(app.HSMXName).SPlambda; 
                shift(inx) = ( app.MainApp.ParticleFile(i).(app.HSMYName).SPlambda - app.MainApp.ParticleFile(i).(app.HSMXName).SPlambda );
                inx = inx+1;
            end
        end 
                       
        SaveSPshiftHistCorr(lambda,shift,app.MainApp.FileFolder,app.TimetraceName,app.HSMXName,app.HSMYName);   
            
        end

        % Button pushed function: ContrastButton
        function ContrastButtonPushed(app, event)
        
            
        time = app.MainApp.ParticleFile(app.PartSpinner.Value).(app.TimetraceName).Time(app.ContrastFrameField.Value);
            
        inx = 1;    
        for i = app.MainApp.ParticleFile(1).Number:app.MainApp.ParticleFile(end).Number     
        
            if app.MainApp.ParticleFile(i).SinglePart == 1  
            if isfield(app.MainApp.ParticleFile(i).(app.TimetraceName), 'Intensity') == 1    
                if length(app.MainApp.ParticleFile(i).(app.TimetraceName).Intensity) > app.RefFrameField.Value
                    ref = mean(hampel(app.MainApp.ParticleFile(i).(app.TimetraceName).Intensity(3:app.RefFrameField.Value),5));
                                        
                        if app.ContrastFrameField.Value < 6                    
                            sig = mean(hampel(app.MainApp.ParticleFile(i).(app.TimetraceName).Intensity(1:10),5));
                        elseif app.ContrastFrameField.Value > length(app.MainApp.ParticleFile(i).(app.TimetraceName).Intensity)-6                     
                            sig = mean(hampel(app.MainApp.ParticleFile(i).(app.TimetraceName).Intensity(end-10:end),5));
                        else
                            sig = mean(hampel(app.MainApp.ParticleFile(i).(app.TimetraceName).Intensity(app.ContrastFrameField.Value-5:app.ContrastFrameField.Value+5),5));
                        end

                    contrast(inx) = sig./ref - 1;                
                    lambda(inx) = app.MainApp.ParticleFile(i).(app.HSMXName).SPlambda; 
                    inx = inx+1;
                end
            end    
            end
        end 
        
                     if -max(contrast) < max(contrast)
                         limY = [-max(contrast)*1.1  max(contrast)*1.1];
                     else
                         limY = [max(contrast)*1.1  -max(contrast)*1.1];
                     end 
        
        
        SaveTimetraceContrast(lambda,contrast,app.MainApp.FileFolder,app.TimetraceName,app.ContrastFrameField.Value,time,limY);      


        end

        % Button pushed function: SaveparticleButton
        function SaveparticleButtonPushed(app, event)
            
            
         SaveFunctTimetracePlot(app.MainApp.FileFolder,app.TimetraceName,app.HSMXName,app.HSMYName,app.MainApp.ParticleFile(app.PartSpinner.Value).(app.TimetraceName).Time,app.TimetraceToPlot,app.MainApp.ParticleFile(app.PartSpinner.Value).(app.HSMXName).Lambda,app.MainApp.ParticleFile(app.PartSpinner.Value).(app.HSMYName).Lambda,app.MainApp.ParticleFile(app.PartSpinner.Value).(app.HSMXName).Intensity,app.MainApp.ParticleFile(app.PartSpinner.Value).(app.HSMYName).Intensity,app.MainApp.ParticleFile(app.PartSpinner.Value).(app.HSMXName).Lorentz,app.MainApp.ParticleFile(app.PartSpinner.Value).(app.HSMYName).Lorentz,app.PartSpinner.Value);      
          
            
        end

        % Button pushed function: SaveallButton
        function SaveallButtonPushed(app, event)


        for i = app.MainApp.ParticleFile(1).Number:app.MainApp.ParticleFile(end).Number     
        
            if isfield(app.MainApp.ParticleFile(i).(app.TimetraceName), 'Intensity') == 1

                timetrace = app.MainApp.ParticleFile(i).(app.TimetraceName).Intensity;
                
                if app.NormalizeButton.Value == 1   
                    ref = mean(hampel(app.MainApp.ParticleFile(i).(app.TimetraceName).Intensity(3:app.RefFrameField.Value),5));            
                    timetrace = timetrace ./ ref;
                end
                if app.RmvspikesButton.Value == 1                                   
                    timetrace = hampel(timetrace,5);                    
                end
      
                    SaveFunctTimetracePlot(app.MainApp.FileFolder,app.TimetraceName,app.HSMXName,app.HSMYName,app.MainApp.ParticleFile(i).(app.TimetraceName).Time,timetrace,app.MainApp.ParticleFile(i).(app.HSMXName).Lambda,app.MainApp.ParticleFile(i).(app.HSMYName).Lambda,app.MainApp.ParticleFile(i).(app.HSMXName).Intensity,app.MainApp.ParticleFile(i).(app.HSMYName).Intensity,app.MainApp.ParticleFile(i).(app.HSMXName).Lorentz,app.MainApp.ParticleFile(i).(app.HSMYName).Lorentz,i);      
             
                close all;
                clear timetrace;
            
            end
                        
        end     


        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.ActionsPanel = uipanel(app.UIFigure);
            app.ActionsPanel.Title = 'Actions';
            app.ContrastButton = uibutton(app.ActionsPanel, 'push');
            app.ContrastButton.ButtonPushedFcn = createCallbackFcn(app, @ContrastButtonPushed, true);
            app.ContrastButton.Text = 'Contrast';
            app.PlasmonShiftButton = uibutton(app.ActionsPanel, 'push');
            app.PlasmonShiftButton.ButtonPushedFcn = createCallbackFcn(app, @PlasmonShiftButtonPushed, true);
            app.PlasmonShiftButton.Text = 'Plasmon shift';
            app.SaveparticleButton = uibutton(app.ActionsPanel, 'push');
            app.SaveparticleButton.ButtonPushedFcn = createCallbackFcn(app, @SaveparticleButtonPushed, true);
            app.SaveparticleButton.Text = 'Save particle';
            app.SaveallButton = uibutton(app.ActionsPanel, 'push');
            app.SaveallButton.ButtonPushedFcn = createCallbackFcn(app, @SaveallButtonPushed, true);
            app.SaveallButton.Text = 'Save all';
            app.NormalizeButton = uibutton(app.ActionsPanel, 'state');
            app.NormalizeButton.ValueChangedFcn = createCallbackFcn(app, @NormalizeButtonValueChanged, true);
            app.NormalizeButton.Text = 'Normalize';
            app.ReffrmLabel = uilabel(app.ActionsPanel);
            app.ReffrmLabel.Text = 'Ref frm:';
            app.RefFrameField = uieditfield(app.ActionsPanel, 'numeric');
            app.RefFrameField.Limits = [4 Inf];
            app.RefFrameField.ValueDisplayFormat = '%.0f';
            app.RefFrameField.ValueChangedFcn = createCallbackFcn(app, @RefFrameFieldValueChanged, true);
            app.RefFrameField.Value = 500;
            app.FrmLabel = uilabel(app.ActionsPanel);
            app.FrmLabel.Text = 'Frm:';
            app.ContrastFrameField = uieditfield(app.ActionsPanel, 'numeric');
            app.ContrastFrameField.Limits = [0 Inf];
            app.ContrastFrameField.ValueDisplayFormat = '%.0f';
            app.ContrastFrameField.Value = 6000;
            app.RmvspikesButton = uibutton(app.ActionsPanel, 'state');
            app.RmvspikesButton.ValueChangedFcn = createCallbackFcn(app, @RmvspikesButtonValueChanged, true);
            app.RmvspikesButton.Text = 'Rmv spikes';
            app.TimetracePlot = uiaxes(app.UIFigure);
            title(app.TimetracePlot, 'Title')
            xlabel(app.TimetracePlot, 'Time (s)')
            ylabel(app.TimetracePlot, 'Scattered intensity (arb.u.)')
            app.FOVPlot = uiaxes(app.UIFigure);
            app.SpectraPlot = uiaxes(app.UIFigure);
            xlabel(app.SpectraPlot, 'Wavelength (nm)')
            ylabel(app.SpectraPlot, 'Scat. cross sec. (arb.u.)')
            app.LabelSPlambda = uilabel(app.UIFigure);
            app.LabelSPshift = uilabel(app.UIFigure);
            app.FrameLabel = uilabel(app.UIFigure);
            app.FrameLabel.Text = 'Frame';
            app.FrameSlider = uislider(app.UIFigure);
            app.FrameSlider.ValueChangedFcn = createCallbackFcn(app, @FrameSliderValueChanged, true);
            app.FrameSlider.ValueChangingFcn = createCallbackFcn(app, @FrameSliderValueChanging, true);
            app.FrameSpinnerLabel = uilabel(app.UIFigure);
            app.FrameSpinnerLabel.Text = 'Frame:';
            app.FrameSpinner = uispinner(app.UIFigure);
            app.FrameSpinner.ValueChangedFcn = createCallbackFcn(app, @FrameSpinnerValueChanged, true);
            app.ParticlePanel = uipanel(app.UIFigure);
            app.ParticlePanel.Title = 'Particle';
            app.PartSpinner = uispinner(app.ParticlePanel);
            app.PartSpinner.Limits = [1 Inf];
            app.PartSpinner.RoundFractionalValues = 'on';
            app.PartSpinner.ValueDisplayFormat = '%.0f';
            app.PartSpinner.ValueChangedFcn = createCallbackFcn(app, @PartSpinnerValueChanged, true);
            app.PartSpinner.Value = 1;
            app.SwitchSingle = uiswitch(app.ParticlePanel, 'slider');
            app.SwitchSingle.Items = {'CLUSTER', 'SINGLE'};
            app.SwitchSingle.ItemsData = {'0', '1'};
            app.SwitchSingle.ValueChangedFcn = createCallbackFcn(app, @SwitchSingleValueChanged, true);
            app.SwitchSingle.Value = '1';
            app.LampSingle = uilamp(app.ParticlePanel);
            app.SignificantparticlePanel = uipanel(app.UIFigure);
            app.SignificantparticlePanel.Title = 'Significant particle';
            app.SwitchSignificant = uiswitch(app.SignificantparticlePanel, 'slider');
            app.SwitchSignificant.Items = {'no', 'YES'};
            app.SwitchSignificant.ItemsData = {'0', '1'};
            app.SwitchSignificant.ValueChangedFcn = createCallbackFcn(app, @SwitchSignificantValueChanged, true);
            app.SwitchSignificant.Value = '1';
            app.LampSignificant = uilamp(app.SignificantparticlePanel);
            app.TimesEditFieldLabel = uilabel(app.UIFigure);
            app.TimesEditFieldLabel.Text = 'Time (s):';
            app.TimeField = uieditfield(app.UIFigure, 'numeric');
            app.TimeField.Editable = 'off';
            app.MeasurementsPanel = uipanel(app.UIFigure);
            app.MeasurementsPanel.Title = 'Measurements';
            app.LoadTimetrace = uilamp(app.MeasurementsPanel);
            app.TimetraceDropDownLabel = uilabel(app.MeasurementsPanel);
            app.TimetraceDropDownLabel.Text = 'Timetrace';
            app.TimetraceDropDown = uidropdown(app.MeasurementsPanel);
            app.TimetraceDropDown.Items = {'', 'Option 2', 'Option 3', 'Option 4'};
            app.TimetraceDropDown.ValueChangedFcn = createCallbackFcn(app, @TimetraceDropDownValueChanged, true);
            app.TimetraceDropDown.Value = '';
            app.LoadHSM1 = uilamp(app.MeasurementsPanel);
            app.InitialHSMLabel = uilabel(app.MeasurementsPanel);
            app.InitialHSMLabel.Text = 'Initial HSM';
            app.HSM1DropDown = uidropdown(app.MeasurementsPanel);
            app.HSM1DropDown.Items = {'', 'Option 2', 'Option 3', 'Option 4'};
            app.HSM1DropDown.ValueChangedFcn = createCallbackFcn(app, @HSM1DropDownValueChanged, true);
            app.HSM1DropDown.Value = '';
            app.LoadHSM2 = uilamp(app.MeasurementsPanel);
            app.FinalHSMLabel = uilabel(app.MeasurementsPanel);
            app.FinalHSMLabel.Text = 'Final HSM';
            app.HSM2DropDown = uidropdown(app.MeasurementsPanel);
            app.HSM2DropDown.Items = {'', 'Option 2', 'Option 3', 'Option 4'};
            app.HSM2DropDown.ValueChangedFcn = createCallbackFcn(app, @HSM2DropDownValueChanged, true);
            app.HSM2DropDown.Value = '';
        end
    end

    methods (Access = public)

        % Construct app
        function app = FunctionalizationApp(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end