classdef InitializeFiles < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        InitializeanewfileLabel  matlab.ui.control.Label
        InitializeButton         matlab.ui.control.Button
        NameEditFieldLabel       matlab.ui.control.Label
        NameField                matlab.ui.control.EditField
        FiletodeterminecoordinatesLabel  matlab.ui.control.Label
        ND2FileList              matlab.ui.control.ListBox
        FolderLabel              matlab.ui.control.Label
        FolderField              matlab.ui.control.EditField
        LoadButton               matlab.ui.control.Button
        ThresholdEditFieldLabel  matlab.ui.control.Label
        Threshold1Field          matlab.ui.control.NumericEditField
        Threshold2Field          matlab.ui.control.NumericEditField
    end


    properties (Access = private)
        FileFolder
        FileName 
        FilesND2
        MainApp
    end
   

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            
            app.MainApp = mainapp;
            clear mainapp;
            
            app.InitializeButton.Enable = 'off';
    
        if exist(app.FileFolder) == 7
            
            % Filling the fields in the app
            app.FilesND2 = dir([ app.FileFolder '\' '*.nd2']);  % Loading all nd2 files in the specified folder
            app.ND2FileList.Items = {app.FilesND2.name};
            
            app.InitializeButton.Enable = 'on';
            
        end
                                   
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            
        app.FileFolder = uigetdir;
        app.FolderField.Value = app.FileFolder; 
        
        if exist(app.FileFolder) == 7
            
            % Filling the fields in the app
            app.FilesND2 = dir([ app.FileFolder '\' '*.nd2']);  % Loading all nd2 files in the specified folder
            app.ND2FileList.Items = {app.FilesND2.name};
            
            app.InitializeButton.Enable = 'on';
            
        end
               
     
        end

        % Button pushed function: InitializeButton
        function InitializeButtonPushed(app, event)
            
        FileND2 = [ app.FileFolder '\' app.ND2FileList.Value ];
        app.FileName = app.NameField.Value;   
        threshold = [ app.Threshold1Field.Value app.Threshold2Field.Value ];
     
        LoadInitialization(app.MainApp,app.FileName,app.FileFolder,FileND2,threshold);     


        end

        % Value changed function: NameField
        function NameFieldValueChanged(app, event)

                      
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.InitializeanewfileLabel = uilabel(app.UIFigure);
            app.InitializeanewfileLabel.Text = 'Initialize a new file';
            app.InitializeButton = uibutton(app.UIFigure, 'push');
            app.InitializeButton.ButtonPushedFcn = createCallbackFcn(app, @InitializeButtonPushed, true);
            app.InitializeButton.Text = 'Initialize';
            app.NameEditFieldLabel = uilabel(app.UIFigure);
            app.NameEditFieldLabel.Text = 'Name:';
            app.NameField = uieditfield(app.UIFigure, 'text');
            app.NameField.ValueChangedFcn = createCallbackFcn(app, @NameFieldValueChanged, true);
            app.NameField.Value = 'HSM';
            app.FiletodeterminecoordinatesLabel = uilabel(app.UIFigure);
            app.FiletodeterminecoordinatesLabel.Text = 'File to determine coordinates:';
            app.ND2FileList = uilistbox(app.UIFigure);
            app.FolderLabel = uilabel(app.UIFigure);
            app.FolderLabel.Text = 'Folder:';
            app.FolderField = uieditfield(app.UIFigure, 'text');
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'load';
            app.ThresholdEditFieldLabel = uilabel(app.UIFigure);
            app.ThresholdEditFieldLabel.Text = 'Threshold:';
            app.Threshold1Field = uieditfield(app.UIFigure, 'numeric');
            app.Threshold1Field.Value = 0.005;
            app.Threshold2Field = uieditfield(app.UIFigure, 'numeric');
            app.Threshold2Field.Value = 0.75;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = InitializeFiles(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end