classdef ParticleSpectraApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        ParticlePanel                matlab.ui.container.Panel
        PartSpinner                  matlab.ui.control.Spinner
        SwitchSingle                 matlab.ui.control.Switch
        LampSingle                   matlab.ui.control.Lamp
        ActionsPanel                 matlab.ui.container.Panel
        LinewidthButton              matlab.ui.control.Button
        SaveParticleButton           matlab.ui.control.Button
        SaveAllButton                matlab.ui.control.Button
        SPlambdaButton               matlab.ui.control.Button
        LeaveButton                  matlab.ui.control.Button
        RawvsFittedGsPanel           matlab.ui.container.Panel
        FOVPlot                      matlab.ui.control.UIAxes
        LambdaSliderLabel            matlab.ui.control.Label
        LambdaSlider                 matlab.ui.control.Slider
        FOVPlot_Fit                  matlab.ui.control.UIAxes
        LorentzfitPanel              matlab.ui.container.Panel
        FitPar2                      matlab.ui.control.NumericEditField
        ParametersEditFieldLabel     matlab.ui.control.Label
        FitPar1                      matlab.ui.control.NumericEditField
        FitPar4                      matlab.ui.control.NumericEditField
        FitButton                    matlab.ui.control.Button
        FitSave                      matlab.ui.control.Button
        DatapointsLabel              matlab.ui.control.Label
        Datapoints                   matlab.ui.control.EditField
        FitPar3                      matlab.ui.control.NumericEditField
        LoadHSM                      matlab.ui.control.Lamp
        SpectraPlot                  matlab.ui.control.UIAxes
        LabelSPlambda                matlab.ui.control.Label
        LabelLinewidth               matlab.ui.control.Label
        LabelLorentzR                matlab.ui.control.Label
        HSMmeasurementDropDownLabel  matlab.ui.control.Label
        HSMmeasurementDropDown       matlab.ui.control.DropDown
        SignificantparticlePanel     matlab.ui.container.Panel
        SwitchSignificant            matlab.ui.control.Switch
        LampSignificant              matlab.ui.control.Lamp
        NoSingPartsTextAreaLabel     matlab.ui.control.Label
        NoSingPartsTextArea          matlab.ui.control.TextArea
        LambdaSpinner                matlab.ui.control.Spinner
    end

    
    properties (Access = private)
        FieldName
        LorentzFitParam
        RSquared
    end
    
    properties (Access = public)
        MainApp
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            
            app.MainApp = mainapp;
            clear mainapp;
            
            app.LoadHSM.Color = 'Red';
            app.HSMmeasurementDropDown.Items = fieldnames(app.MainApp.ParticleFile);
            
            app.SwitchSingle.ItemsData = [ 0 , 1 ];
            app.SwitchSignificant.ItemsData = [ 0 , 1 ];
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            
            CallBack(app.MainApp);
            delete(app)
            
        end

        % Value changed function: HSMmeasurementDropDown
        function HSMmeasurementDropDownValueChanged(app, event)
            
            
            app.LoadHSM.Color = 'Yellow';
            app.FieldName = app.HSMmeasurementDropDown.Value;
            
            app.PartSpinner.Limits = [app.MainApp.ParticleFile(1).Number  app.MainApp.ParticleFile(end).Number];
            
            app.LoadHSM.Color = 'Green';
            
            app.SwitchSingle.Value = app.MainApp.ParticleFile(app.PartSpinner.Value).SinglePart;
            app.SwitchSignificant.Value = app.MainApp.ParticleFile(app.PartSpinner.Value).SignificantPart;
            
            if app.SwitchSingle.Value == 1
                app.LampSingle.Color = 'Green';
            else
                app.LampSingle.Color = 'Red';
            end
            
            if app.SwitchSignificant.Value == 1
                app.LampSignificant.Color = 'Cyan';
            else
                app.LampSignificant.Color = 'Red';
            end
            
            
            Lorentz_eV = linspace(1248/(min(app.MainApp.ParticleFile(app.PartSpinner.Value).(app.FieldName).Lambda)-30),1248/(max(app.MainApp.ParticleFile(app.PartSpinner.Value).(app.FieldName).Lambda)+30),100);
            plot(app.SpectraPlot,app.MainApp.ParticleFile(app.PartSpinner.Value).(app.FieldName).Lambda,app.MainApp.ParticleFile(app.PartSpinner.Value).(app.FieldName).Intensity,'ro',1248./Lorentz_eV,Lorentzfunction(app.MainApp.ParticleFile(app.PartSpinner.Value).(app.FieldName).Lorentz,Lorentz_eV),'r-');
            app.SpectraPlot.XLim = [ (min(app.MainApp.ParticleFile(app.PartSpinner.Value).(app.FieldName).Lambda)-30)  (max(app.MainApp.ParticleFile(app.PartSpinner.Value).(app.FieldName).Lambda)+30) ];
            app.SpectraPlot.Title.String = [ app.FieldName ': Particle no. ' num2str(app.PartSpinner.Value) ];
            
            app.LabelSPlambda.Text = ['λ = ' num2str(round(app.MainApp.ParticleFile(app.PartSpinner.Value).(app.FieldName).SPlambda,1)) ' nm'];
            app.LabelLinewidth.Text = ['Γ = ' num2str(round(app.MainApp.ParticleFile(app.PartSpinner.Value).(app.FieldName).Linewidth)) ' meV'];
            app.LabelLorentzR.Text = ['R^2 = ' num2str(round(app.MainApp.ParticleFile(app.PartSpinner.Value).(app.FieldName).LorentzR,3)) ];
            
            app.LambdaSlider.MajorTicks = [1:length(app.MainApp.ParticleFile(app.PartSpinner.Value).(app.FieldName).Lambda)];
            app.LambdaSlider.MinorTicks = [];
            app.LambdaSlider.FontSize = 9;
            app.LambdaSlider.MajorTickLabels = cellstr(num2str(app.MainApp.ParticleFile(app.PartSpinner.Value).(app.FieldName).Lambda(:)))';
            app.LambdaSlider.Limits = [ 1 length(app.MainApp.ParticleFile(app.PartSpinner.Value).(app.FieldName).Lambda)  ];
            %app.LambdaSlider.Value = 1;
            app.LambdaSlider.Value = round(length(app.MainApp.ParticleFile(app.PartSpinner.Value).(app.FieldName).Lambda)/2);
            app.LambdaSpinner.Limits = [app.MainApp.ParticleFile(app.PartSpinner.Value).(app.FieldName).Lambda(1) app.MainApp.ParticleFile(app.PartSpinner.Value).(app.FieldName).Lambda(end)];
            app.LambdaSpinner.Value = app.MainApp.ParticleFile(app.PartSpinner.Value).(app.FieldName).Lambda(app.LambdaSlider.Value);
            % Plot raw data and gaussian fitted 2D graph
            FOV1 = imagesc(app.FOVPlot,app.MainApp.ParticleFile(app.PartSpinner.Value).(app.FieldName).RawData(:,:,round(app.LambdaSlider.Value)));
            
            app.FOVPlot.XLim = [ 1 size(app.MainApp.ParticleFile(app.PartSpinner.Value).(app.FieldName).RawData,1)  ];
            app.FOVPlot.YLim = [ 1 size(app.MainApp.ParticleFile(app.PartSpinner.Value).(app.FieldName).RawData,2)  ];
            app.FOVPlot.XTick = [];
            app.FOVPlot.YTick = [];
            app.FOVPlot.DataAspectRatio = [1 1 1];
            colormap(app.FOVPlot,jet);
            colorbar(app.FOVPlot);
            
            % generate coordinate system for 2d Gauss fit
            xx = linspace(-(size(app.MainApp.ParticleFile(app.PartSpinner.Value).(app.FieldName).RawData,1)-1)/2,(size(app.MainApp.ParticleFile(app.PartSpinner.Value).(app.FieldName).RawData,1)-1)/2,size(app.MainApp.ParticleFile(app.PartSpinner.Value).(app.FieldName).RawData,1));
            yy = xx;
            [x,y] = meshgrid(xx,yy);
            %xdata(:,:,1) = x + size(app.MainApp.ParticleFile(app.PartSpinner.Value).(app.FieldName).RawData,1)/2;
            xdata(:,:,1) = x ;
            xdata(:,:,2) = y ;
            %xdata(:,:,2) = y + size(app.MainApp.ParticleFile(app.PartSpinner.Value).(app.FieldName).RawData,1)/2;
            %coord_gauss( :, :, 1 ) = xdata(: ,:, 1) + app.MainApp.ParticleFile(app.PartSpinner.Value).(app.FieldName).Location(1)/2;
            %coord_gauss( :, :, 2 ) = xdata(:, :, 2) + app.MainApp.ParticleFile(app.PartSpinner.Value).(app.FieldName).Location(2)/2;
            coord_gauss( :, :, 1 ) = xdata(: ,:, 1);
            coord_gauss( :, :, 2 ) = xdata(:, :, 2);
            
            z = app.MainApp.ParticleFile(app.PartSpinner.Value).(app.FieldName).twoDGauss(round(app.LambdaSlider.Value),1) + ...
                app.MainApp.ParticleFile(app.PartSpinner.Value).(app.FieldName).twoDGauss(round(app.LambdaSlider.Value),2)...
                *exp( -((coord_gauss(:, :,1) - (app.MainApp.ParticleFile(app.PartSpinner.Value).(app.FieldName).twoDGauss(round(app.LambdaSlider.Value),3))).^2/(2*app.MainApp.ParticleFile(app.PartSpinner.Value).(app.FieldName).twoDGauss(round(app.LambdaSlider.Value),4)^2) + (coord_gauss(:, :,2)-(app.MainApp.ParticleFile(app.PartSpinner.Value).(app.FieldName).twoDGauss(round(app.LambdaSlider.Value),5))).^2/(2*app.MainApp.ParticleFile(app.PartSpinner.Value).(app.FieldName).twoDGauss(round(app.LambdaSlider.Value),4)^2) )    );
            %FOV2 = surf(app.FOVPlot_Fit, coord_gauss(:, : ,1) , coord_gauss( :, : , 2 ), z' );
            FOV2 = imagesc(app.FOVPlot_Fit, xx, yy, z );
            app.FOVPlot_Fit.View = [0 , 90];
            % app.FOVPlot_Fit.XLim = [ 1 size(app.MainApp.ParticleFile(app.PartSpinner.Value).(app.FieldName).RawData,1)  ];
            % app.FOVPlot_Fit.YLim = [ 1 size(app.MainApp.ParticleFile(app.PartSpinner.Value).(app.FieldName).RawData,2)  ];
            app.FOVPlot_Fit.XTick = [];
            app.FOVPlot_Fit.YTick = [];
            app.FOVPlot_Fit.DataAspectRatio = [1 1 1];
            colormap(app.FOVPlot_Fit,jet);
            colorbar(app.FOVPlot_Fit);
            
            app.FitPar1.Value = app.MainApp.ParticleFile(app.PartSpinner.Value).(app.FieldName).Lorentz(1);
            app.FitPar2.Value = app.MainApp.ParticleFile(app.PartSpinner.Value).(app.FieldName).Lorentz(2);
            app.FitPar3.Value = app.MainApp.ParticleFile(app.PartSpinner.Value).(app.FieldName).Lorentz(3);
            app.FitPar4.Value = app.MainApp.ParticleFile(app.PartSpinner.Value).(app.FieldName).Lorentz(4);
            app.Datapoints.Value = num2str( 1:length(app.MainApp.ParticleFile(app.PartSpinner.Value).(app.FieldName).Lambda),'%1.0d ' );
            
        end

        % Value changed function: NoSingPartsTextArea, PartSpinner
        function PartSpinnerValueChanged(app, event)
            
            app.SwitchSingle.Value = app.MainApp.ParticleFile(app.PartSpinner.Value).SinglePart;
            app.SwitchSignificant.Value = app.MainApp.ParticleFile(app.PartSpinner.Value).SignificantPart;
            
            %% display single nanoparticle numbers retrieved from results.
            num_sing = 0 ;
            for i = 1 : length(app.MainApp.ParticleFile)
                if  app.MainApp.ParticleFile(i).SinglePart == 1
                    num_sing = num_sing + 1;
                else
                end
            end
            app.NoSingPartsTextArea.Value = num2str(num_sing);
            
            if app.SwitchSingle.Value == 1
                app.LampSingle.Color = 'Green';
            else
                app.LampSingle.Color = 'Red';
            end
            
            if app.SwitchSignificant.Value == 1
                app.LampSignificant.Color = 'Cyan';
            else
                app.LampSignificant.Color = 'Red';
            end
            
            
            Lorentz_eV = linspace(1248/(min(app.MainApp.ParticleFile(app.PartSpinner.Value).(app.FieldName).Lambda)-30),1248/(max(app.MainApp.ParticleFile(app.PartSpinner.Value).(app.FieldName).Lambda)+30),100);
            plot(app.SpectraPlot,app.MainApp.ParticleFile(app.PartSpinner.Value).(app.FieldName).Lambda,app.MainApp.ParticleFile(app.PartSpinner.Value).(app.FieldName).Intensity,'ro',1248./Lorentz_eV,Lorentzfunction(app.MainApp.ParticleFile(app.PartSpinner.Value).(app.FieldName).Lorentz,Lorentz_eV),'r-');
            app.SpectraPlot.XLim = [ (min(app.MainApp.ParticleFile(app.PartSpinner.Value).(app.FieldName).Lambda)-30)  (max(app.MainApp.ParticleFile(app.PartSpinner.Value).(app.FieldName).Lambda)+30) ];
            app.SpectraPlot.Title.String = [ app.FieldName ': Particle no. ' num2str(app.PartSpinner.Value) ];
            
            app.LabelSPlambda.Text = ['λ = ' num2str(round(app.MainApp.ParticleFile(app.PartSpinner.Value).(app.FieldName).SPlambda,1)) ' nm'];
            app.LabelLinewidth.Text = ['Γ = ' num2str(round(app.MainApp.ParticleFile(app.PartSpinner.Value).(app.FieldName).Linewidth)) ' meV'];
            app.LabelLorentzR.Text = ['R^2 = ' num2str(round(app.MainApp.ParticleFile(app.PartSpinner.Value).(app.FieldName).LorentzR,3)) ];
            
            app.LambdaSlider.MajorTicks = [1:length(app.MainApp.ParticleFile(app.PartSpinner.Value).(app.FieldName).Lambda)];
            app.LambdaSlider.MinorTicks = [];
            app.LambdaSlider.FontSize = 9;
            app.LambdaSlider.MajorTickLabels = cellstr(num2str(app.MainApp.ParticleFile(app.PartSpinner.Value).(app.FieldName).Lambda(:)))';
            app.LambdaSlider.Limits = [1 length(app.MainApp.ParticleFile(app.PartSpinner.Value).(app.FieldName).Lambda)];
            app.LambdaSlider.Value = round(length(app.MainApp.ParticleFile(app.PartSpinner.Value).(app.FieldName).Lambda)/2);
            app.LambdaSpinner.Limits = [app.MainApp.ParticleFile(app.PartSpinner.Value).(app.FieldName).Lambda(1) app.MainApp.ParticleFile(app.PartSpinner.Value).(app.FieldName).Lambda(end)];
            app.LambdaSpinner.Value = app.MainApp.ParticleFile(app.PartSpinner.Value).(app.FieldName).Lambda(app.LambdaSlider.Value);
            
            %% Plot raw data ROI of single particles as a function of wavelength
            
            imagesc(app.FOVPlot,app.MainApp.ParticleFile(app.PartSpinner.Value).(app.FieldName).RawData(:,:,round(app.LambdaSlider.Value)));
            
            app.FOVPlot.XLim = [ 1 size(app.MainApp.ParticleFile(app.PartSpinner.Value).(app.FieldName).RawData,1)  ];
            app.FOVPlot.YLim = [ 1 size(app.MainApp.ParticleFile(app.PartSpinner.Value).(app.FieldName).RawData,2)  ];
            app.FOVPlot.XTick = [];
            app.FOVPlot.YTick = [];
            app.FOVPlot.DataAspectRatio = [1 1 1];
            colormap(app.FOVPlot,jet);
            colorbar(app.FOVPlot);
            
            %% Plot Gaussian fitted ROI.
            
            xx = linspace(-(size(app.MainApp.ParticleFile(app.PartSpinner.Value).(app.FieldName).RawData,1)-1)/2,(size(app.MainApp.ParticleFile(app.PartSpinner.Value).(app.FieldName).RawData,1)-1)/2,size(app.MainApp.ParticleFile(app.PartSpinner.Value).(app.FieldName).RawData,1));
            yy = xx;
            [x,y] = meshgrid(xx,yy);
            %xdata(:,:,1) = x + size(app.MainApp.ParticleFile(app.PartSpinner.Value).(app.FieldName).RawData,1)/2;
            %xdata(:,:,2) = y + size(app.MainApp.ParticleFile(app.PartSpinner.Value).(app.FieldName).RawData,1)/2;
            xdata(:,:,1) = x ;
            xdata(:,:,2) = y ;
            %coord_gauss( :, :, 1 ) = xdata(: ,:, 1) + app.MainApp.ParticleFile(app.PartSpinner.Value).(app.FieldName).Location(1)/2;
            %coord_gauss( :, :, 2 ) = xdata(:, :, 2) + app.MainApp.ParticleFile(app.PartSpinner.Value).(app.FieldName).Location(2)/2;
            coord_gauss( :, :, 1 ) = xdata(: ,:, 1) ;
            coord_gauss( :, :, 2 ) = xdata(: ,:, 2) ;
            
            z = app.MainApp.ParticleFile(app.PartSpinner.Value).(app.FieldName).twoDGauss(round(app.LambdaSlider.Value),1) + ...
                app.MainApp.ParticleFile(app.PartSpinner.Value).(app.FieldName).twoDGauss(round(app.LambdaSlider.Value),2)...
                *exp( -((coord_gauss(:, :,1) - (app.MainApp.ParticleFile(app.PartSpinner.Value).(app.FieldName).twoDGauss(round(app.LambdaSlider.Value),3))).^2/(2*app.MainApp.ParticleFile(app.PartSpinner.Value).(app.FieldName).twoDGauss(round(app.LambdaSlider.Value),4)^2) + (coord_gauss(:, :,2)-(app.MainApp.ParticleFile(app.PartSpinner.Value).(app.FieldName).twoDGauss(round(app.LambdaSlider.Value),5))).^2/(2*app.MainApp.ParticleFile(app.PartSpinner.Value).(app.FieldName).twoDGauss(round(app.LambdaSlider.Value),4)^2) )    );
            %surf(app.FOVPlot_Fit, coord_gauss(:, : ,1) , coord_gauss( :, : , 2 ), z' );
            imagesc(app.FOVPlot_Fit, xx, yy, z );
            
            app.FOVPlot_Fit.View = [0 , 90];
            %app.FOVPlot_Fit.XLim = [-20 20];
            %app.FOVPlot_Fit.YLim = [-20 20];
            
            %app.FOVPlot_Fit.XLim = [ 1 size(app.MainApp.ParticleFile(app.PartSpinner.Value).(app.FieldName).RawData,1)  ];
            %app.FOVPlot_Fit.YLim = [ 1 size(app.MainApp.ParticleFile(app.PartSpinner.Value).(app.FieldName).RawData,2)  ];
            app.FOVPlot_Fit.XTick = [];
            app.FOVPlot_Fit.YTick = [];
            app.FOVPlot_Fit.DataAspectRatio = [1 1 1];
            colormap(app.FOVPlot_Fit,jet);
            colorbar(app.FOVPlot_Fit);


            app.FitPar1.Value = app.MainApp.ParticleFile(app.PartSpinner.Value).(app.FieldName).Lorentz(1);
            app.FitPar2.Value = app.MainApp.ParticleFile(app.PartSpinner.Value).(app.FieldName).Lorentz(2);
            app.FitPar3.Value = app.MainApp.ParticleFile(app.PartSpinner.Value).(app.FieldName).Lorentz(3);
            app.FitPar4.Value = app.MainApp.ParticleFile(app.PartSpinner.Value).(app.FieldName).Lorentz(4);
            app.Datapoints.Value = num2str( 1:length(app.MainApp.ParticleFile(app.PartSpinner.Value).(app.FieldName).Lambda),'%1.0d ' );
            
            
        end

        % Value changed function: SwitchSingle
        function SwitchSingleValueChanged(app, event)
            
            app.MainApp.ParticleFile(app.PartSpinner.Value).SinglePart = app.SwitchSingle.Value;
            
            if app.SwitchSingle.Value == 1
                app.LampSingle.Color = 'Green';
            else
                app.LampSingle.Color = 'Red';
            end
            
        end

        % Value changed function: SwitchSignificant
        function SwitchSignificantValueChanged(app, event)
            
            app.MainApp.ParticleFile(app.PartSpinner.Value).SignificantPart = app.SwitchSignificant.Value;
            
            if app.SwitchSignificant.Value == 1
                app.LampSignificant.Color = 'Cyan';
            else
                app.LampSignificant.Color = 'Red';
            end
            
        end

        % Value changed function: LambdaSlider
        function LambdaSliderValueChanged(app, event)
            
            app.LambdaSlider.Value = round(app.LambdaSlider.Value);
            app.LambdaSpinner.Value = app.MainApp.ParticleFile(app.PartSpinner.Value).(app.FieldName).Lambda(app.LambdaSlider.Value);
            imagesc(app.FOVPlot,app.MainApp.ParticleFile(app.PartSpinner.Value).(app.FieldName).RawData(:,:,round(app.LambdaSlider.Value)));
            
            xx = linspace(-(size(app.MainApp.ParticleFile(app.PartSpinner.Value).(app.FieldName).RawData,1)-1)/2,(size(app.MainApp.ParticleFile(app.PartSpinner.Value).(app.FieldName).RawData,1)-1)/2,size(app.MainApp.ParticleFile(app.PartSpinner.Value).(app.FieldName).RawData,1));
            yy = xx;
            [x,y] = meshgrid(xx,yy);
            %xdata(:,:,1) = x + size(app.MainApp.ParticleFile(app.PartSpinner.Value).(app.FieldName).RawData,1)/2;
            xdata(:,:,1) = x ;
            xdata(:,:,2) = y ;
            
            %xdata(:,:,2) = y + size(app.MainApp.ParticleFile(app.PartSpinner.Value).(app.FieldName).RawData,1)/2;
            %coord_gauss( :, :, 1 ) = xdata(: ,:, 1) + app.MainApp.ParticleFile(app.PartSpinner.Value).(app.FieldName).Location(1)/2;
            %coord_gauss( :, :, 2 ) = xdata(:, :, 2) + app.MainApp.ParticleFile(app.PartSpinner.Value).(app.FieldName).Location(2)/2;
            coord_gauss( :, :, 1 ) = xdata(:, :, 1);
            coord_gauss( :, :, 2 ) = xdata(:, :, 2);
            
            z = app.MainApp.ParticleFile(app.PartSpinner.Value).(app.FieldName).twoDGauss(round(app.LambdaSlider.Value),1) + ...
                app.MainApp.ParticleFile(app.PartSpinner.Value).(app.FieldName).twoDGauss(round(app.LambdaSlider.Value),2)...
                *exp( -((coord_gauss(:, :,1) - (app.MainApp.ParticleFile(app.PartSpinner.Value).(app.FieldName).twoDGauss(round(app.LambdaSlider.Value),3))).^2/(2*app.MainApp.ParticleFile(app.PartSpinner.Value).(app.FieldName).twoDGauss(round(app.LambdaSlider.Value),4)^2) + (coord_gauss(:, :,2)-(app.MainApp.ParticleFile(app.PartSpinner.Value).(app.FieldName).twoDGauss(round(app.LambdaSlider.Value),5))).^2/(2*app.MainApp.ParticleFile(app.PartSpinner.Value).(app.FieldName).twoDGauss(round(app.LambdaSlider.Value),4)^2) )    );
            
            %surf(app.FOVPlot_Fit, coord_gauss(:, : ,1) , coord_gauss( :, : , 2 ), z' );
            imagesc(app.FOVPlot_Fit, xx, yy, z );
            
            app.FOVPlot_Fit.View = [0 , 90];
            % app.FOVPlot_Fit.XLim = [ 1 size(app.MainApp.ParticleFile(app.PartSpinner.Value).(app.FieldName).RawData,1)  ];
            % app.FOVPlot_Fit.YLim = [ 1 size(app.MainApp.ParticleFile(app.PartSpinner.Value).(app.FieldName).RawData,2)  ];
            app.FOVPlot_Fit.XTick = [];
            app.FOVPlot_Fit.YTick = [];
            app.FOVPlot_Fit.DataAspectRatio = [1 1 1];
            colormap(app.FOVPlot_Fit,jet);
            colorbar(app.FOVPlot_Fit);
            
        end

        % Value changing function: LambdaSlider
        function LambdaSliderValueChanging(app, event)
            
            app.LambdaSpinner.Value = app.MainApp.ParticleFile(app.PartSpinner.Value).(app.FieldName).Lambda(round(event.Value));
            imagesc(app.FOVPlot,app.MainApp.ParticleFile(app.PartSpinner.Value).(app.FieldName).RawData(:,:,round(event.Value)));
            
        end

        % Button pushed function: FitButton
        function FitButtonPushed(app, event)
            
            initial_guess = [ app.FitPar1.Value app.FitPar2.Value app.FitPar3.Value app.FitPar4.Value ];
            boundaries = str2num(app.Datapoints.Value);
            
            Lorentz_eV = linspace(1248/(min(app.MainApp.ParticleFile(app.PartSpinner.Value).(app.FieldName).Lambda)-30),1248/(max(app.MainApp.ParticleFile(app.PartSpinner.Value).(app.FieldName).Lambda)+30),100);
            options = optimoptions('lsqcurvefit','MaxIter',5000,'Display','off','Algorithm', 'levenberg-marquardt'); % do not show output of lsqcurvefit
            
            [ app.LorentzFitParam, ~, ~, ~ ] = lsqcurvefit( @Lorentzfunction, initial_guess, 1248./app.MainApp.ParticleFile(app.PartSpinner.Value).(app.FieldName).Lambda(boundaries), app.MainApp.ParticleFile(app.PartSpinner.Value).(app.FieldName).Intensity(boundaries) , [], [], options );
            
            app.LorentzFitParam(4) = abs(app.LorentzFitParam(4));  % sometimes lsqcurvefit finds a negative linewidth. This is the correction.
            app.RSquared = rsquare(app.MainApp.ParticleFile(app.PartSpinner.Value).(app.FieldName).Intensity(boundaries),Lorentzfunction(app.LorentzFitParam,1248./app.MainApp.ParticleFile(app.PartSpinner.Value).(app.FieldName).Lambda(boundaries))); % determines the R2 (coefficient of determination) of the fit
            
            plot(app.SpectraPlot,app.MainApp.ParticleFile(app.PartSpinner.Value).(app.FieldName).Lambda(boundaries),app.MainApp.ParticleFile(app.PartSpinner.Value).(app.FieldName).Intensity(boundaries),'ro',1248./Lorentz_eV,Lorentzfunction(app.LorentzFitParam,Lorentz_eV),'r-');
            
            app.SpectraPlot.XLim = [ (min(app.MainApp.ParticleFile(app.PartSpinner.Value).(app.FieldName).Lambda)-30)  (max(app.MainApp.ParticleFile(app.PartSpinner.Value).(app.FieldName).Lambda)+30) ];
            app.SpectraPlot.Title.String = [ app.FieldName ': Particle no. ' num2str(app.PartSpinner.Value) ];
            
            app.LabelSPlambda.Text = ['λ = ' num2str( round(1248./app.LorentzFitParam(3),1) ) ' nm'];
            app.LabelLinewidth.Text = ['Γ = ' num2str( round(1000.*app.LorentzFitParam(4)) ) ' meV'];
            app.LabelLorentzR.Text = ['R^2 = ' num2str(round(app.RSquared,3)) ];
            
        end

        % Button pushed function: FitSave
        function FitSaveButtonPushed(app, event)
            
            boundaries = str2num(app.Datapoints.Value);
            
            app.MainApp.ParticleFile(app.PartSpinner.Value).(app.FieldName).Lorentz = app.LorentzFitParam;
            
            lambda_transfer = app.MainApp.ParticleFile(app.PartSpinner.Value).(app.FieldName).Lambda(boundaries);
            app.MainApp.ParticleFile(app.PartSpinner.Value).(app.FieldName).Lambda = [];
            app.MainApp.ParticleFile(app.PartSpinner.Value).(app.FieldName).Lambda = lambda_transfer;
            
            intensity_transfer = app.MainApp.ParticleFile(app.PartSpinner.Value).(app.FieldName).Intensity(boundaries);
            app.MainApp.ParticleFile(app.PartSpinner.Value).(app.FieldName).Intensity = [];
            app.MainApp.ParticleFile(app.PartSpinner.Value).(app.FieldName).Intensity = intensity_transfer;
            
            FOV_transfer = app.MainApp.ParticleFile(app.PartSpinner.Value).(app.FieldName).RawData(:,:,boundaries);
            app.MainApp.ParticleFile(app.PartSpinner.Value).(app.FieldName).RawData = [];
            app.MainApp.ParticleFile(app.PartSpinner.Value).(app.FieldName).RawData = FOV_transfer;
            
            
            app.MainApp.ParticleFile(app.PartSpinner.Value).(app.FieldName).SPlambda = 1248./app.LorentzFitParam(3);
            app.MainApp.ParticleFile(app.PartSpinner.Value).(app.FieldName).Linewidth = 1000.*app.LorentzFitParam(4);
            
            app.MainApp.ParticleFile(app.PartSpinner.Value).(app.FieldName).LorentzR = app.RSquared;
            
        end

        % Button pushed function: LeaveButton
        function LeaveButtonPushed(app, event)
            
            CallBack(app.MainApp);
            delete(app)
            
        end

        % Button pushed function: SPlambdaButton
        function SPlambdaButtonPushed(app, event)
            
            inx = 1;
            for i = app.MainApp.ParticleFile(1).Number:app.MainApp.ParticleFile(end).Number
                
                if app.MainApp.ParticleFile(i).SinglePart == 1
                    
                    lambda(inx) = app.MainApp.ParticleFile(i).(app.FieldName).SPlambda;
                    inx = inx+1;
                end
            end
            
            SaveSPHist(lambda,app.MainApp.FileFolder,app.FieldName);
            
        end

        % Button pushed function: SaveParticleButton
        function SaveParticleButtonPushed(app, event)
            
            wavelength = app.MainApp.ParticleFile(app.PartSpinner.Value).(app.FieldName).Lambda;
            scattering = app.MainApp.ParticleFile(app.PartSpinner.Value).(app.FieldName).Intensity;
            fit_Lorentz = app.MainApp.ParticleFile(app.PartSpinner.Value).(app.FieldName).Lorentz;
            rsquared = app.MainApp.ParticleFile(app.PartSpinner.Value).(app.FieldName).LorentzR;
            address = app.MainApp.FileFolder;
            name = app.FieldName;
            number = app.PartSpinner.Value;
            single = app.MainApp.ParticleFile(app.PartSpinner.Value).SinglePart;
            
            SaveParticlePlot(wavelength,scattering,fit_Lorentz,rsquared,address,name,number,single);
            
        end

        % Button pushed function: LinewidthButton
        function LinewidthButtonPushed(app, event)
            
            inx = 1;
            for i = app.MainApp.ParticleFile(1).Number:app.MainApp.ParticleFile(end).Number
                
                if app.MainApp.ParticleFile(i).SinglePart == 1
                    
                    linewidth(inx) = app.MainApp.ParticleFile(i).(app.FieldName).Linewidth;
                    inx = inx+1;
                end
            end
            
            SaveGammaHist(linewidth,app.MainApp.FileFolder,app.FieldName);
            
        end

        % Button pushed function: SaveAllButton
        function SaveAllButtonPushed(app, event)
            
            address = app.MainApp.FileFolder;
            name = app.FieldName;
            
            for i = app.MainApp.ParticleFile(1).Number:app.MainApp.ParticleFile(end).Number
                
                wavelength = app.MainApp.ParticleFile(i).(app.FieldName).Lambda;
                scattering = app.MainApp.ParticleFile(i).(app.FieldName).Intensity;
                fit_Lorentz = app.MainApp.ParticleFile(i).(app.FieldName).Lorentz;
                rsquared = app.MainApp.ParticleFile(i).(app.FieldName).LorentzR;
                single = app.MainApp.ParticleFile(i).SinglePart;
                
                SaveParticlePlot(wavelength,scattering,fit_Lorentz,rsquared,address,name,i,single);
                
                close all;
                clear wavelength;
                clear scattering;
                
            end


        end

        % Callback function
        function NumberofsingleparticlesTextAreaValueChanged(app, event)
            
        end

        % Value changed function: LambdaSpinner
        function LambdaSpinnerValueChanged(app, event)
            [~,idx] = min(abs(app.MainApp.ParticleFile(app.PartSpinner.Value).(app.FieldName).Lambda(:) - app.LambdaSpinner.Value));
            app.LambdaSlider.Value = idx;
            app.LambdaSpinner.Value = app.MainApp.ParticleFile(app.PartSpinner.Value).(app.FieldName).Lambda(idx);
            
            imagesc(app.FOVPlot,app.MainApp.ParticleFile(app.PartSpinner.Value).(app.FieldName).RawData(:,:,round(app.LambdaSlider.Value)));
            
            xx = linspace(-(size(app.MainApp.ParticleFile(app.PartSpinner.Value).(app.FieldName).RawData,1)-1)/2,(size(app.MainApp.ParticleFile(app.PartSpinner.Value).(app.FieldName).RawData,1)-1)/2,size(app.MainApp.ParticleFile(app.PartSpinner.Value).(app.FieldName).RawData,1));
            yy = xx;
            [x,y] = meshgrid(xx,yy);
            %xdata(:,:,1) = x + size(app.MainApp.ParticleFile(app.PartSpinner.Value).(app.FieldName).RawData,1)/2;
            xdata(:,:,1) = x ;
            xdata(:,:,2) = y ;
            
            %xdata(:,:,2) = y + size(app.MainApp.ParticleFile(app.PartSpinner.Value).(app.FieldName).RawData,1)/2;
            %coord_gauss( :, :, 1 ) = xdata(: ,:, 1) + app.MainApp.ParticleFile(app.PartSpinner.Value).(app.FieldName).Location(1)/2;
            %coord_gauss( :, :, 2 ) = xdata(:, :, 2) + app.MainApp.ParticleFile(app.PartSpinner.Value).(app.FieldName).Location(2)/2;
            coord_gauss( :, :, 1 ) = xdata(:, :, 1);
            coord_gauss( :, :, 2 ) = xdata(:, :, 2);
            
            z = app.MainApp.ParticleFile(app.PartSpinner.Value).(app.FieldName).twoDGauss(round(app.LambdaSlider.Value),1) + ...
                app.MainApp.ParticleFile(app.PartSpinner.Value).(app.FieldName).twoDGauss(round(app.LambdaSlider.Value),2)...
                *exp( -((coord_gauss(:, :,1) - (app.MainApp.ParticleFile(app.PartSpinner.Value).(app.FieldName).twoDGauss(round(app.LambdaSlider.Value),3))).^2/(2*app.MainApp.ParticleFile(app.PartSpinner.Value).(app.FieldName).twoDGauss(round(app.LambdaSlider.Value),4)^2) + (coord_gauss(:, :,2)-(app.MainApp.ParticleFile(app.PartSpinner.Value).(app.FieldName).twoDGauss(round(app.LambdaSlider.Value),5))).^2/(2*app.MainApp.ParticleFile(app.PartSpinner.Value).(app.FieldName).twoDGauss(round(app.LambdaSlider.Value),4)^2) )    );
            
            %surf(app.FOVPlot_Fit, coord_gauss(:, : ,1) , coord_gauss( :, : , 2 ), z' );
            imagesc(app.FOVPlot_Fit, xx, yy, z );
            
            app.FOVPlot_Fit.View = [0 , 90];
            % app.FOVPlot_Fit.XLim = [ 1 size(app.MainApp.ParticleFile(app.PartSpinner.Value).(app.FieldName).RawData,1)  ];
            % app.FOVPlot_Fit.YLim = [ 1 size(app.MainApp.ParticleFile(app.PartSpinner.Value).(app.FieldName).RawData,2)  ];
            app.FOVPlot_Fit.XTick = [];
            app.FOVPlot_Fit.YTick = [];
            app.FOVPlot_Fit.DataAspectRatio = [1 1 1];
            colormap(app.FOVPlot_Fit,jet);
            colorbar(app.FOVPlot_Fit);
        end

        % Value changing function: LambdaSpinner
        function LambdaSpinnerValueChanging(app, event)
            [~,idx] = min(abs(app.MainApp.ParticleFile(app.PartSpinner.Value).(app.FieldName).Lambda(:) - event.Value));
            app.LambdaSlider.Value = idx;
            imagesc(app.FOVPlot,app.MainApp.ParticleFile(app.PartSpinner.Value).(app.FieldName).RawData(:,:,idx));
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.ParticlePanel = uipanel(app.UIFigure);
            app.ParticlePanel.Title = 'Particle';
            app.PartSpinner = uispinner(app.ParticlePanel);
            app.PartSpinner.Limits = [1 Inf];
            app.PartSpinner.RoundFractionalValues = 'on';
            app.PartSpinner.ValueDisplayFormat = '%.0f';
            app.PartSpinner.ValueChangedFcn = createCallbackFcn(app, @PartSpinnerValueChanged, true);
            app.PartSpinner.Value = 1;
            app.SwitchSingle = uiswitch(app.ParticlePanel, 'slider');
            app.SwitchSingle.Items = {'CLUSTER', 'SINGLE'};
            app.SwitchSingle.ItemsData = {'0', '1'};
            app.SwitchSingle.ValueChangedFcn = createCallbackFcn(app, @SwitchSingleValueChanged, true);
            app.SwitchSingle.Value = '1';
            app.LampSingle = uilamp(app.ParticlePanel);
            app.ActionsPanel = uipanel(app.UIFigure);
            app.ActionsPanel.Title = 'Actions';
            app.LinewidthButton = uibutton(app.ActionsPanel, 'push');
            app.LinewidthButton.ButtonPushedFcn = createCallbackFcn(app, @LinewidthButtonPushed, true);
            app.LinewidthButton.Text = 'Linewidth';
            app.SaveParticleButton = uibutton(app.ActionsPanel, 'push');
            app.SaveParticleButton.ButtonPushedFcn = createCallbackFcn(app, @SaveParticleButtonPushed, true);
            app.SaveParticleButton.Text = 'Save particle';
            app.SaveAllButton = uibutton(app.ActionsPanel, 'push');
            app.SaveAllButton.ButtonPushedFcn = createCallbackFcn(app, @SaveAllButtonPushed, true);
            app.SaveAllButton.Text = 'Save all';
            app.SPlambdaButton = uibutton(app.ActionsPanel, 'push');
            app.SPlambdaButton.ButtonPushedFcn = createCallbackFcn(app, @SPlambdaButtonPushed, true);
            app.SPlambdaButton.Text = 'SP lambda';
            app.LeaveButton = uibutton(app.ActionsPanel, 'push');
            app.LeaveButton.ButtonPushedFcn = createCallbackFcn(app, @LeaveButtonPushed, true);
            app.LeaveButton.Text = 'Leave';
            app.RawvsFittedGsPanel = uipanel(app.UIFigure);
            app.RawvsFittedGsPanel.Title = 'Raw vs Fitted Gs';
            app.FOVPlot = uiaxes(app.RawvsFittedGsPanel);
            app.LambdaSliderLabel = uilabel(app.RawvsFittedGsPanel);
            app.LambdaSliderLabel.Text = 'Lambda';
            app.LambdaSlider = uislider(app.RawvsFittedGsPanel);
            app.LambdaSlider.MajorTickLabels = {'0', '20', '40', '60', '80', '100'};
            app.LambdaSlider.ValueChangedFcn = createCallbackFcn(app, @LambdaSliderValueChanged, true);
            app.LambdaSlider.ValueChangingFcn = createCallbackFcn(app, @LambdaSliderValueChanging, true);
            app.LambdaSlider.MinorTicks = [];
            app.FOVPlot_Fit = uiaxes(app.RawvsFittedGsPanel);
            app.LorentzfitPanel = uipanel(app.UIFigure);
            app.LorentzfitPanel.Title = 'Lorentz fit';
            app.FitPar2 = uieditfield(app.LorentzfitPanel, 'numeric');
            app.ParametersEditFieldLabel = uilabel(app.LorentzfitPanel);
            app.ParametersEditFieldLabel.Text = 'Parameters';
            app.FitPar1 = uieditfield(app.LorentzfitPanel, 'numeric');
            app.FitPar4 = uieditfield(app.LorentzfitPanel, 'numeric');
            app.FitButton = uibutton(app.LorentzfitPanel, 'push');
            app.FitButton.ButtonPushedFcn = createCallbackFcn(app, @FitButtonPushed, true);
            app.FitButton.Text = 'Fit';
            app.FitSave = uibutton(app.LorentzfitPanel, 'push');
            app.FitSave.ButtonPushedFcn = createCallbackFcn(app, @FitSaveButtonPushed, true);
            app.FitSave.Text = 'Save';
            app.DatapointsLabel = uilabel(app.LorentzfitPanel);
            app.DatapointsLabel.Text = 'Datapoints';
            app.Datapoints = uieditfield(app.LorentzfitPanel, 'text');
            app.FitPar3 = uieditfield(app.LorentzfitPanel, 'numeric');
            app.LoadHSM = uilamp(app.UIFigure);
            app.SpectraPlot = uiaxes(app.UIFigure);
            title(app.SpectraPlot, 'HSM: Particle no. X')
            xlabel(app.SpectraPlot, 'Wavelength (nm)')
            ylabel(app.SpectraPlot, 'Scattering cross section (arb. u.)')
            app.LabelSPlambda = uilabel(app.UIFigure);
            app.LabelLinewidth = uilabel(app.UIFigure);
            app.LabelLorentzR = uilabel(app.UIFigure);
            app.HSMmeasurementDropDownLabel = uilabel(app.UIFigure);
            app.HSMmeasurementDropDownLabel.Text = 'HSM measurement';
            app.HSMmeasurementDropDown = uidropdown(app.UIFigure);
            app.HSMmeasurementDropDown.Items = {'', 'Option 2', 'Option 3', 'Option 4'};
            app.HSMmeasurementDropDown.ValueChangedFcn = createCallbackFcn(app, @HSMmeasurementDropDownValueChanged, true);
            app.HSMmeasurementDropDown.Value = '';
            app.SignificantparticlePanel = uipanel(app.UIFigure);
            app.SignificantparticlePanel.Title = 'Significant particle';
            app.SwitchSignificant = uiswitch(app.SignificantparticlePanel, 'slider');
            app.SwitchSignificant.Items = {'no', 'YES'};
            app.SwitchSignificant.ItemsData = {'0', '1'};
            app.SwitchSignificant.ValueChangedFcn = createCallbackFcn(app, @SwitchSignificantValueChanged, true);
            app.SwitchSignificant.Value = '1';
            app.LampSignificant = uilamp(app.SignificantparticlePanel);
            app.NoSingPartsTextAreaLabel = uilabel(app.UIFigure);
            app.NoSingPartsTextAreaLabel.Text = 'No.Sing.Parts';
            app.NoSingPartsTextArea = uitextarea(app.UIFigure);
            app.NoSingPartsTextArea.ValueChangedFcn = createCallbackFcn(app, @PartSpinnerValueChanged, true);
            app.LambdaSpinner = uispinner(app.UIFigure);
            app.LambdaSpinner.Step = 10;
            app.LambdaSpinner.ValueChangingFcn = createCallbackFcn(app, @LambdaSpinnerValueChanging, true);
            app.LambdaSpinner.Limits = [0 100];
            app.LambdaSpinner.ValueDisplayFormat = '%.0f';
            app.LambdaSpinner.ValueChangedFcn = createCallbackFcn(app, @LambdaSpinnerValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ParticleSpectraApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end