classdef SettingsHSM < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        HSManalysissettingsLabel  matlab.ui.control.Label
        NameEditFieldLabel        matlab.ui.control.Label
        NameField                 matlab.ui.control.EditField
        ROIsizepxEditFieldLabel   matlab.ui.control.Label
        ROIsizeField              matlab.ui.control.NumericEditField
        FileListBoxLabel          matlab.ui.control.Label
        ND2FileList               matlab.ui.control.ListBox
        SpectralcorrectionLabel   matlab.ui.control.Label
        SpecCorrList              matlab.ui.control.ListBox
        RunAnalysisButton         matlab.ui.control.Button
        ParticlestobeanalyzedButtonGroup  matlab.ui.container.ButtonGroup
        AllPclesButton            matlab.ui.control.RadioButton
        SinglePclesButton         matlab.ui.control.RadioButton
        SignificantPclesButton    matlab.ui.control.RadioButton
        AllLamp                   matlab.ui.control.Lamp
        SingleLamp                matlab.ui.control.Lamp
        SignificantLamp           matlab.ui.control.Lamp
        EvaluateCheckBox          matlab.ui.control.CheckBox
    end


    properties (Access = private)
%         ParticleFile 
        FileFolder
        FileName 
        FilesND2
        FilesSpecCorr
        MainApp
    end
    
    properties (Access = public)
        ParticleFile 
        
%         FileFolder
%         FileName 
%         FilesND2
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, FileFolder, FileName)
            
            clc;
            
            app.MainApp = mainapp;
            clear mainapp;
                        
            app.FileFolder = FileFolder;
            app.FileName = FileName;
            
            % Filling the fields in the app
            app.FilesND2 = dir([ app.FileFolder '\' '*.nd2']);  % Loading all nd2 files in the specified folder
            app.ND2FileList.Items = {app.FilesND2.name};
            
            % Filling the fields in the app
            app.FilesSpecCorr = dir([ app.MainApp.ProgramFolder '\spectral_corrections\' '*.mat']);  % Loading all nd2 files in the specified folder
            app.SpecCorrList.Items = {app.FilesSpecCorr.name};
            
            app.NameField.Value = ['HSM'];
            app.ROIsizeField.Value = 19; 
            
                        
            if exist( [app.FileFolder '\' app.FileName '_CoordinatesFile.mat' ], 'file') == 2
                
                app.AllLamp.Color = 'Green'; 
                
                for i = app.MainApp.ParticleFile(1).Number:app.MainApp.ParticleFile(end).Number                    
                    SingleVector(i) = app.MainApp.ParticleFile(i).SinglePart;
                    SignificantVector(i) = app.MainApp.ParticleFile(i).SignificantPart;                
                end
                
                if SingleVector == zeros(size(SingleVector,1),size(SingleVector,2))                  
                    app.SingleLamp.Color = 'Red';
                    app.EvaluateCheckBox.Visible = 'on';
                else
                    app.SingleLamp.Color = 'Green';
                    app.EvaluateCheckBox.Visible = 'off';
                end
                                               
                if SignificantVector == zeros(size(SignificantVector,1),size(SignificantVector,2))                  
                    app.SignificantLamp.Color = 'Red'; 
                else
                    app.SignificantLamp.Color = 'Green'; 
                end
                
            else
                app.AllLamp.Color = 'Red'; 
                app.SingleLamp.Color = 'Red';
                app.SignificantLamp.Color = 'Red'; 
                app.RunAnalysisButton.Enable = 'off';
            end    


        end

        % Button pushed function: RunAnalysisButton
        function RunAnalysisButtonPushed(app, event)
            
            newFieldName = app.NameField.Value;
            FileND2 = [ app.FileFolder '\' app.ND2FileList.Value ];
            FileSpecCorr = [ app.MainApp.ProgramFolder '\spectral_corrections\' app.SpecCorrList.Value ];
            ROI_size_gauss = app.ROIsizeField.Value; 
            DoSingle = app.EvaluateCheckBox.Value;
                      
            if app.AllPclesButton.Value == 1
                PclesToAnal = 1;
            end
            if app.SinglePclesButton.Value == 1
                PclesToAnal = 2;
            end
            if app.SignificantPclesButton.Value == 1
                PclesToAnal = 3;
            end            

            
            % Send values back to main window by calling its public function            
            RunHSMAnal(app.MainApp,newFieldName,FileND2,FileSpecCorr,ROI_size_gauss,PclesToAnal,DoSingle)  
            
        end

        % Value changed function: EvaluateCheckBox
        function EvaluateCheckBoxValueChanged(app, event)
  
            if app.EvaluateCheckBox.Value == 1
                app.SingleLamp.Color = 'Yellow';        
            else
                app.SingleLamp.Color = 'Red';     
            end    
                       
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.HSManalysissettingsLabel = uilabel(app.UIFigure);
            app.HSManalysissettingsLabel.Text = 'HSM analysis: settings';
            app.NameEditFieldLabel = uilabel(app.UIFigure);
            app.NameEditFieldLabel.Text = 'Name:';
            app.NameField = uieditfield(app.UIFigure, 'text');
            app.ROIsizepxEditFieldLabel = uilabel(app.UIFigure);
            app.ROIsizepxEditFieldLabel.Text = 'ROI size (px):';
            app.ROIsizeField = uieditfield(app.UIFigure, 'numeric');
            app.ROIsizeField.Limits = [1 Inf];
            app.ROIsizeField.Value = 19;
            app.FileListBoxLabel = uilabel(app.UIFigure);
            app.FileListBoxLabel.Text = 'File:';
            app.ND2FileList = uilistbox(app.UIFigure);
            app.SpectralcorrectionLabel = uilabel(app.UIFigure);
            app.SpectralcorrectionLabel.Text = 'Spectral correction:';
            app.SpecCorrList = uilistbox(app.UIFigure);
            app.RunAnalysisButton = uibutton(app.UIFigure, 'push');
            app.RunAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @RunAnalysisButtonPushed, true);
            app.RunAnalysisButton.Text = 'Run analysis';
            app.ParticlestobeanalyzedButtonGroup = uibuttongroup(app.UIFigure);
            app.ParticlestobeanalyzedButtonGroup.Title = 'Particles to be analyzed:';
            app.AllPclesButton = uiradiobutton(app.ParticlestobeanalyzedButtonGroup);
            app.AllPclesButton.Text = 'All';
            app.AllPclesButton.Value = true;
            app.SinglePclesButton = uiradiobutton(app.ParticlestobeanalyzedButtonGroup);
            app.SinglePclesButton.Text = 'Single';
            app.SignificantPclesButton = uiradiobutton(app.ParticlestobeanalyzedButtonGroup);
            app.SignificantPclesButton.Text = 'Significant';
            app.AllLamp = uilamp(app.ParticlestobeanalyzedButtonGroup);
            app.SingleLamp = uilamp(app.ParticlestobeanalyzedButtonGroup);
            app.SignificantLamp = uilamp(app.ParticlestobeanalyzedButtonGroup);
            app.EvaluateCheckBox = uicheckbox(app.ParticlestobeanalyzedButtonGroup);
            app.EvaluateCheckBox.ValueChangedFcn = createCallbackFcn(app, @EvaluateCheckBoxValueChanged, true);
            app.EvaluateCheckBox.Text = 'Evaluate';
        end
    end

    methods (Access = public)

        % Construct app
        function app = SettingsHSM(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end