classdef SettingsPOL < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        POLanalysissettingsLabel   matlab.ui.control.Label
        RunAnalysisButton          matlab.ui.control.Button
        NameEditFieldLabel         matlab.ui.control.Label
        NameField                  matlab.ui.control.EditField
        ROIsizepxEditFieldLabel    matlab.ui.control.Label
        ROIsizeField               matlab.ui.control.NumericEditField
        FileListBoxLabel           matlab.ui.control.Label
        ND2FileList                matlab.ui.control.ListBox
        MethodButtonGroup          matlab.ui.container.ButtonGroup
        DGaussianfittingButton     matlab.ui.control.RadioButton
        MeanButton                 matlab.ui.control.RadioButton
        SumButton                  matlab.ui.control.RadioButton
        LoadedParticlesLabel       matlab.ui.control.Label
        AllLamp                    matlab.ui.control.Lamp
        FromEditFieldLabel         matlab.ui.control.Label
        FromEditField              matlab.ui.control.NumericEditField
        SetAnglesinDegreeforcos2fittingLabel  matlab.ui.control.Label
        ToEditFieldLabel           matlab.ui.control.Label
        ToEditField                matlab.ui.control.NumericEditField
        NumberEditFieldLabel       matlab.ui.control.Label
        NumberEditField            matlab.ui.control.NumericEditField
        LoadPOLdataEditFieldLabel  matlab.ui.control.Label
        LoadPOLField               matlab.ui.control.EditField
        LoadedPOLLampLabel         matlab.ui.control.Label
        LoadedPOLLamp              matlab.ui.control.Lamp
        LoadPOLButton              matlab.ui.control.Button
    end


    properties (Access = private)
        HSMFileFolder
        HSMFileName 
        HSMFilesND2
        MainApp
        POLFileFolder
        POLFileName 
        POLFilesND2
        
    end

    properties (Access = public)
        ParticleFile
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, FileFolder, FileName)
            clc;
            
            app.MainApp = mainapp;
            clear mainapp;
                        
            app.HSMFileFolder = FileFolder;
            app.HSMFileName = FileName;


            app.NameField.Value = 'POL';
            app.ROIsizeField.Value = 11; 
            
            %app.FromEditField = 0 ;
            %app.ToEditField = 180 ;
            %app.NumberEditField = 19 ; 
            
            app.LoadedPOLLamp.Color = 'Red';
            app.LoadPOLField.Value = 'Load directory of POL nd2 files!';
            
            
            if exist( [app.HSMFileFolder '\' app.HSMFileName '_CoordinatesFile.mat' ], 'file') == 2
                
                app.AllLamp.Color = 'Green'; 
              
            else
                app.AllLamp.Color = 'Red'; 
                app.RunAnalysisButton.Enable = 'off';
            end    
            
            
        end

        % Button pushed function: RunAnalysisButton
        function RunAnalysisButtonPushed(app, event)
            newFieldName = app.NameField.Value;
            POLFileND2 = [ app.POLFileFolder '\' app.ND2FileList.Value ];
            ROI_size_gauss = app.ROIsizeField.Value; 
            
            if app.DGaussianfittingButton.Value == 1
                MethodToUse = 1;
            end
            if app.MeanButton.Value == 1
                MethodToUse = 2;
            end                        
            if app.SumButton.Value == 1
                MethodToUse = 3;
            end
            
            Pol = linspace(app.FromEditField.Value, app.ToEditField.Value,app.NumberEditField.Value);
            Pol = Pol.*pi./180;
            
            % Send values back to main window by calling its public function            
            RunPOLAnal(app.MainApp, newFieldName, POLFileND2, ROI_size_gauss, MethodToUse, Pol)  
        end

        % Button pushed function: LoadPOLButton
        function LoadPOLButtonPushed(app, event)
           
            [pathname] = uigetdir;
            
            app.POLFileFolder = pathname;
            app.LoadPOLField.Value = pathname;
           
            % Filling the fields in the app
            app.POLFilesND2 = dir([ app.POLFileFolder '\' '*.nd2']);  % Loading all nd2 files in the specified folder
            app.ND2FileList.Items = {app.POLFilesND2.name};
            app.LoadedPOLLamp.Color = 'Green';
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.POLanalysissettingsLabel = uilabel(app.UIFigure);
            app.POLanalysissettingsLabel.Text = 'POL analysis: settings';
            app.RunAnalysisButton = uibutton(app.UIFigure, 'push');
            app.RunAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @RunAnalysisButtonPushed, true);
            app.RunAnalysisButton.Text = 'Run analysis';
            app.NameEditFieldLabel = uilabel(app.UIFigure);
            app.NameEditFieldLabel.Text = 'Name:';
            app.NameField = uieditfield(app.UIFigure, 'text');
            app.ROIsizepxEditFieldLabel = uilabel(app.UIFigure);
            app.ROIsizepxEditFieldLabel.Text = 'ROI size (px):';
            app.ROIsizeField = uieditfield(app.UIFigure, 'numeric');
            app.ROIsizeField.Limits = [1 Inf];
            app.ROIsizeField.Value = 19;
            app.FileListBoxLabel = uilabel(app.UIFigure);
            app.FileListBoxLabel.Text = 'File:';
            app.ND2FileList = uilistbox(app.UIFigure);
            app.MethodButtonGroup = uibuttongroup(app.UIFigure);
            app.MethodButtonGroup.Title = 'Method';
            app.DGaussianfittingButton = uiradiobutton(app.MethodButtonGroup);
            app.DGaussianfittingButton.Text = '2D Gaussian fitting';
            app.DGaussianfittingButton.Value = true;
            app.MeanButton = uiradiobutton(app.MethodButtonGroup);
            app.MeanButton.Text = 'Mean';
            app.SumButton = uiradiobutton(app.MethodButtonGroup);
            app.SumButton.Text = 'Sum';
            app.LoadedParticlesLabel = uilabel(app.UIFigure);
            app.LoadedParticlesLabel.Text = 'LoadedParticles';
            app.AllLamp = uilamp(app.UIFigure);
            app.FromEditFieldLabel = uilabel(app.UIFigure);
            app.FromEditFieldLabel.Text = 'From';
            app.FromEditField = uieditfield(app.UIFigure, 'numeric');
            app.SetAnglesinDegreeforcos2fittingLabel = uilabel(app.UIFigure);
            app.SetAnglesinDegreeforcos2fittingLabel.Text = 'Set Angles in Degree for cos^2 fitting:';
            app.ToEditFieldLabel = uilabel(app.UIFigure);
            app.ToEditFieldLabel.Text = 'To';
            app.ToEditField = uieditfield(app.UIFigure, 'numeric');
            app.ToEditField.Value = 180;
            app.NumberEditFieldLabel = uilabel(app.UIFigure);
            app.NumberEditFieldLabel.Text = 'Number';
            app.NumberEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumberEditField.Value = 19;
            app.LoadPOLdataEditFieldLabel = uilabel(app.UIFigure);
            app.LoadPOLdataEditFieldLabel.Text = 'Load POL data';
            app.LoadPOLField = uieditfield(app.UIFigure, 'text');
            app.LoadedPOLLampLabel = uilabel(app.UIFigure);
            app.LoadedPOLLampLabel.Text = 'LoadedPOL';
            app.LoadedPOLLamp = uilamp(app.UIFigure);
            app.LoadPOLButton = uibutton(app.UIFigure, 'push');
            app.LoadPOLButton.ButtonPushedFcn = createCallbackFcn(app, @LoadPOLButtonPushed, true);
            app.LoadPOLButton.Text = 'Load POL';
        end
    end

    methods (Access = public)

        % Construct app
        function app = SettingsPOL(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end