classdef Page_Edit < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        DatasetsLabel              matlab.ui.control.Label
        list_datasets              matlab.ui.control.ListBox
        button_erase_all_clusters  matlab.ui.control.Button
        panel_rename               matlab.ui.container.Panel
        NewnameEditFieldLabel      matlab.ui.control.Label
        entry_new_name             matlab.ui.control.EditField
        button_rename              matlab.ui.control.Button
        button_remove_dataset      matlab.ui.control.Button
    end


    properties (Access = private)
        
    end
        
    properties (Access = public)
         parent_app
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            % Start up
            % ----------------------
            app.parent_app = mainapp;
            clear mainapp;
            
            app.list_datasets.Items = app.parent_app.datasets_names;
            
            % disable button if need be
            rois = fieldnames(app.parent_app.file_results);
            if isfield(app.parent_app.file_results.(rois{1}), 'SingleParticle')
                app.button_erase_all_clusters.Enable = 'On';
            else
                warndlg('Single particles unknown at the moment, button disabled!','!! Warning !!')
                app.button_erase_all_clusters.Enable = 'Off';
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % Function to close window
            % ----------------------
            try
                CloseChildApp(app.parent_app);
                delete(app)
            catch
                delete(app)
            end
        end

        % Button pushed function: button_erase_all_clusters
        function EraseClusters(app, event)
            % Function to erase all clusters
            % ----------------------
            
            rois = fieldnames(app.parent_app.file_results);
            to_delete = {};
            for k=1:numel(rois)
                
                % if not single particle
                if ~app.parent_app.file_results.(rois{k}).SingleParticle
                    to_delete{end+1} = rois{k};
                end
            end
            app.parent_app.file_results = rmfield(app.parent_app.file_results, to_delete);
            msgbox('Clusters deleted!','Success');
        end

        % Button pushed function: button_rename
        function RenameDataset(app, event)
            % Functino to rename a single dataset for all ROIs
            % ----------------------
            new_name = app.entry_new_name.Value;
            if strtrim(new_name) ~= ""
                if isvarname(new_name)
                    rois = fieldnames(app.parent_app.file_results);
                    for k=1:numel(rois)
                        % if holds selected dataset in the roi
                        if isfield(app.parent_app.file_results.(rois{k}), app.list_datasets.Value)
                            % take it out
                            tmp_roi = app.parent_app.file_results.(rois{k});
                            % take data out
                            tmp_data = tmp_roi.(app.list_datasets.Value);
                            % remove data
                            tmp_roi = rmfield(tmp_roi, app.list_datasets.Value);
                            % re-add
                            tmp_roi.(new_name) = tmp_data;
                            app.parent_app.file_results.(rois{k}) = tmp_roi;
                        end
                    end
                else
                    warndlg('Invalid name. Check MATLAB requirements for variable names!','!! Warning !!')
                end
            end
            % update GUI
            app.entry_new_name.Value = "";
            app.parent_app.FindDatasets()
            app.list_datasets.Items = app.parent_app.datasets_names;
            app.parent_app.listbox_status.Value = app.parent_app.datasets_names;
        end

        % Button pushed function: button_remove_dataset
        function RemoveDataset(app, event)
            % Functino to remove a single dataset for all ROIs
            % ----------------------
            rois = fieldnames(app.parent_app.file_results);
            for k=1:numel(rois)
                % if holds selected dataset in the roi
                if isfield(app.parent_app.file_results.(rois{k}), app.list_datasets.Value)
                    % take it out
                    tmp_roi = app.parent_app.file_results.(rois{k});
                    % remove data
                    tmp_roi = rmfield(tmp_roi, app.list_datasets.Value);
                    % re-add
                    app.parent_app.file_results.(rois{k}) = tmp_roi;
                end
            end            
            % update GUI
            app.entry_new_name.Value = "";
            app.parent_app.FindDatasets()
            app.list_datasets.Items = app.parent_app.datasets_names;
            app.parent_app.listbox_status.Value = app.parent_app.datasets_names;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.DatasetsLabel = uilabel(app.UIFigure);
            app.DatasetsLabel.Text = 'Datasets';
            app.list_datasets = uilistbox(app.UIFigure);
            app.list_datasets.Items = {};
            app.list_datasets.Value = {};
            app.button_erase_all_clusters = uibutton(app.UIFigure, 'push');
            app.button_erase_all_clusters.ButtonPushedFcn = createCallbackFcn(app, @EraseClusters, true);
            app.button_erase_all_clusters.Text = 'Erase all cluster ROIs';
            app.panel_rename = uipanel(app.UIFigure);
            app.panel_rename.Title = 'Rename field';
            app.NewnameEditFieldLabel = uilabel(app.panel_rename);
            app.NewnameEditFieldLabel.Text = 'New name:';
            app.entry_new_name = uieditfield(app.panel_rename, 'text');
            app.button_rename = uibutton(app.panel_rename, 'push');
            app.button_rename.ButtonPushedFcn = createCallbackFcn(app, @RenameDataset, true);
            app.button_rename.Text = 'Rename';
            app.button_remove_dataset = uibutton(app.UIFigure, 'push');
            app.button_remove_dataset.ButtonPushedFcn = createCallbackFcn(app, @RemoveDataset, true);
            app.button_remove_dataset.Text = 'Remove dataset';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Page_Edit(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end