classdef Page_TT < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        panel_actions                 matlab.ui.container.Panel
        button_contrast               matlab.ui.control.Button
        button_save_particle          matlab.ui.control.Button
        FrmLabel                      matlab.ui.control.Label
        entry_contrast_frame          matlab.ui.control.NumericEditField
        button_snr                    matlab.ui.control.Button
        button_save_all               matlab.ui.control.Button
        button_signal_hist            matlab.ui.control.Button
        plot_tt                       matlab.ui.control.UIAxes
        plot_fov                      matlab.ui.control.UIAxes
        plot_spectra                  matlab.ui.control.UIAxes
        plot_spectra_label_SP_lambda  matlab.ui.control.Label
        plot_spectra_label_SP_gamma   matlab.ui.control.Label
        FrameLabel                    matlab.ui.control.Label
        slider_frame                  matlab.ui.control.Slider
        panel_particle                matlab.ui.container.Panel
        spinner_particle              matlab.ui.control.Spinner
        switch_single                 matlab.ui.control.Switch
        lamp_single                   matlab.ui.control.Lamp
        lamp_significant              matlab.ui.control.Lamp
        switch_significant            matlab.ui.control.Switch
        panel_measurements            matlab.ui.container.Panel
        SpectraLabel                  matlab.ui.control.Label
        drop_spectra                  matlab.ui.control.DropDown
        TimetraceDropDownLabel        matlab.ui.control.Label
        plot_constrast                matlab.ui.control.UIAxes
        plot_snr                      matlab.ui.control.UIAxes
        plot_sample_fov               matlab.ui.control.UIAxes
        drop_tt                       matlab.ui.control.DropDown
        lamp_spectra                  matlab.ui.control.Lamp
        lamp_tt                       matlab.ui.control.Lamp
        plot_signal_levels            matlab.ui.control.UIAxes
        panel_tt_settings             matlab.ui.container.Panel
        button_normalize              matlab.ui.control.StateButton
        ReffrmLabel                   matlab.ui.control.Label
        entry_ref_frame               matlab.ui.control.NumericEditField
        button_manual_y_axis          matlab.ui.control.StateButton
        entry_y_axis_max              matlab.ui.control.NumericEditField
        entry_y_axis_min              matlab.ui.control.NumericEditField
        button_rmv_spikes             matlab.ui.control.StateButton
        checkbox_drift_correction     matlab.ui.control.CheckBox
        panel_show_particles          matlab.ui.container.ButtonGroup
        button_show_all               matlab.ui.control.ToggleButton
        button_show_single            matlab.ui.control.ToggleButton
        button_show_significant       matlab.ui.control.ToggleButton
        BinWidthSliderLabel           matlab.ui.control.Label
        slider_bin_width              matlab.ui.control.Slider
        plot_scatter                  matlab.ui.control.UIAxes
        FrameSpinnerLabel             matlab.ui.control.Label
        spinner_frame                 matlab.ui.control.Spinner
        TimesEditFieldLabel           matlab.ui.control.Label
        field_time                    matlab.ui.control.NumericEditField
        checkbox_drift_correction2    matlab.ui.control.CheckBox
    end

    properties (Access = private)

         name_timetrace
         name_hsm
         name_polar
         name_workfield
         tt_to_plot
         bin_width
         sample_fov 
         particle_location
         
    end

    properties (Access = public)
         parent_app
    end
    
    
    methods (Access = private)
        
        function n_bins = NumHistBins(app,data)
            % Utility function to compute the number of histogram bins
                if app.bin_width == 1
                    binwidth = 0.000001;   
                elseif app.bin_width == 2
                    binwidth = 0.000005;   
                elseif app.bin_width == 3
                    binwidth = 0.00001;   
                elseif app.bin_width == 4
                    binwidth = 0.00005;   
                elseif app.bin_width == 5
                    binwidth = 0.0001;   
                elseif app.bin_width == 6
                    binwidth = 0.0005;   
                elseif app.bin_width == 7
                    binwidth = 0.001;   
                elseif app.bin_width == 8
                    binwidth = 0.005;   
                elseif app.bin_width == 9
                    binwidth = 0.01;   
                elseif app.bin_width == 10
                    binwidth = 0.05;   
                elseif app.bin_width == 11
                    binwidth = 0.1;   
                elseif app.bin_width == 12
                    binwidth = 0.5;   
                elseif app.bin_width == 13
                    binwidth = 1;   
                end 
 
                range2plot = floor(min(data)):mean(data)*binwidth:ceil(max(data));                   
                n_bins = size(range2plot,2);
        end
      
    end    


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
        
        app.parent_app = mainapp;
        clear mainapp;
                     
        app.lamp_tt.Color = 'Red';
        app.lamp_spectra.Color = 'Red';
            
        app.drop_tt.Items = app.parent_app.tts;
        app.drop_spectra.Items = app.parent_app.hsms;
        
        app.name_timetrace = app.drop_tt.Items{1};        
        app.name_hsm = app.drop_spectra.Items{1};
                
        app.switch_single.ItemsData = [ 0 , 1 ];
        app.switch_significant.ItemsData = [ 0 , 1 ];
        
                app.button_show_all.Enable = 'on';
        
                for i = app.parent_app.ParticleFile(1).Number:app.parent_app.ParticleFile(end).Number                    
                    SingleVector(i) = app.parent_app.ParticleFile(i).SinglePart;
                    SignificantVector(i) = app.parent_app.ParticleFile(i).SignificantPart;                
                end
                
                if SingleVector == zeros(size(SingleVector,1),size(SingleVector,2))                  
                    app.button_show_single.Enable = 'off';
                else
                    app.button_show_single.Enable = 'on'; 
                end
                                               
                if SignificantVector == zeros(size(SignificantVector,1),size(SignificantVector,2))                  
                    app.button_show_significant.Enable = 'off';
                else
                    app.button_show_significant.Enable = 'on';
                end
        
        app.switch_single.Value = app.parent_app.ParticleFile(app.spinner_particle.Value).SinglePart;
        app.switch_significant.Value = app.parent_app.ParticleFile(app.spinner_particle.Value).SignificantPart;
        
           
            if app.switch_single.Value == 1
                app.lamp_single.Color = 'Green';
            else
                app.lamp_single.Color = 'Red';
            end
            
            if app.switch_significant.Value == 1
                app.lamp_significant.Color = 'Cyan';
            else
                app.lamp_significant.Color = 'Red';
            end
        
        app.slider_bin_width.MajorTicks = [1 2 3 4 5 6 7 8 9 10 11 12 13];
        app.slider_bin_width.MinorTicks = [];
        app.slider_bin_width.FontSize = 10;
        % app.slider_bin_width.MajorTickLabels = {'0.000001','0.000005','0.00001', '0.00005', '0.0001', '0.005', '0.001', '0.005', '0.01', '0.05', '0.1', '0.5', '1'}';        
        app.slider_bin_width.MajorTickLabels = {'','','', '', '', '', '', '', '', '', '', '', ''}';                
        app.slider_bin_width.Limits = [ 1 13 ];
        app.slider_bin_width.Value = 7;
        app.bin_width = app.slider_bin_width.Value;
                  
        Population = load([app.parent_app.FileFolder '\' app.parent_app.FileName '_CoordinatesFile.mat' ]);    
        app.sample_fov = Population.FOVmerged; 
        app.sample_fov = flipdim(app.sample_fov,1);
        
        app.particle_location = Population.Pcles.Location;
        app.particle_location(:,2) = size(app.sample_fov,2) - app.particle_location(:,2);
        
        UpdateSampleFOVPlot(app);             
       
        
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
        
        CloseChildApp(app.parent_app);        
        delete(app)
            
        end

        % Value changed function: drop_tt
        function DropTTChanged(app, event)
                    
            app.lamp_tt.Color = 'Yellow'; 
            app.name_timetrace = app.drop_tt.Value;
            
            app.spinner_particle.Limits = [app.parent_app.ParticleFile(1).Number  app.parent_app.ParticleFile(end).Number];
            
            app.lamp_tt.Color = 'Green';
                         
            %Change the plots             
            ActionsChangeSNRFrame(app); 
            
            %Settings and preparation for Timetrace plotting
            if isfield(app.parent_app.ParticleFile(app.spinner_particle.Value).(app.name_timetrace), 'Intensity') == 1
                
                % setting the frame slider and spinner                
                app.slider_frame.Limits = [ 1 length(app.parent_app.ParticleFile(app.spinner_particle.Value).(app.name_timetrace).Time)  ];
                app.spinner_frame.Limits = [  1 length(app.parent_app.ParticleFile(app.spinner_particle.Value).(app.name_timetrace).Time) ];
                app.entry_contrast_frame.Value = length(app.parent_app.ParticleFile(app.spinner_particle.Value).(app.name_timetrace).Time);
                                                
                cla(app.plot_tt)
                cla(app.plot_fov);
                hold(app.plot_fov,'on');
                app.plot_fov.XLim = [ 1 size(app.parent_app.ParticleFile(app.spinner_particle.Value).(app.name_timetrace).RawData,1)  ];
                app.plot_fov.YLim = [ 1 size(app.parent_app.ParticleFile(app.spinner_particle.Value).(app.name_timetrace).RawData,2)  ];
                app.plot_fov.XTick = [];
                app.plot_fov.YTick = [];  
                
                if isfield(app.parent_app.ParticleFile(app.spinner_particle.Value).(app.name_timetrace), 'DriftCorrTrace') == 1
                    app.checkbox_drift_correction.Enable = 'on';
                else
                    app.checkbox_drift_correction.Enable = 'off';                    
                end  
               
            end

            UpdateTimetracePlot(app); 
            UpdateSignalLevelsPlot(app); 
            UpdateContrastPlot(app); 
            UpdateXYtracePlot(app);
            
        end

        % Value changed function: drop_spectra
        function DropSpectraChanged(app, event)
            
            app.lamp_spectra.Color = 'Yellow'; 
            app.name_hsm = app.drop_spectra.Value;            
            app.lamp_spectra.Color = 'Green';
            
            %Change the plots                     
            UpdateSpectraPlot(app);
            UpdateContrastPlot(app);
                     
        end

        % Callback function
        function HSM2DropDownValueChanged(app, event)
      
        end

        % Value changed function: spinner_particle
        function SpinnerParticleChanged(app, event)
          
           
            clc;
            cla(app.plot_tt);
                        
            app.switch_single.Value = app.parent_app.ParticleFile(app.spinner_particle.Value).SinglePart;
            app.switch_significant.Value = app.parent_app.ParticleFile(app.spinner_particle.Value).SignificantPart;
            
            if app.switch_single.Value == 1
                app.lamp_single.Color = 'Green';
            else
                app.lamp_single.Color = 'Red';
            end
            
            if app.switch_significant.Value == 1
                app.lamp_significant.Color = 'Cyan';
            else
                app.lamp_significant.Color = 'Red';
            end   


            UpdateSampleFOVPlot(app); 
            
            % Particle spectra plot                       
            UpdateSpectraPlot(app);
            
            % Contrast plot
            UpdateContrastPlot(app);
          
            % SNR plot
            ActionsChangeSNRFrame(app);                
                
           
            %Settings and preparation for Timetrace plotting
            if isfield(app.parent_app.ParticleFile(app.spinner_particle.Value).(app.name_timetrace), 'Intensity') == 1                
                
                % setting the frame slider and spinner
                app.slider_frame.Limits = [ 1 length(app.parent_app.ParticleFile(app.spinner_particle.Value).(app.name_timetrace).Time)  ];
                app.spinner_frame.Limits = [  1 length(app.parent_app.ParticleFile(app.spinner_particle.Value).(app.name_timetrace).Time) ];
                app.entry_contrast_frame.Value = length(app.parent_app.ParticleFile(app.spinner_particle.Value).(app.name_timetrace).Time);
                
                cla(app.plot_tt);
                cla(app.plot_fov);
                hold(app.plot_fov,'on');
                app.plot_fov.XLim = [ 1 size(app.parent_app.ParticleFile(app.spinner_particle.Value).(app.name_timetrace).RawData,1)  ];
                app.plot_fov.YLim = [ 1 size(app.parent_app.ParticleFile(app.spinner_particle.Value).(app.name_timetrace).RawData,2)  ];
                app.plot_fov.XTick = [];
                app.plot_fov.YTick = [];
                
                if isfield(app.parent_app.ParticleFile(app.spinner_particle.Value).(app.name_timetrace), 'DriftCorrTrace') == 1
                    app.checkbox_drift_correction.Enable = 'on';
                else
                    app.checkbox_drift_correction.Enable = 'off';                    
                end                
                
                                
            end
                
            UpdateTimetracePlot(app); 
            UpdateSignalLevelsPlot(app);
            UpdateXYtracePlot(app);
            
        end

        % Value changed function: entry_ref_frame
        function TTFrame(app, event)
                           
            UpdateTimetracePlot(app); 
            UpdateSignalLevelsPlot(app); 
            UpdateContrastPlot(app); 
            ActionsChangeSNRFrame(app);

        end

        % Value changed function: slider_frame
        function SliderFrameChanged(app, event)

%          erase this somehow!!!    
            
        end

        % Value changed function: button_normalize
        function TTNormalize(app, event)
                
            UpdateTimetracePlot(app);
            UpdateSignalLevelsPlot(app);

        end

        % Value changing function: slider_frame
        function SliderFrameChanging(app, event)
            
            app.slider_frame.Value = round(event.Value);
            app.spinner_frame.Value = app.slider_frame.Value;
                        
            UpdateTimetracePlot(app);
            UpdateXYtracePlot(app);
                              
        end

        % Value changed function: button_rmv_spikes
        function TTRMVSpikes(app, event)
              
            UpdateTimetracePlot(app);
            UpdateSignalLevelsPlot(app);

        end

        % Value changed function: switch_single
        function SwitchSingleChanged(app, event)
            
            app.parent_app.ParticleFile(app.spinner_particle.Value).SinglePart = app.switch_single.Value;
                        
            if app.switch_single.Value == 1
                app.lamp_single.Color = 'Green';
            else
                app.lamp_single.Color = 'Red';
            end    
            
                for i = app.parent_app.ParticleFile(1).Number:app.parent_app.ParticleFile(end).Number                    
                    SingleVector(i) = app.parent_app.ParticleFile(i).SinglePart;
                    SignificantVector(i) = app.parent_app.ParticleFile(i).SignificantPart;                
                end
                
                if SingleVector == zeros(size(SingleVector,1),size(SingleVector,2))                  
                    app.button_show_single.Enable = 'off';
                else
                    app.button_show_single.Enable = 'on'; 
                end
                                               
                if SignificantVector == zeros(size(SignificantVector,1),size(SignificantVector,2))                  
                    app.button_show_significant.Enable = 'off';
                else
                    app.button_show_significant.Enable = 'on';
                end
            
        
        end

        % Value changed function: switch_significant
        function SwitchSignificantChanged(app, event)
        
            app.parent_app.ParticleFile(app.spinner_particle.Value).SignificantPart = app.switch_significant.Value;
  
            if app.switch_significant.Value == 1
                app.lamp_significant.Color = 'Cyan';
            else
                app.lamp_significant.Color = 'Red';
            end
            
                for i = app.parent_app.ParticleFile(1).Number:app.parent_app.ParticleFile(end).Number                    
                    SingleVector(i) = app.parent_app.ParticleFile(i).SinglePart;
                    SignificantVector(i) = app.parent_app.ParticleFile(i).SignificantPart;                
                end
                
                if SingleVector == zeros(size(SingleVector,1),size(SingleVector,2))                  
                    app.button_show_single.Enable = 'off';
                else
                    app.button_show_single.Enable = 'on'; 
                end
                                               
                if SignificantVector == zeros(size(SignificantVector,1),size(SignificantVector,2))                  
                    app.button_show_significant.Enable = 'off';
                else
                    app.button_show_significant.Enable = 'on';
                end
            
            
        end

        % Value changed function: spinner_frame
        function SpinnerFrameChanged(app, event)
            
            app.spinner_frame.Value = round(event.Value);
            app.slider_frame.Value = app.spinner_frame.Value;
              
            UpdateTimetracePlot(app);
            UpdateXYtracePlot(app);

        end

        % Callback function
        function PlasmonShiftButtonPushed(app, event)
            
%          erase this somehow!!!   
            
        end

        % Button pushed function: button_contrast
        function ActionsContrast(app, event)
          
            UpdateContrastPlot(app,event)
          
        end

        % Button pushed function: button_save_particle
        function ActionsSaveParticle(app, event)
            
            SaveFunctTimetracePlot(app.parent_app.FileFolder,app.name_timetrace,app.HSMXName,app.HSMYName,app.parent_app.ParticleFile(app.spinner_particle.Value).(app.name_timetrace).Time,app.tt_to_plot,app.parent_app.ParticleFile(app.spinner_particle.Value).(app.HSMXName).Lambda,app.parent_app.ParticleFile(app.spinner_particle.Value).(app.HSMYName).Lambda,app.parent_app.ParticleFile(app.spinner_particle.Value).(app.HSMXName).Intensity,app.parent_app.ParticleFile(app.spinner_particle.Value).(app.HSMYName).Intensity,app.parent_app.ParticleFile(app.spinner_particle.Value).(app.HSMXName).Lorentz,app.parent_app.ParticleFile(app.spinner_particle.Value).(app.HSMYName).Lorentz,app.spinner_particle.Value);      
                      
        end

        % Button pushed function: button_save_all
        function ActionSaveAll(app, event)


        for i = app.parent_app.ParticleFile(1).Number:app.parent_app.ParticleFile(end).Number     
        
            if isfield(app.parent_app.ParticleFile(i).(app.name_timetrace), 'Intensity') == 1

                timetrace = app.parent_app.ParticleFile(i).(app.name_timetrace).Intensity;
                
                if app.button_normalize.Value == 1   
                    ref = mean(hampel(app.parent_app.ParticleFile(i).(app.name_timetrace).Intensity(3:app.entry_ref_frame.Value),5));            
                    timetrace = timetrace ./ ref;
                end
                if app.button_rmv_spikes.Value == 1                                   
                    timetrace = hampel(timetrace,5);                    
                end
      
                    SaveFunctTimetracePlot(app.parent_app.FileFolder,app.name_timetrace,app.HSMXName,app.HSMYName,app.parent_app.ParticleFile(i).(app.name_timetrace).Time,timetrace,app.parent_app.ParticleFile(i).(app.HSMXName).Lambda,app.parent_app.ParticleFile(i).(app.HSMYName).Lambda,app.parent_app.ParticleFile(i).(app.HSMXName).Intensity,app.parent_app.ParticleFile(i).(app.HSMYName).Intensity,app.parent_app.ParticleFile(i).(app.HSMXName).Lorentz,app.parent_app.ParticleFile(i).(app.HSMYName).Lorentz,i);      
             
                close all;
                clear timetrace;
            
            end
                        
        end     
            
        
        end

        % Selection changed function: panel_show_particles
        function panel_show_particlesSelectionChanged(app, event)
           
            UpdateSampleFOVPlot(app); 
            UpdateContrastPlot(app);
            UpdateSpectraPlot(app);            
                        
        end

        % Size changed function: UIFigure
        function UpdateContrastPlot(app, event)
   
        %% Contrast distribution plot
        % show contrast for all particles with measured timetrace and HSM        
        
        if app.button_show_all.Value == 1                
            inx = 1;
            for i = app.parent_app.ParticleFile(1).Number:app.parent_app.ParticleFile(end).Number
                if isfield(app.parent_app.ParticleFile(i).(app.name_timetrace), 'Intensity') == 1 & isfield(app.parent_app.ParticleFile(i).(app.name_hsm), 'Intensity') == 1          

                            if app.button_rmv_spikes.Value == 1                                                         
                                if app.entry_contrast_frame.Value < 6                    
                                    sig = mean(hampel(app.parent_app.ParticleFile(i).(app.name_timetrace).Intensity(1:10),5));
                                elseif app.entry_contrast_frame.Value > length(app.parent_app.ParticleFile(i).(app.name_timetrace).Intensity)-6                     
                                    sig = mean(hampel(app.parent_app.ParticleFile(i).(app.name_timetrace).Intensity(end-10:end),5));
                                else
                                    sig = mean(hampel(app.parent_app.ParticleFile(i).(app.name_timetrace).Intensity(app.entry_contrast_frame.Value-5:app.entry_contrast_frame.Value+5),5));
                                end                                
                                ref = mean(hampel(app.parent_app.ParticleFile(i).(app.name_timetrace).Intensity(3:app.entry_ref_frame.Value),5)); %reference value for the contrast                                                                                            
                            else
                                if app.entry_contrast_frame.Value < 6                    
                                    sig = mean(app.parent_app.ParticleFile(i).(app.name_timetrace).Intensity(1:10));
                                elseif app.entry_contrast_frame.Value > length(app.parent_app.ParticleFile(i).(app.name_timetrace).Intensity)-6                     
                                    sig = mean(app.parent_app.ParticleFile(i).(app.name_timetrace).Intensity(end-10:end));
                                else
                                    sig = mean(app.parent_app.ParticleFile(i).(app.name_timetrace).Intensity(app.entry_contrast_frame.Value-5:app.entry_contrast_frame.Value+5));
                                end                                
                                ref = mean(app.parent_app.ParticleFile(i).(app.name_timetrace).Intensity(3:app.entry_ref_frame.Value)); %reference value for the contrast                                                                                                                           
                            end
                            
                            contrast(inx) = sig./ref - 1;                
                            wavelength(inx) = app.parent_app.ParticleFile(i).(app.name_hsm).SPlambda;
                            
                                if i == app.spinner_particle.Value
                                    contrast_active = contrast(inx);
                                    wavelength_active = wavelength(inx);
                                end 
                                                                                           
                            inx = inx+1;                        
                end    
            end   
        end 
 
        % show contrast for only single particles with measured timetrace and HSM
        if app.button_show_single.Value == 1                
            inx = 1;
            for i = app.parent_app.ParticleFile(1).Number:app.parent_app.ParticleFile(end).Number
                if isfield(app.parent_app.ParticleFile(i).(app.name_timetrace), 'Intensity') == 1 & isfield(app.parent_app.ParticleFile(i).(app.name_hsm), 'Intensity') == 1          
                    if app.parent_app.ParticleFile(i).SinglePart == 1  
                            if app.button_rmv_spikes.Value == 1                                                         
                                if app.entry_contrast_frame.Value < 6                    
                                    sig = mean(hampel(app.parent_app.ParticleFile(i).(app.name_timetrace).Intensity(1:10),5));
                                elseif app.entry_contrast_frame.Value > length(app.parent_app.ParticleFile(i).(app.name_timetrace).Intensity)-6                     
                                    sig = mean(hampel(app.parent_app.ParticleFile(i).(app.name_timetrace).Intensity(end-10:end),5));
                                else
                                    sig = mean(hampel(app.parent_app.ParticleFile(i).(app.name_timetrace).Intensity(app.entry_contrast_frame.Value-5:app.entry_contrast_frame.Value+5),5));
                                end                                
                                ref = mean(hampel(app.parent_app.ParticleFile(i).(app.name_timetrace).Intensity(3:app.entry_ref_frame.Value),5)); %reference value for the contrast                                                                                            
                            else
                                if app.entry_contrast_frame.Value < 6                    
                                    sig = mean(app.parent_app.ParticleFile(i).(app.name_timetrace).Intensity(1:10));
                                elseif app.entry_contrast_frame.Value > length(app.parent_app.ParticleFile(i).(app.name_timetrace).Intensity)-6                     
                                    sig = mean(app.parent_app.ParticleFile(i).(app.name_timetrace).Intensity(end-10:end));
                                else
                                    sig = mean(app.parent_app.ParticleFile(i).(app.name_timetrace).Intensity(app.entry_contrast_frame.Value-5:app.entry_contrast_frame.Value+5));
                                end                                
                                ref = mean(app.parent_app.ParticleFile(i).(app.name_timetrace).Intensity(3:app.entry_ref_frame.Value)); %reference value for the contrast                                                                                                                           
                            end
                            
                            contrast(inx) = sig./ref - 1;                
                            wavelength(inx) = app.parent_app.ParticleFile(i).(app.name_hsm).SPlambda;                                                      
                                                        
                                if i == app.spinner_particle.Value
                                    contrast_active = contrast(inx);
                                    wavelength_active = wavelength(inx);
                                end 
                                
                            inx = inx+1;     
                    end   
                end    
            end   
        end 
 
 
        % show contrast for only significant particles with measured timetrace and HSM
        if app.button_show_significant.Value == 1                
            inx = 1;
            for i = app.parent_app.ParticleFile(1).Number:app.parent_app.ParticleFile(end).Number
                if isfield(app.parent_app.ParticleFile(i).(app.name_timetrace), 'Intensity') == 1 & isfield(app.parent_app.ParticleFile(i).(app.name_hsm), 'Intensity') == 1          
                    if app.parent_app.ParticleFile(i).SignificantPart == 1  
                            if app.button_rmv_spikes.Value == 1                                                         
                                if app.entry_contrast_frame.Value < 6                    
                                    sig = mean(hampel(app.parent_app.ParticleFile(i).(app.name_timetrace).Intensity(1:10),5));
                                elseif app.entry_contrast_frame.Value > length(app.parent_app.ParticleFile(i).(app.name_timetrace).Intensity)-6                     
                                    sig = mean(hampel(app.parent_app.ParticleFile(i).(app.name_timetrace).Intensity(end-10:end),5));
                                else
                                    sig = mean(hampel(app.parent_app.ParticleFile(i).(app.name_timetrace).Intensity(app.entry_contrast_frame.Value-5:app.entry_contrast_frame.Value+5),5));
                                end                                
                                ref = mean(hampel(app.parent_app.ParticleFile(i).(app.name_timetrace).Intensity(3:app.entry_ref_frame.Value),5)); %reference value for the contrast                                                                                            
                            else
                                if app.entry_contrast_frame.Value < 6                    
                                    sig = mean(app.parent_app.ParticleFile(i).(app.name_timetrace).Intensity(1:10));
                                elseif app.entry_contrast_frame.Value > length(app.parent_app.ParticleFile(i).(app.name_timetrace).Intensity)-6                     
                                    sig = mean(app.parent_app.ParticleFile(i).(app.name_timetrace).Intensity(end-10:end));
                                else
                                    sig = mean(app.parent_app.ParticleFile(i).(app.name_timetrace).Intensity(app.entry_contrast_frame.Value-5:app.entry_contrast_frame.Value+5));
                                end                                
                                ref = mean(app.parent_app.ParticleFile(i).(app.name_timetrace).Intensity(3:app.entry_ref_frame.Value)); %reference value for the contrast                                                                                                                           
                            end
                            
                            contrast(inx) = sig./ref - 1;                
                            wavelength(inx) = app.parent_app.ParticleFile(i).(app.name_hsm).SPlambda; 
                                                                                    
                                if i == app.spinner_particle.Value
                                    contrast_active = contrast(inx);
                                    wavelength_active = wavelength(inx);
                                end 
                                                                                           
                            inx = inx+1;     
                    end   
                end    
            end   
        end  

        
         if exist('wavelength') ~= 0
                 % Contrast additional calculation and plotting
                 wavelength = wavelength( abs(contrast)<2.5 );
                 contrast = contrast( abs(contrast)<2.5 );   
                 wavelength_mu = mean(wavelength);
                 wavelength_sigma = std(wavelength);
                
                 cla(app.plot_constrast);
                 hold(app.plot_constrast,'on');
                 scatter(app.plot_constrast,wavelength,contrast,'bo');  
                 if exist('contrast_active') ~= 0
                     scatter(app.plot_constrast,wavelength_active,contrast_active,'ro', 'filled');
                 end    
                 
                 app.plot_constrast.XLim = [round(wavelength_mu-3*wavelength_sigma) round(wavelength_mu+3*wavelength_sigma)];
                 
                 if app.button_manual_y_axis.Value == 1 
                     app.plot_constrast.YLim = [app.entry_y_axis_min.Value-1  app.entry_y_axis_max.Value-1 ];
                     limY = [app.entry_y_axis_min.Value-1  app.entry_y_axis_max.Value-1 ];
                 else  
                     if -max(contrast) < max(contrast)
                         app.plot_constrast.YLim = [-max(contrast)*1.1  max(contrast)*1.1]; 
                         limY = [-max(contrast)*1.1  max(contrast)*1.1];
                     else
                         app.plot_constrast.YLim = [max(contrast)*1.1  -max(contrast)*1.1];
                         limY = [max(contrast)*1.1  -max(contrast)*1.1];
                     end                       
                 end
                 
                 if exist('event') ~= 0 
                     if event.EventName == 'ButtonPushed'
                            time = app.parent_app.ParticleFile(app.spinner_particle.Value).(app.name_timetrace).Time(app.entry_contrast_frame.Value);        
                            SaveTimetraceContrast(wavelength,contrast,app.parent_app.FileFolder,app.name_timetrace,app.entry_contrast_frame.Value,time,limY); 
                     
                     end
                 end    
         else
                 cla(app.plot_constrast);            
         end    


        end

        % Size changed function: panel_actions
        function UpdateSpectraPlot(app, event)
            
            %% Particle spectra plot            
            if isfield(app.parent_app.ParticleFile(app.spinner_particle.Value).(app.name_hsm), 'Intensity') == 1            
                
                Lorentz_eV = linspace(1248/(min(app.parent_app.ParticleFile(app.spinner_particle.Value).(app.name_hsm).Lambda)-30),1248/(max(app.parent_app.ParticleFile(app.spinner_particle.Value).(app.name_hsm).Lambda)+30),100);                                                          
                plot(app.plot_spectra,app.parent_app.ParticleFile(app.spinner_particle.Value).(app.name_hsm).Lambda,app.parent_app.ParticleFile(app.spinner_particle.Value).(app.name_hsm).Intensity,'ro',1248./Lorentz_eV,Lorentzfunction(app.parent_app.ParticleFile(app.spinner_particle.Value).(app.name_hsm).Lorentz,Lorentz_eV),'r-');    
                app.plot_spectra.XLim = [ (min(app.parent_app.ParticleFile(app.spinner_particle.Value).(app.name_hsm).Lambda)-30)  (max(app.parent_app.ParticleFile(app.spinner_particle.Value).(app.name_hsm).Lambda)+30) ];
                %  app.plot_tt.Title.String = [];            
                app.plot_spectra_label_SP_lambda.Text = ['λ = ' num2str(round(app.parent_app.ParticleFile(app.spinner_particle.Value).(app.name_hsm).SPlambda,1)) ' nm'];
                app.plot_spectra_label_SP_gamma.Text = ['Γ = ' num2str(round(app.parent_app.ParticleFile(app.spinner_particle.Value).(app.name_hsm).Linewidth,1)) ' meV'];
               
            else            
                cla(app.plot_spectra); 
                app.plot_spectra_label_SP_lambda.Text = ['No data'];
                app.plot_spectra_label_SP_gamma.Text = ['No data'];
            end  
                    
        end

        % Value changed function: entry_contrast_frame
        function ActionsChangeSNRFrame(app, event)

            
        % show SNR andN (shot noise) for all particles with measured timetrace
        if app.button_show_all.Value == 1                
            inx = 1;
            for i = app.parent_app.ParticleFile(1).Number:app.parent_app.ParticleFile(end).Number
                if isfield(app.parent_app.ParticleFile(i).(app.name_timetrace), 'Intensity') == 1                          

                            if app.button_rmv_spikes.Value == 1                                                                                        
                                ref = mean(hampel(app.parent_app.ParticleFile(i).(app.name_timetrace).Intensity(3:app.entry_ref_frame.Value),5)); %reference value for the contrast                                                                                            
                                ref_std = std(hampel(app.parent_app.ParticleFile(i).(app.name_timetrace).Intensity(3:app.entry_ref_frame.Value),5)); %reference value for the contrast                                                                                                                                                        
                            else                               
                                ref = mean(app.parent_app.ParticleFile(i).(app.name_timetrace).Intensity(3:app.entry_ref_frame.Value)); %reference value for the contrast                                                                                                                           
                                ref_std = std(app.parent_app.ParticleFile(i).(app.name_timetrace).Intensity(3:app.entry_ref_frame.Value)); %reference value for the contrast                                                                                                                                                       
                            end
                            
                            signalN(inx) = ref;
                            % app.parent_app.ParticleFile(i).(app.name_timetrace).NormSignalN = ref;
                            signalSTD(inx) = ref_std;
                            % app.parent_app.ParticleFile(i).(app.name_timetrace).NormSignalSTD = ref_std;
                            signalSN(inx) = sqrt(ref);
                            % app.parent_app.ParticleFile(i).(app.name_timetrace).NormSignalSN = sqrt(ref);                            
                            
                                if i == app.spinner_particle.Value
                                    signalN_active = signalN(inx);
                                    signalSTD_active = signalSTD(inx);
                                    signalSN_active = signalSN(inx);
                                end  
                                                                                           
                            inx = inx+1;                        
                end    
            end   
        end          
            
            
        % show contrast for only single particles with measured timetrace
        if app.button_show_single.Value == 1                
            inx = 1;
            for i = app.parent_app.ParticleFile(1).Number:app.parent_app.ParticleFile(end).Number
                if isfield(app.parent_app.ParticleFile(i).(app.name_timetrace), 'Intensity') == 1          
                    if app.parent_app.ParticleFile(i).SinglePart == 1  
                            
                            if app.button_rmv_spikes.Value == 1                                                                                        
                                ref = mean(hampel(app.parent_app.ParticleFile(i).(app.name_timetrace).Intensity(3:app.entry_ref_frame.Value),5)); %reference value for the contrast                                                                                            
                                ref_std = std(hampel(app.parent_app.ParticleFile(i).(app.name_timetrace).Intensity(3:app.entry_ref_frame.Value),5)); %reference value for the contrast                                                                                                                                                        
                            else                               
                                ref = mean(app.parent_app.ParticleFile(i).(app.name_timetrace).Intensity(3:app.entry_ref_frame.Value)); %reference value for the contrast                                                                                                                           
                                ref_std = std(app.parent_app.ParticleFile(i).(app.name_timetrace).Intensity(3:app.entry_ref_frame.Value)); %reference value for the contrast                                                                                                                                                       
                            end
                            
                            signalN(inx) = ref;
                            % app.parent_app.ParticleFile(i).(app.name_timetrace).NormSignalN = ref;
                            signalSTD(inx) = ref_std;
                            % app.parent_app.ParticleFile(i).(app.name_timetrace).NormSignalSTD = ref_std;
                            signalSN(inx) = sqrt(ref);
                            % app.parent_app.ParticleFile(i).(app.name_timetrace).NormSignalSN = sqrt(ref);                            
                            
                                if i == app.spinner_particle.Value
                                    signalN_active = signalN(inx);
                                    signalSTD_active = signalSTD(inx);
                                    signalSN_active = signalSN(inx);
                                end 
                                
                            inx = inx+1;     
                    end   
                end    
            end   
        end             
            
            
        % show contrast for only significant particles with measured timetrace and HSM
        if app.button_show_significant.Value == 1                
            inx = 1;
            for i = app.parent_app.ParticleFile(1).Number:app.parent_app.ParticleFile(end).Number
                if isfield(app.parent_app.ParticleFile(i).(app.name_timetrace), 'Intensity') == 1        
                    if app.parent_app.ParticleFile(i).SignificantPart == 1  
                            
                            if app.button_rmv_spikes.Value == 1                                                                                        
                                ref = mean(hampel(app.parent_app.ParticleFile(i).(app.name_timetrace).Intensity(3:app.entry_ref_frame.Value),5)); %reference value for the contrast                                                                                            
                                ref_std = std(hampel(app.parent_app.ParticleFile(i).(app.name_timetrace).Intensity(3:app.entry_ref_frame.Value),5)); %reference value for the contrast                                                                                                                                                        
                            else                               
                                ref = mean(app.parent_app.ParticleFile(i).(app.name_timetrace).Intensity(3:app.entry_ref_frame.Value)); %reference value for the contrast                                                                                                                           
                                ref_std = std(app.parent_app.ParticleFile(i).(app.name_timetrace).Intensity(3:app.entry_ref_frame.Value)); %reference value for the contrast                                                                                                                                                       
                            end
                            
                            signalN(inx) = ref;
                            % app.parent_app.ParticleFile(i).(app.name_timetrace).NormSignalN = ref;
                            signalSTD(inx) = ref_std;
                            % app.parent_app.ParticleFile(i).(app.name_timetrace).NormSignalSTD = ref_std;
                            signalSN(inx) = sqrt(ref);
                            % app.parent_app.ParticleFile(i).(app.name_timetrace).NormSignalSN = sqrt(ref);                            
                            
                                if i == app.spinner_particle.Value
                                    signalN_active = signalN(inx);
                                    signalSTD_active = signalSTD(inx);
                                    signalSN_active = signalSN(inx);
                                end 
                                                                                           
                            inx = inx+1;     
                    end   
                end    
            end   
        end            
            
        
        if exist('signalN') ~= 0
               
                cla(app.plot_snr)
                hold(app.plot_snr,'on')
                scatter(app.plot_snr,signalN,signalSTD,'bo');
                scatter(app.plot_snr,signalN,signalSN,'ro'); 
                if exist('signalN_active') ~= 0
                    scatter(app.plot_snr,signalN_active,signalSTD_active,'co', 'filled');
                    scatter(app.plot_snr,signalN_active,signalSN_active,'yo', 'filled');                    
                end 
                app.plot_snr.XLim = [0.9*min(signalN) 1.1*max(signalN)];
                app.plot_snr.YLim = [-Inf Inf];
                app.plot_snr.XScale = 'log';
                app.plot_snr.YScale = 'log';
       
                 if exist('event') ~= 0 
                     if event.EventName == 'ButtonPushed'
                           
                         time = app.parent_app.ParticleFile(app.spinner_particle.Value).(app.name_timetrace).Time(app.entry_ref_frame.Value);                                    
                         SaveTimetraceSNR(signalN,signalSTD,signalSN,app.parent_app.FileFolder,app.name_timetrace,app.entry_ref_frame.Value,time); 
                     
                     end
                 end 
                
        else
                cla(app.plot_snr);            
        end  
            
   
        end

        % Value changed function: button_manual_y_axis
        function TTManualAxis(app, event)
            
            % Contrast plot
            UpdateTimetracePlot(app);
            UpdateContrastPlot(app);            
            UpdateSignalLevelsPlot(app);
            
        end

        % Callback function
        function UpdateTimetracePlot(app, event)

        if isfield(app.parent_app.ParticleFile(app.spinner_particle.Value).(app.name_timetrace), 'Intensity') == 1
        
            app.tt_to_plot = app.parent_app.ParticleFile(app.spinner_particle.Value).(app.name_timetrace).Intensity;
            
            app.field_time.Value = app.parent_app.ParticleFile(app.spinner_particle.Value).(app.name_timetrace).Time(app.spinner_frame.Value);
            
            if app.button_rmv_spikes.Value == 1                                   
                app.tt_to_plot = hampel(app.tt_to_plot,5);                    
            end  
            
            if app.button_normalize.Value == 1                
                ref = mean(app.tt_to_plot(3:app.entry_ref_frame.Value));
                app.tt_to_plot = app.tt_to_plot ./ ref;                    
            end
            
                % Updating some information fields
                app.field_time.Value = app.parent_app.ParticleFile(app.spinner_particle.Value).(app.name_timetrace).Time(app.spinner_frame.Value);
            
                % Plotting the timetrace
                cla(app.plot_tt)
                hold(app.plot_tt,'on')
                plot(app.plot_tt,app.parent_app.ParticleFile(app.spinner_particle.Value).(app.name_timetrace).Time(1:app.spinner_frame.Value), app.tt_to_plot(1:app.spinner_frame.Value) ,'-','color', [1 0 0]);    
                plot(app.plot_tt,app.parent_app.ParticleFile(app.spinner_particle.Value).(app.name_timetrace).Time(app.spinner_frame.Value:end), app.tt_to_plot(app.spinner_frame.Value:end) ,'-','color', [1 0.5 0.5]);                  
                app.plot_tt.Title.String = [ app.name_timetrace ];
                app.plot_tt.XLim = [app.parent_app.ParticleFile(app.spinner_particle.Value).(app.name_timetrace).Time(1)  app.parent_app.ParticleFile(app.spinner_particle.Value).(app.name_timetrace).Time(end) ];
                
                    if app.button_manual_y_axis.Value == 1 
                        app.plot_tt.YLim = [app.entry_y_axis_min.Value  app.entry_y_axis_max.Value ];                    
                    else            
                        app.plot_tt.YLim = [-Inf Inf]; 
                    end
                
                % Plot triangles for frames where the drift correction happened  
                if app.checkbox_drift_correction.Value == 1
                
                    if isfield(app.parent_app.ParticleFile(app.spinner_particle.Value).(app.name_timetrace), 'DriftCorrTrace') == 1
                        
                        time_axis = app.parent_app.ParticleFile(app.spinner_particle.Value).(app.name_timetrace).Time; 
                        drift_trace = app.parent_app.ParticleFile(app.spinner_particle.Value).(app.name_timetrace).DriftCorrTrace;
                        
                        time_axis(drift_trace==0) = [];
                        time_axis = reshape(time_axis,1,[]);
                        
                        drift_trace(drift_trace==0) = [];
                        drift_trace = reshape(drift_trace,1,[]);
                        
                        if app.button_manual_y_axis.Value == 1 
                            drift_trace =  ones(size(drift_trace)) .* app.entry_y_axis_max.Value;   % ones(size(drift_trace)) .* 0.9 .* app.entry_y_axis_max.Value                
                        else            
                            drift_trace =  ones(size(drift_trace)) .*(max(app.tt_to_plot));   % ones(size(drift_trace)) .* 0.9 .* app.entry_y_axis_max.Value                            
                        end
                        
                        if isempty('drift_trace') ~= 1
                            scatter(app.plot_tt,time_axis,drift_trace,'kv','filled');
                        end                                                   
                    end
                end
                
                % Plotting the ROI
                imagesc(app.plot_fov,app.parent_app.ParticleFile(app.spinner_particle.Value).(app.name_timetrace).RawData(:,:,app.spinner_frame.Value));             
          
        else
            cla(app.plot_tt); 
            cla(app.plot_fov);             
        end    
 
            
        end

        % Value changed function: slider_bin_width
        function SliderHistBinChanged(app, event)
            
            % Update the histogram as the slider value for bindwidth changes         
            app.bin_width = round(app.slider_bin_width.Value);
            UpdateSignalLevelsPlot(app);
                        
        end

        % Value changing function: slider_bin_width
        function SliderHistBinChanging(app, event)
            
            % Update the histogram as the slider value for bindwidth changes            
            app.bin_width = round(event.Value);
            UpdateSignalLevelsPlot(app);
                                    
        end

        % Callback function
        function UpdateSignalLevelsPlot(app, event)
        
        if isfield(app.parent_app.ParticleFile(app.spinner_particle.Value).(app.name_timetrace), 'Intensity') == 1
                           
                cla(app.plot_signal_levels)
                hold(app.plot_signal_levels,'on')
                NBins = NumHistBins(app,app.tt_to_plot);
               
                histogram(app.plot_signal_levels,app.tt_to_plot,NBins,'BinLimits',[floor(min(app.tt_to_plot)) ceil(max(app.tt_to_plot))],'FaceColor','red','Orientation','horizontal','LineWidth',0.3); 
                
                    if app.button_manual_y_axis.Value == 1 
                        app.plot_signal_levels.YLim = [app.entry_y_axis_min.Value  app.entry_y_axis_max.Value ]; 
                        limY = [app.entry_y_axis_min.Value  app.entry_y_axis_max.Value ];                         
                    else
                        app.plot_signal_levels.YLim = [min(app.tt_to_plot) max(app.tt_to_plot)]; 
                        limY = [min(app.tt_to_plot) max(app.tt_to_plot)]; 
                    end
            
                 
                 if exist('event') ~= 0 
                     if event.EventName == 'ButtonPushed'                                                          
                         SaveSignalHist(app.tt_to_plot,NBins,[floor(min(app.tt_to_plot)) ceil(max(app.tt_to_plot))],app.parent_app.FileFolder,app.name_timetrace,limY,app.spinner_particle.Value);                      
                     end
                 end     
                    
                    
        else            
            cla(app.plot_signal_levels);             
        end    
            
            
        end

        % Callback function
        function UpdateSampleFOVPlot(app, event)
            
   
        % show SNR andN (shot noise) for all particles with measured timetrace
        if app.button_show_all.Value == 1                
            inx = 1;
            for i = app.parent_app.ParticleFile(1).Number:app.parent_app.ParticleFile(end).Number
                
                location(inx,:) = app.particle_location(i,:);                          
                    if i == app.spinner_particle.Value
                        location_active = location(inx,:); 
                    end                  
                inx = inx+1;                        
                    
            end   
        end          
            
            
        % show contrast for only single particles with measured timetrace
        if app.button_show_single.Value == 1                
            inx = 1;
            for i = app.parent_app.ParticleFile(1).Number:app.parent_app.ParticleFile(end).Number
          
                if app.parent_app.ParticleFile(i).SinglePart == 1  
                    location(inx,:) = app.particle_location(i,:);                          
                        if i == app.spinner_particle.Value
                            location_active = location(inx,:); 
                        end                  
                    inx = inx+1;     
                end   
                    
            end   
        end             
            
            
        % show contrast for only significant particles with measured timetrace and HSM
        if app.button_show_significant.Value == 1                
            inx = 1;
            for i = app.parent_app.ParticleFile(1).Number:app.parent_app.ParticleFile(end).Number
                        
                 if app.parent_app.ParticleFile(i).SignificantPart == 1  
                    location(inx,:) = app.particle_location(i,:);                          
                        if i == app.spinner_particle.Value
                            location_active = location(inx,:); 
                        end                  
                    inx = inx+1;    
                 end   
               
            end   
        end            
       
             
        if exist('location') ~= 0
               
            cla(app.plot_sample_fov);
            hold(app.plot_sample_fov,'on');
            imagesc(app.plot_sample_fov,app.sample_fov);            
            app.plot_sample_fov.XLim = [1 size(app.sample_fov,1)];
            app.plot_sample_fov.YLim = [1 size(app.sample_fov,2)];
            app.plot_sample_fov.ZLim = [0 10000];
            app.plot_sample_fov.CLim = [0 10000];

            
            app.plot_sample_fov.XTick = [];
            app.plot_sample_fov.YTick = [];  
            
            for i_Beads = 1:size(location,1)
                rectangle(app.plot_sample_fov,'Position',[location(i_Beads,1)-5,location(i_Beads,2)-5,11,11],'EdgeColor','white','Curvature',[1,1],'LineWidth',1);
            end   
            
            if exist('location_active') ~= 0
                rectangle(app.plot_sample_fov,'Position',[location_active(1)-5,location_active(2)-5,11,11],'EdgeColor','red','Curvature',[1,1],'LineWidth',1,'FaceColor','red');
            end    

        else
                cla(app.plot_sample_fov);            
        end        


        end

        % Value changed function: checkbox_drift_correction
        function TTDriftCorr(app, event)

            UpdateTimetracePlot(app)
            
        end

        % Value changed function: entry_y_axis_min
        function TTYMinChanged(app, event)
            
            UpdateTimetracePlot(app); 
            UpdateSignalLevelsPlot(app); 
            UpdateContrastPlot(app); 
            ActionsChangeSNRFrame(app);
                        
        end

        % Value changed function: entry_y_axis_max
        function TTYMaxChanged(app, event)
            
            UpdateTimetracePlot(app); 
            UpdateSignalLevelsPlot(app); 
            UpdateContrastPlot(app); 
            ActionsChangeSNRFrame(app);
                        
        end

        % Callback function
        function UpdateXYtracePlot(app, event)
       
        if isfield(app.parent_app.ParticleFile(app.spinner_particle.Value).(app.name_timetrace), 'Intensity') == 1
            
            if isfield(app.parent_app.ParticleFile(app.spinner_particle.Value).(app.name_timetrace), 'Tracking') == 1                                                   

                    Xtrace = app.parent_app.ParticleFile(app.spinner_particle.Value).(app.name_timetrace).Tracking(:,1);
                    Ytrace = app.parent_app.ParticleFile(app.spinner_particle.Value).(app.name_timetrace).Tracking(:,2);
                           
                    if app.checkbox_drift_correction2.Value == 1                
                        Xtrace = detrend(Xtrace);
                        Ytrace = detrend(Ytrace);
                    end
                
                    Xlim_mean = mean(Xtrace);
                    Ylim_mean = mean(Ytrace);
                    Xlim_std = std(Xtrace);
                    Ylim_std = std(Ytrace);
                    
                    Xtrace = Xtrace - Xlim_mean;
                    Ytrace = Ytrace - Ylim_mean;
   
                cla(app.plot_scatter)
                hold(app.plot_scatter,'on')
                scatter(app.plot_scatter,Xtrace(:),Ytrace(:),'r.');
                scatter(app.plot_scatter,Xtrace(app.spinner_frame.Value),Ytrace(app.spinner_frame.Value),'bx');                            
                    
                if Xlim_std >= Ylim_std
                    app.plot_scatter.XLim = [-3*Xlim_std 3*Xlim_std];
                    app.plot_scatter.YLim = [-3*Xlim_std 3*Xlim_std];
                else    
                    app.plot_scatter.XLim = [-3*Ylim_std 3*Ylim_std];
                    app.plot_scatter.YLim = [-3*Ylim_std 3*Ylim_std];
                end 
                
            else
                cla(app.plot_scatter);    
            end    
            
        else
            cla(app.plot_scatter);
        end    
            
        end

        % Button pushed function: button_snr
        function ActionsSNR(app, event)

            ActionsChangeSNRFrame(app,event);          
            
        end

        % Button pushed function: button_signal_hist
        function ActionsSignalHist(app, event)
            
            UpdateSignalLevelsPlot(app,event);   
           
        end

        % Value changed function: checkbox_drift_correction2
        function DriftCorrectionCheckbox(app, event)
                        
           UpdateXYtracePlot(app); 
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @UpdateContrastPlot, true);
            app.panel_actions = uipanel(app.UIFigure);
            app.panel_actions.Title = 'Actions';
            app.panel_actions.SizeChangedFcn = createCallbackFcn(app, @UpdateSpectraPlot, true);
            app.button_contrast = uibutton(app.panel_actions, 'push');
            app.button_contrast.ButtonPushedFcn = createCallbackFcn(app, @ActionsContrast, true);
            app.button_contrast.Text = 'Contrast';
            app.button_save_particle = uibutton(app.panel_actions, 'push');
            app.button_save_particle.ButtonPushedFcn = createCallbackFcn(app, @ActionsSaveParticle, true);
            app.button_save_particle.Text = 'Save particle';
            app.FrmLabel = uilabel(app.panel_actions);
            app.FrmLabel.Text = 'Frm:';
            app.entry_contrast_frame = uieditfield(app.panel_actions, 'numeric');
            app.entry_contrast_frame.Limits = [0 Inf];
            app.entry_contrast_frame.ValueDisplayFormat = '%.0f';
            app.entry_contrast_frame.ValueChangedFcn = createCallbackFcn(app, @ActionsChangeSNRFrame, true);
            app.entry_contrast_frame.Value = 6000;
            app.button_save_all = uibutton(app.panel_actions, 'push');
            app.button_save_all.ButtonPushedFcn = createCallbackFcn(app, @ActionSaveAll, true);
            app.button_save_all.Text = 'Save all';
            app.button_snr = uibutton(app.panel_actions, 'push');
            app.button_snr.ButtonPushedFcn = createCallbackFcn(app, @ActionsSNR, true);
            app.button_snr.Text = 'SNR';
            app.button_signal_hist = uibutton(app.panel_actions, 'push');
            app.button_signal_hist.ButtonPushedFcn = createCallbackFcn(app, @ActionsSignalHist, true);
            app.button_signal_hist.Text = 'Signal Hist';
            app.plot_tt = uiaxes(app.UIFigure);
            title(app.plot_tt, 'Title')
            xlabel(app.plot_tt, 'Time (s)')
            ylabel(app.plot_tt, 'Scattered intensity (arb.u.)')
            app.plot_fov = uiaxes(app.UIFigure);
            app.plot_spectra = uiaxes(app.UIFigure);
            title(app.plot_spectra, 'Particle spectra')
            xlabel(app.plot_spectra, 'Wavelength (nm)')
            ylabel(app.plot_spectra, 'Scat. cross sec. (arb.u.)')
            app.plot_spectra_label_SP_lambda = uilabel(app.UIFigure);
            app.plot_spectra_label_SP_gamma = uilabel(app.UIFigure);
            app.FrameLabel = uilabel(app.UIFigure);
            app.FrameLabel.Text = 'Frame';
            app.slider_frame = uislider(app.UIFigure);
            app.slider_frame.ValueChangedFcn = createCallbackFcn(app, @SliderFrameChanged, true);
            app.slider_frame.ValueChangingFcn = createCallbackFcn(app, @SliderFrameChanging, true);
            app.panel_particle = uipanel(app.UIFigure);
            app.panel_particle.Title = 'Particle';
            app.spinner_particle = uispinner(app.panel_particle);
            app.spinner_particle.Limits = [1 Inf];
            app.spinner_particle.RoundFractionalValues = 'on';
            app.spinner_particle.ValueDisplayFormat = '%.0f';
            app.spinner_particle.ValueChangedFcn = createCallbackFcn(app, @SpinnerParticleChanged, true);
            app.spinner_particle.Value = 1;
            app.switch_single = uiswitch(app.panel_particle, 'slider');
            app.switch_single.Items = {'CLUSTER', 'SINGLE'};
            app.switch_single.ItemsData = {'0', '1'};
            app.switch_single.ValueChangedFcn = createCallbackFcn(app, @SwitchSingleChanged, true);
            app.switch_single.Value = '1';
            app.lamp_single = uilamp(app.panel_particle);
            app.lamp_significant = uilamp(app.panel_particle);
            app.switch_significant = uiswitch(app.panel_particle, 'slider');
            app.switch_significant.Items = {'no', 'SIGNIFICANT'};
            app.switch_significant.ItemsData = {'0', '1'};
            app.switch_significant.ValueChangedFcn = createCallbackFcn(app, @SwitchSignificantChanged, true);
            app.switch_significant.Value = '1';
            app.panel_measurements = uipanel(app.UIFigure);
            app.panel_measurements.Title = 'Measurements';
            app.SpectraLabel = uilabel(app.panel_measurements);
            app.SpectraLabel.Text = 'Spectra';
            app.drop_spectra = uidropdown(app.panel_measurements);
            app.drop_spectra.Items = {'', 'Option 2', 'Option 3', 'Option 4'};
            app.drop_spectra.ValueChangedFcn = createCallbackFcn(app, @DropSpectraChanged, true);
            app.drop_spectra.Value = '';
            app.TimetraceDropDownLabel = uilabel(app.panel_measurements);
            app.TimetraceDropDownLabel.Text = 'Timetrace';
            app.drop_tt = uidropdown(app.panel_measurements);
            app.drop_tt.Items = {'', 'Option 2', 'Option 3', 'Option 4'};
            app.drop_tt.ValueChangedFcn = createCallbackFcn(app, @DropTTChanged, true);
            app.drop_tt.Value = '';
            app.lamp_spectra = uilamp(app.panel_measurements);
            app.lamp_tt = uilamp(app.panel_measurements);
            app.plot_constrast = uiaxes(app.UIFigure);
            title(app.plot_constrast, 'Contrast')
            xlabel(app.plot_constrast, 'Wavelength (nm)')
            ylabel(app.plot_constrast, 'Contrast (arb.u.)')
            app.plot_snr = uiaxes(app.UIFigure);
            title(app.plot_snr, 'SNR')
            xlabel(app.plot_snr, 'N')
            ylabel(app.plot_snr, 'SNR and SN')
            app.plot_sample_fov = uiaxes(app.UIFigure);
            app.plot_signal_levels = uiaxes(app.UIFigure);
            app.panel_tt_settings = uipanel(app.UIFigure);
            app.panel_tt_settings.Title = 'Timetrace settings';
            app.button_normalize = uibutton(app.panel_tt_settings, 'state');
            app.button_normalize.ValueChangedFcn = createCallbackFcn(app, @TTNormalize, true);
            app.button_normalize.Text = 'Normalize';
            app.ReffrmLabel = uilabel(app.panel_tt_settings);
            app.ReffrmLabel.Text = 'Ref frm:';
            app.entry_ref_frame = uieditfield(app.panel_tt_settings, 'numeric');
            app.entry_ref_frame.Limits = [4 Inf];
            app.entry_ref_frame.ValueDisplayFormat = '%.0f';
            app.entry_ref_frame.ValueChangedFcn = createCallbackFcn(app, @TTFrame, true);
            app.entry_ref_frame.Value = 200;
            app.button_manual_y_axis = uibutton(app.panel_tt_settings, 'state');
            app.button_manual_y_axis.ValueChangedFcn = createCallbackFcn(app, @TTManualAxis, true);
            app.button_manual_y_axis.Text = 'Manual Yaxis';
            app.entry_y_axis_max = uieditfield(app.panel_tt_settings, 'numeric');
            app.entry_y_axis_max.ValueChangedFcn = createCallbackFcn(app, @TTYMaxChanged, true);
            app.entry_y_axis_max.Value = 1.1;
            app.entry_y_axis_min = uieditfield(app.panel_tt_settings, 'numeric');
            app.entry_y_axis_min.ValueChangedFcn = createCallbackFcn(app, @TTYMinChanged, true);
            app.entry_y_axis_min.Value = 0.9;
            app.button_rmv_spikes = uibutton(app.panel_tt_settings, 'state');
            app.button_rmv_spikes.ValueChangedFcn = createCallbackFcn(app, @TTRMVSpikes, true);
            app.button_rmv_spikes.Text = 'Rmv spikes';
            app.checkbox_drift_correction = uicheckbox(app.panel_tt_settings);
            app.checkbox_drift_correction.ValueChangedFcn = createCallbackFcn(app, @TTDriftCorr, true);
            app.checkbox_drift_correction.Text = {'Show drift'; 'correction'};
            app.panel_show_particles = uibuttongroup(app.UIFigure);
            app.panel_show_particles.SelectionChangedFcn = createCallbackFcn(app, @panel_show_particlesSelectionChanged, true);
            app.panel_show_particles.Title = 'Show particles:';
            app.button_show_all = uitogglebutton(app.panel_show_particles);
            app.button_show_all.Text = 'All';
            app.button_show_all.Value = true;
            app.button_show_single = uitogglebutton(app.panel_show_particles);
            app.button_show_single.Text = 'Single';
            app.button_show_significant = uitogglebutton(app.panel_show_particles);
            app.button_show_significant.Text = 'Significant';
            app.BinWidthSliderLabel = uilabel(app.UIFigure);
            app.BinWidthSliderLabel.Text = 'Bin Width';
            app.slider_bin_width = uislider(app.UIFigure);
            app.slider_bin_width.Limits = [1 9];
            app.slider_bin_width.MajorTicks = [1 2 3 4 5 6 7 8 9];
            app.slider_bin_width.ValueChangedFcn = createCallbackFcn(app, @SliderHistBinChanged, true);
            app.slider_bin_width.ValueChangingFcn = createCallbackFcn(app, @SliderHistBinChanging, true);
            app.slider_bin_width.MinorTicks = [];
            app.slider_bin_width.Value = 5;
            app.plot_scatter = uiaxes(app.UIFigure);
            app.FrameSpinnerLabel = uilabel(app.UIFigure);
            app.FrameSpinnerLabel.Text = 'Frame:';
            app.spinner_frame = uispinner(app.UIFigure);
            app.spinner_frame.ValueChangedFcn = createCallbackFcn(app, @SpinnerFrameChanged, true);
            app.TimesEditFieldLabel = uilabel(app.UIFigure);
            app.TimesEditFieldLabel.Text = 'Time (s):';
            app.field_time = uieditfield(app.UIFigure, 'numeric');
            app.field_time.Editable = 'off';
            app.checkbox_drift_correction2 = uicheckbox(app.UIFigure);
            app.checkbox_drift_correction2.ValueChangedFcn = createCallbackFcn(app, @DriftCorrectionCheckbox, true);
            app.checkbox_drift_correction2.Text = 'Correct drift';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Page_TT(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end