classdef Page_Visualization < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        panel_particle                  matlab.ui.container.Panel
        spinner_particle                matlab.ui.control.Spinner
        switch_single                   matlab.ui.control.Switch
        lamp_single                     matlab.ui.control.Lamp
        panel_single_particles          matlab.ui.container.Panel
        label_r2                        matlab.ui.control.Label
        button_single_particles         matlab.ui.control.Button
        entry_r2_min                    matlab.ui.control.NumericEditField
        label_r2_min                    matlab.ui.control.Label
        entry_lambda_max                matlab.ui.control.NumericEditField
        entry_lambda_min                matlab.ui.control.NumericEditField
        label_lambda_min                matlab.ui.control.Label
        label_lambda_max                matlab.ui.control.Label
        label_lambda                    matlab.ui.control.Label
        entry_linewidth_max             matlab.ui.control.NumericEditField
        entry_linewidth_min             matlab.ui.control.NumericEditField
        label_linewidth_min             matlab.ui.control.Label
        label_linewidth_max             matlab.ui.control.Label
        label_linewidth                 matlab.ui.control.Label
        panel_datasets                  matlab.ui.container.Panel
        SpectraLabel                    matlab.ui.control.Label
        drop_spectra                    matlab.ui.control.DropDown
        TimetraceDropDownLabel          matlab.ui.control.Label
        drop_tt                         matlab.ui.control.DropDown
        lamp_spectra                    matlab.ui.control.Lamp
        lamp_tt                         matlab.ui.control.Lamp
        plot_contrast                   matlab.ui.control.UIAxes
        panel_contrast_settings         matlab.ui.container.Panel
        panel_show_particles            matlab.ui.container.ButtonGroup
        button_show_all                 matlab.ui.control.ToggleButton
        button_show_single              matlab.ui.control.ToggleButton
        ContrastframeLabel              matlab.ui.control.Label
        entry_contrast_frame            matlab.ui.control.NumericEditField
        ContrastreferenceframeLabel     matlab.ui.control.Label
        entry_ref_frame                 matlab.ui.control.NumericEditField
        panel_spectrum                  matlab.ui.container.Panel
        plot_spectra                    matlab.ui.control.UIAxes
        plot_spectra_label_SP_lambda    matlab.ui.control.Label
        plot_spectra_label_SP_gamma     matlab.ui.control.Label
        panel_manual_fit                matlab.ui.container.Panel
        manual_fit_par2                 matlab.ui.control.NumericEditField
        ParametersEditFieldLabel        matlab.ui.control.Label
        manual_fit_par1                 matlab.ui.control.NumericEditField
        manual_fit_par4                 matlab.ui.control.NumericEditField
        manual_fit_button               matlab.ui.control.Button
        manual_fit_button_save          matlab.ui.control.Button
        DatapointsLabel                 matlab.ui.control.Label
        manual_fit_datapoints           matlab.ui.control.EditField
        manual_fit_par3                 matlab.ui.control.NumericEditField
        plot_hsm_fov                    matlab.ui.control.UIAxes
        HSMFrameLabel                   matlab.ui.control.Label
        slider_hsm_frame                matlab.ui.control.Slider
        plot_spectra_label_SP_r2        matlab.ui.control.Label
        panel_save                      matlab.ui.container.Panel
        button_contrast                 matlab.ui.control.Button
        button_save_particle            matlab.ui.control.Button
        button_save_all                 matlab.ui.control.Button
        panel_tt                        matlab.ui.container.Panel
        plot_tt_fov                     matlab.ui.control.UIAxes
        panel_tt_settings               matlab.ui.container.Panel
        button_normalize                matlab.ui.control.StateButton
        button_manual_y_axis            matlab.ui.control.StateButton
        entry_y_axis_max                matlab.ui.control.NumericEditField
        entry_y_axis_min                matlab.ui.control.NumericEditField
        button_rmv_spikes               matlab.ui.control.StateButton
        label_y_axis_min                matlab.ui.control.Label
        label_y_axis_max                matlab.ui.control.Label
        plot_scatter                    matlab.ui.control.UIAxes
        panel_tt_scatter_settings       matlab.ui.container.Panel
        checkbox_drift_correction       matlab.ui.control.CheckBox
        checkbox_show_drift_correction  matlab.ui.control.CheckBox
        plot_tt                         matlab.ui.control.UIAxes
        slider_frame                    matlab.ui.control.Slider
        label_time                      matlab.ui.control.Label
        field_time                      matlab.ui.control.NumericEditField
        label_frame_spinner             matlab.ui.control.Label
        spinner_frame                   matlab.ui.control.Spinner
    end

    properties (Access = private)
        
        name_timetrace
        name_hsm
        tt_to_plot
        rois
        valid_tt
        valid_hsm
        contrast_info
    end
    
    properties (Access = public)
        parent_app
    end
    
    
    methods (Access = private)
        function UpdateSingleValues(app, roi_number)
            % updates the switch depending on the new particle
            % ----------------------
            % set switch
            app.switch_single.Value = app.parent_app.file_results.(app.rois{roi_number}).SingleParticle;
            % set lamp
            if app.switch_single.Value == 1
                app.lamp_single.Color = 'Green';
            else
                app.lamp_single.Color = 'Red';
            end
        end
        
        function UpdateWithNewParticle(app, roi_number)
            % updates everything when a new particle is selected
            % ----------------------
            % set switches
            UpdateSingleValues(app, roi_number)
            
            if app.valid_tt
                UpdateTT(app, roi_number)
            end
            
            if app.valid_hsm
                UpdateHSM(app, roi_number)
            end            
            
            UpdateFOVTT(app, roi_number)
            UpdateFOVHSM(app, roi_number)
            
        end
        
        function UpdateHSM(app, roi_number)
            % update everything related to HSM
            % ----------------------
            DetermineSingleParticlesStandardSettings(app)
            UpdateHSMFrameSlider(app, roi_number)
            UpdateFOVHSM(app, roi_number)
            UpdateHSMPlot(app, roi_number, [])
            UpdateHSMFitData(app, roi_number)
            % if there is a valid TT, also contrast
            if app.valid_hsm && app.valid_tt
                UpdateTTContrast(app, roi_number, false)
            end
        end
        
        function UpdateHSMFrameSlider(app, roi_number)
            % updates the HSM Frame slider whenever needed
            % ----------------------
            app.slider_hsm_frame.MajorTicks = 1:length(app.parent_app.file_results.(app.rois{roi_number}).(app.name_hsm).wavelengths);
            app.slider_hsm_frame.MinorTicks = [];
            app.slider_hsm_frame.FontSize = 9;
            app.slider_hsm_frame.MajorTickLabels = cellstr(num2str(app.parent_app.file_results.(app.rois{roi_number}).(app.name_hsm).wavelengths(:)))';
            app.slider_hsm_frame.Limits = [ 1 length(app.parent_app.file_results.(app.rois{roi_number}).(app.name_hsm).wavelengths)  ];
            app.slider_hsm_frame.Value = round(length(app.parent_app.file_results.(app.rois{roi_number}).(app.name_hsm).wavelengths)/2);
        end
        
        function UpdateHSMPlot(app, roi_number, custom_fit)
            % Creates / Updates the spectrum plot of a ROI
            % ----------------------
            % Particle spectra plot
            cla(app.plot_spectra)
            if isfield(app.parent_app.file_results.(app.rois{roi_number}), app.name_hsm)
                % convert wavelengths to double
                app.parent_app.file_results.(app.rois{roi_number}).(app.name_hsm).wavelengths = double(app.parent_app.file_results.(app.rois{roi_number}).(app.name_hsm).wavelengths);
                % plot
                wavelength_ev = linspace(1240/(min(app.parent_app.file_results.(app.rois{roi_number}).(app.name_hsm).wavelengths)-30), 1240/(max(app.parent_app.file_results.(app.rois{roi_number}).(app.name_hsm).wavelengths)+30),100);
                hold(app.plot_spectra,'on')
                plot(app.plot_spectra, app.parent_app.file_results.(app.rois{roi_number}).(app.name_hsm).wavelengths, app.parent_app.file_results.(app.rois{roi_number}).(app.name_hsm).intensity, 'ro');
                if ~isnan(app.parent_app.file_results.(app.rois{roi_number}).(app.name_hsm).lambda) && isempty(custom_fit)
                    % if no custom fit, and fit exists
                    plot(app.plot_spectra, 1240./wavelength_ev, LorentzFunction(app.parent_app.file_results.(app.rois{roi_number}).(app.name_hsm).fit_parameters, wavelength_ev),'r-')
                    app.plot_spectra.XLim = [min(app.parent_app.file_results.(app.rois{roi_number}).(app.name_hsm).wavelengths)-30 max(app.parent_app.file_results.(app.rois{roi_number}).(app.name_hsm).wavelengths)+30 ];
                    % set labels
                    app.plot_spectra_label_SP_lambda.Text = ['λ = ' num2str(round(app.parent_app.file_results.(app.rois{roi_number}).(app.name_hsm).lambda, 1)) ' nm'];
                    app.plot_spectra_label_SP_gamma.Text = ['Γ = ' num2str(round(app.parent_app.file_results.(app.rois{roi_number}).(app.name_hsm).linewidth, 1)) ' meV'];
                    app.plot_spectra_label_SP_r2.Text = ['R^2 = ' num2str(round(app.parent_app.file_results.(app.rois{roi_number}).(app.name_hsm).R2, 2))];
                elseif ~isempty(custom_fit)
                    % if custom fit
                    plot(app.plot_spectra, 1240./wavelength_ev, LorentzFunction(custom_fit(1:4), wavelength_ev),'r-')
                    app.plot_spectra.XLim = [min(app.parent_app.file_results.(app.rois{roi_number}).(app.name_hsm).wavelengths)-30 max(app.parent_app.file_results.(app.rois{roi_number}).(app.name_hsm).wavelengths)+30 ];
                    % set labels
                    app.plot_spectra_label_SP_lambda.Text = ['λ = ' num2str(round(1240 / custom_fit(3), 1)) ' nm'];
                    app.plot_spectra_label_SP_gamma.Text = ['Γ = ' num2str(round(1000 * custom_fit(4), 1)) ' meV'];
                    app.plot_spectra_label_SP_r2.Text = ['R^2 = ' num2str(round(custom_fit(5), 2))];
                else
                    % if nothing at all
                    app.plot_spectra_label_SP_lambda.Text = 'No data';
                    app.plot_spectra_label_SP_gamma.Text = 'No data';
                    app.plot_spectra_label_SP_r2.Text = 'No data';
                end
                
                
            else
                app.plot_spectra_label_SP_lambda.Text = 'No data';
                app.plot_spectra_label_SP_gamma.Text = 'No data';
                app.plot_spectra_label_SP_r2.Text = 'No data';
                
            end
        end
        
        function UpdateHSMFitData(app, roi_number)
            % updates the HSM self fit fields whenever needed
            % ----------------------
            if app.valid_hsm && isfield(app.parent_app.file_results.(app.rois{roi_number}), app.name_hsm) && ~isnan(app.parent_app.file_results.(app.rois{roi_number}).(app.name_hsm).fit_parameters(1))
                app.manual_fit_par1.Value = app.parent_app.file_results.(app.rois{roi_number}).(app.name_hsm).fit_parameters(1);
                app.manual_fit_par2.Value = app.parent_app.file_results.(app.rois{roi_number}).(app.name_hsm).fit_parameters(2);
                app.manual_fit_par3.Value = app.parent_app.file_results.(app.rois{roi_number}).(app.name_hsm).fit_parameters(3);
                app.manual_fit_par4.Value = app.parent_app.file_results.(app.rois{roi_number}).(app.name_hsm).fit_parameters(4);
                app.manual_fit_datapoints.Value = num2str(1:length(app.parent_app.file_results.(app.rois{roi_number}).(app.name_hsm).wavelengths),'%1.0d ');
            else
                app.manual_fit_par1.Value = 0;
                app.manual_fit_par2.Value = 0;
                app.manual_fit_par3.Value = 0;
                app.manual_fit_par4.Value = 0;
                app.manual_fit_datapoints.Value = '';
                
                
            end
        end
        
        function ManualHSMFit(app, roi_number, save)
            % Function that allows you to do manual fits of HSMs
            % ----------------------
            
            % get input
            initial_guess = [ app.manual_fit_par1.Value app.manual_fit_par2.Value app.manual_fit_par3.Value app.manual_fit_par4.Value ];
            datapoints = str2num(app.manual_fit_datapoints.Value);
            % remove nans
            x_axis = 1240./app.parent_app.file_results.(app.rois{roi_number}).(app.name_hsm).wavelengths(datapoints);
            y_axis = app.parent_app.file_results.(app.rois{roi_number}).(app.name_hsm).intensity(datapoints);
            x_axis = x_axis(~isnan(y_axis));
            y_axis = y_axis(~isnan(y_axis));
            
            % fit
            options = optimoptions('lsqcurvefit','MaxIter',5000,'Display','off','Algorithm', 'levenberg-marquardt'); % do not show output of lsqcurvefit
            [fit_result, ~, ~, ~ ] = lsqcurvefit( @LorentzFunction, initial_guess, x_axis, y_axis, [], [], options );
            
            % post process, get R^2
            fit_result(4) = abs(fit_result(4));  % sometimes lsqcurvefit finds a negative linewidth. This is the correction.
            fit_result = [fit_result rsquare(y_axis,LorentzFunction(fit_result,x_axis))]; % determines the R2 (coefficient of determination) of the fit
            
            % update plot
            UpdateHSMPlot(app, roi_number, fit_result)
            
            % if save is true, save
            if save
                app.parent_app.file_results.(app.rois{roi_number}).(app.name_hsm).lambda = 1240 / fit_result(3);
                app.parent_app.file_results.(app.rois{roi_number}).(app.name_hsm).linewidth = 1000 * fit_result(4);
                app.parent_app.file_results.(app.rois{roi_number}).(app.name_hsm).fit_parameters = fit_result(1:4);
                app.parent_app.file_results.(app.rois{roi_number}).(app.name_hsm).R2 = fit_result(5) ;
                
            end
        end
        
        function UpdateTT(app, roi_number)
            % update everything related to TT
            % ----------------------
            UpdateTTPlot(app, roi_number)
            UpdateTTScatter(app, roi_number)
            
            % if there is a valid HSM, also contrast
            if app.valid_tt && app.valid_hsm
                UpdateTTContrast(app, roi_number, false)
            end
        end
        
        function UpdateFOVTT(app, roi_number)
            % Creates / Updates the particle view
            % ----------------------
            % plot ROI
            if app.valid_tt && isfield(app.parent_app.file_results.(app.rois{roi_number}), app.name_timetrace)
                imagesc(app.plot_tt_fov, app.parent_app.file_results.(app.rois{roi_number}).(app.name_timetrace).raw(:,:, app.spinner_frame.Value))
                app.plot_tt_fov.XLim = [0.5 size(app.parent_app.file_results.(app.rois{roi_number}).(app.name_timetrace).raw, 1)+0.5];
                app.plot_tt_fov.YLim = [0.5 size(app.parent_app.file_results.(app.rois{roi_number}).(app.name_timetrace).raw, 2)+0.5];
                colormap(app.plot_tt_fov, "jet");
            else
                % clear it if not possible
                cla(app.plot_tt_fov);
            end
        end
        
        function UpdateFOVHSM(app, roi_number)
            % Creates / Updates the particle view
            % ----------------------
            % plot ROI
            if app.valid_hsm && isfield(app.parent_app.file_results.(app.rois{roi_number}), app.name_hsm)
                imagesc(app.plot_hsm_fov, app.parent_app.file_results.(app.rois{roi_number}).(app.name_hsm).raw(:, :, app.slider_hsm_frame.Value))
                app.plot_hsm_fov.XLim = [0.5 size(app.parent_app.file_results.(app.rois{roi_number}).(app.name_hsm).raw, 1)+0.5];
                app.plot_hsm_fov.YLim = [0.5 size(app.parent_app.file_results.(app.rois{roi_number}).(app.name_hsm).raw, 2)+0.5];
                colormap(app.plot_hsm_fov, "jet");
                colorbar(app.plot_hsm_fov);
            else
                % clear it if not possible
                cla(app.plot_hsm_fov);
            end
        end
        
        function UpdateTTPlot(app, roi_number)
            % Creates / Updates the intensity plot of a TT
            % ----------------------
            
            if isfield(app.parent_app.file_results.(app.rois{roi_number}), app.name_timetrace)
                try
                    app.tt_to_plot = app.parent_app.file_results.(app.rois{roi_number}).(app.name_timetrace).result(:, 4);
                catch
                    % if this row does not exist, you cannot plot it
                    cla(app.plot_tt);
                    return;
                end
                
                % do outlier rejection if desired
                if app.button_rmv_spikes.Value
                    app.tt_to_plot = hampel(app.tt_to_plot, 5);
                end
                
                % normalize if desired
                if app.button_normalize.Value
                    ref = nanmean(app.tt_to_plot);
                    app.tt_to_plot = app.tt_to_plot ./ ref;
                end
                
                % Update values / slider / spinnners
                UpdateTTDataset(app, length(app.tt_to_plot))
                
                % updating time
                time_axis = app.parent_app.file_results.(app.rois{roi_number}).(app.name_timetrace).time_axis;
                if strcmp(app.parent_app.file_results.(app.rois{roi_number}).(app.name_timetrace).time_axis_dim, 't')
                    app.field_time.Value = time_axis(app.spinner_frame.Value);
                    app.plot_tt.XLabel.String = 'Time (s)';
                else
                    app.field_time.Value = 'No time';
                    app.plot_tt.XLabel.String = 'Frames';
                end
                
                % Plotting the timetrace
                cla(app.plot_tt)
                hold(app.plot_tt,'on')
                plot(app.plot_tt, time_axis(1:app.spinner_frame.Value), app.tt_to_plot(1:app.spinner_frame.Value),'-','color', [1 0 0]);
                plot(app.plot_tt, time_axis(app.spinner_frame.Value:end), app.tt_to_plot(app.spinner_frame.Value:end),'-','color', [1 0.5 0.5]);
                app.plot_tt.Title.String = app.name_timetrace;
                set(app.plot_tt.Title,'Interpreter','none');
                app.plot_tt.XLim = [time_axis(1)  time_axis(end)];
                
                % set y limits
                if app.button_manual_y_axis.Value == 1
                    app.plot_tt.YLim = [app.entry_y_axis_min.Value  app.entry_y_axis_max.Value ];
                else
                    app.plot_tt.YLim = [-Inf Inf];
                end
            else
                cla(app.plot_tt);
            end
        end
        
        function UpdateTTScatter(app, roi_number)
            % Creates / Updates the scatter of the fits
            % ----------------------
            % Check if drift correction or show drift correction desired
            try
                if app.checkbox_drift_correction.Value
                    to_plot_x = app.parent_app.file_results.(app.rois{roi_number}).(app.name_timetrace).result_post_drift(:, 2);
                    to_plot_y = app.parent_app.file_results.(app.rois{roi_number}).(app.name_timetrace).result_post_drift(:, 3);
                elseif app.checkbox_show_drift_correction.Value
                    to_plot_x = app.parent_app.file_results.(app.rois{roi_number}).(app.name_timetrace).drift(:, 1);
                    to_plot_y = app.parent_app.file_results.(app.rois{roi_number}).(app.name_timetrace).drift(:, 2);
                else
                    to_plot_x = app.parent_app.file_results.(app.rois{roi_number}).(app.name_timetrace).result(:, 2);
                    to_plot_y = app.parent_app.file_results.(app.rois{roi_number}).(app.name_timetrace).result(:, 3);
                end
                
                scatter(app.plot_scatter,to_plot_x,to_plot_y,'r.');
                % set axis labels
                if strcmp(app.parent_app.file_results.(app.rois{roi_number}).(app.name_timetrace).dimension, 'nm')
                    app.plot_scatter.XLabel.String = '(nm)';
                else
                    app.plot_scatter.XLabel.String = '(pixels)';
                end
            catch
                cla(app.plot_scatter);
            end
            
        end
        
        function UpdateTTContrast(app, roi_number, to_save)
            % Creates / Updates the contrast figure
            % ----------------------
            
            % find contrast
            contrast = zeros(1, length(app.rois));
            wavelength = zeros(1, length(app.rois));
            indices = 1:length(app.rois);
            
            for k=1:numel(app.rois)
                if app.button_show_all.Value || (app.button_show_single.Value && app.parent_app.file_results.(app.rois{k}).SingleParticle)
                    if isfield(app.parent_app.file_results.(app.rois{k}), app.name_timetrace) && isfield(app.parent_app.file_results.(app.rois{k}), app.name_hsm)
                        tt_intensity = app.parent_app.file_results.(app.rois{k}).(app.name_timetrace).result(:, 4);
                        % if compenstae for spikes
                        if app.button_rmv_spikes.Value
                            % find signal
                            if app.entry_contrast_frame.Value < 6
                                sig = nanmean(hampel(tt_intensity(1:10),5));
                            elseif app.entry_contrast_frame.Value > length(tt_intensity)-6
                                sig = nanmean(hampel(tt_intensity(end-10:end),5));
                            else
                                sig = nanmean(hampel(tt_intensity(app.entry_contrast_frame.Value-5:app.entry_contrast_frame.Value+5),5));
                            end
                            ref = nanmean(hampel(tt_intensity(3:app.entry_ref_frame.Value),5)); %reference value for the contrast
                        else
                            % find signal
                            if app.entry_contrast_frame.Value < 6
                                sig = nanmean(tt_intensity(1:10));
                            elseif app.entry_contrast_frame.Value > length(tt_intensity)-6
                                sig = nanmean(tt_intensity(end-10:end));
                            else
                                sig = nanmean(tt_intensity(app.entry_contrast_frame.Value-5:app.entry_contrast_frame.Value+5));
                            end
                            ref = nanmean(tt_intensity(3:app.entry_ref_frame.Value)); %reference value for the contrast
                        end
                        
                        contrast(k) = (sig./ref - 1);
                        wavelength(k) = app.parent_app.file_results.(app.rois{k}).(app.name_hsm).lambda;
                        
                        if k==roi_number
                            active_contrast = sig./ref - 1;
                            active_wavelength = app.parent_app.file_results.(app.rois{k}).(app.name_hsm).lambda;
                        end
                    end
                end
                
            end
            
            % if wavelength is filled with anything else than zeros
            if size(wavelength(wavelength~=0), 1) > 0
                % Contrast additional calculation and plotting
                % remove bad values
                contrast = contrast(wavelength~=0);
                indices = indices(wavelength~=0);
                wavelength = wavelength(wavelength~=0);
                wavelength = wavelength( abs(contrast)<2.5 );
                indices = indices(abs(contrast)<2.5);
                contrast = contrast( abs(contrast)<2.5 );
                app.contrast_info.wavelength = wavelength;
                app.contrast_info.contrast = contrast;
                app.contrast_info.indices = indices;
                
                % get mean and sigma
                wavelength_mu = nanmean(wavelength);
                wavelength_sigma = nanstd(wavelength);
                
                cla(app.plot_contrast);
                hold(app.plot_contrast,'on');
                scatter(app.plot_contrast,wavelength,contrast,'bo', 'Tag', 'allData');
                if exist('active_contrast','var')
                    scatter(app.plot_contrast,active_wavelength,active_contrast,'ro', 'filled');
                end
                
                app.plot_contrast.XLim = [round(wavelength_mu-3*wavelength_sigma) round(wavelength_mu+3*wavelength_sigma)];
                
                % if manual y_axis
                if app.button_manual_y_axis.Value
                    app.plot_contrast.YLim = [app.entry_y_axis_min.Value-1  app.entry_y_axis_max.Value-1 ];
                else
                    % calculate ylim
                    if nanmax(abs(contrast)) == 0
                        % only happens as fail state
                        app.plot_contrast.YLim = [-Inf Inf];
                        lim_y = app.plot_contrast.YLim;
                    elseif -nanmax(contrast) < nanmax(contrast)
                        % if maximum is positive
                        app.plot_contrast.YLim = [-nanmax(contrast)*1.1  nanmax(contrast)*1.1];
                        lim_y = [-nanmax(contrast)*1.1  nanmax(contrast)*1.1];
                    else
                        % if maximum is negative
                        app.plot_contrast.YLim = [nanmax(contrast)*1.1  -nanmax(contrast)*1.1];
                        lim_y = [nanmax(contrast)*1.1  -nanmax(contrast)*1.1];
                    end
                end
            else
                cla(app.plot_contrast);
            end
            
            if to_save
                time = app.parent_app.file_metadata.(['meta_' app.name_timetrace(5:end)]).timesteps(app.entry_contrast_frame.Value);
                TTPageSaveContrast(wavelength, contrast, app.parent_app.file_dir, app.name_timetrace, app.entry_contrast_frame.Value, time, lim_y)
            end
        end
        
        function UpdateTTDataset(app, length_tt)
            % set limit of frame slider, spinner, entry contrast, and
            % min/max y-axis entries
            % ----------------------
            app.slider_frame.Limits = [1, length_tt];
            app.spinner_frame.Limits = [1, length_tt];
            app.entry_contrast_frame.Limits = [1, length_tt];
            app.entry_ref_frame.Limits = [1, length_tt];
            app.entry_contrast_frame.Value = length_tt;
            if 200 > length_tt
                app.entry_ref_frame.Value = length_tt;
            else
                app.entry_ref_frame.Value = 200;
            end
            app.entry_y_axis_max.Limits = [app.entry_y_axis_min.Value Inf];
            app.entry_y_axis_min.Limits = [-Inf app.entry_y_axis_max.Value];
        end
        
        function CheckSingleParticles(app)
            % check if single particles present, otherwise
            % turn of buttons
            % ----------------------
            single_counter = 0;
            for k=1:numel(app.rois)
                single_counter = single_counter + app.parent_app.file_results.(app.rois{k}).SingleParticle;
            end
            if single_counter > 0
                app.button_show_single.Enable = 'on';
            else
                app.button_show_single.Enable = 'off';
            end
        end
        
        function UpdateSwitch(app, roi_number)
            % Updates the switch and its lights for a roi number
            % ----------------------
            % change single value and light
            app.switch_single.Value = app.parent_app.file_results.(app.rois{roi_number}).SingleParticle;
            if app.switch_single.Value
                app.lamp_single.Color = 'Green';
            else
                app.lamp_single.Color = 'Red';
            end
        end
        
        function DetermineSingleParticlesStandardSettings(app)
            % Determines the standard settings for single particles. Different
            % per dataset
            % ----------------------
            if app.valid_hsm
                % find the wavelengths used from a valid partilce
                for k=1:numel(app.rois)
                    if isfield(app.parent_app.file_results.(app.rois{k}), app.name_hsm)
                        wavelengths = app.parent_app.file_results.(app.rois{k}).(app.name_hsm).wavelengths;
                        break;
                    end
                end
                % check all lambdas and linewidths
                lambdas = zeros(1, length(app.rois));
                linewidths = zeros(1, length(app.rois));
                for k=1:numel(app.rois)
                    if isfield(app.parent_app.file_results.(app.rois{k}), app.name_hsm)
                        lambdas(k) = app.parent_app.file_results.(app.rois{k}).(app.name_hsm).lambda;
                        if app.parent_app.file_results.(app.rois{k}).(app.name_hsm).linewidth < 500
                            linewidths(k) = app.parent_app.file_results.(app.rois{k}).(app.name_hsm).linewidth;
                        end
                    end
                end
                % filter to ensure within range of wavelengths used
                lambdas = lambdas(lambdas > wavelengths(1) & lambdas < wavelengths(end));
                linewidths = linewidths(lambdas > wavelengths(1) & lambdas < wavelengths(end));
                linewidths = linewidths(linewidths ~= 0);
                linewidths = linewidths(~isnan(linewidths));
                
                [linewidth_mean,linewidth_sigma] = normfit(linewidths);
                
                app.button_single_particles.Enable = 'on';
                app.entry_lambda_min.Value = double(wavelengths(1) + 5);
                app.entry_lambda_max.Value = double(wavelengths(end) - 5);
                app.entry_linewidth_max.Value = linewidth_mean+1.2*linewidth_sigma;
                app.entry_linewidth_min.Value = linewidth_mean-1.2*linewidth_sigma;
                app.entry_r2_min.Value = 0.9;
            else
                % if no HSM, disable
                app.button_single_particles.Enable = 'off';
                app.entry_lambda_min.Value = 0;
                app.entry_lambda_max.Value = 0;
                app.entry_linewidth_max.Value = 0;
                app.entry_linewidth_min.Value = 0;
                app.entry_r2_min.Value = 0;
            end
            
        end
        
        function DetermineSingleParticles(app)
            % Find the single particles based on the settings. Triggered when
            % loading on click on button
            if app.valid_hsm
                for k=1:numel(app.rois)
                    if isfield(app.parent_app.file_results.(app.rois{k}), app.name_hsm)
                        %tmp copy
                        lambda = app.parent_app.file_results.(app.rois{k}).(app.name_hsm).lambda;
                        lw = app.parent_app.file_results.(app.rois{k}).(app.name_hsm).linewidth;
                        r2 = app.parent_app.file_results.(app.rois{k}).(app.name_hsm).R2;
                        % determine single or not
                        if lambda > app.entry_lambda_min.Value && lambda < app.entry_lambda_max.Value && lw > app.entry_linewidth_min.Value && lw < app.entry_linewidth_max.Value && r2 > app.entry_r2_min.Value
                            app.parent_app.file_results.(app.rois{k}).SingleParticle = 1;
                        else
                            app.parent_app.file_results.(app.rois{k}).SingleParticle = 0;
                        end
                    else
                        % if not in HSM, not single
                        app.parent_app.file_results.(app.rois{k}).SingleParticle = 0;
                    end
                    
                end
            else
                % if no HSM, all single
                for k=1:numel(app.rois)
                    app.parent_app.file_results.(app.rois{k}).SingleParticle = 1;
                end
            end
        end
        
        function ClickOnContrast(app, event)
            % Function that is called when you click on contrast plot
            % ----------------------
            pos_plot_contrast = app.plot_contrast.InnerPosition;
            pos_click = event.Source.CurrentPoint;
            % check if you clicked within contrast
            if pos_click(1) > pos_plot_contrast(1) && pos_click(1) < pos_plot_contrast(1) + pos_plot_contrast(3) && pos_click(2) > pos_plot_contrast(2) && pos_click(2) < pos_plot_contrast(2) + pos_plot_contrast(4)
                % get xy click
                pos_click_fig = pos_click;
                pos_click_fig(1) = (pos_click_fig(1) - pos_plot_contrast(1)) / pos_plot_contrast(3);
                pos_click_fig(2) = (pos_click_fig(2) - pos_plot_contrast(2)) / pos_plot_contrast(4);
                
                % get all datapoints from fig
                xy_data = cat(1, app.contrast_info.wavelength, app.contrast_info.contrast);
                % normalize to axis
                xlim = app.plot_contrast.XLim;
                ylim = app.plot_contrast.YLim;
                xy_data(1,:) = (xy_data(1,:) - xlim(1)) / (xlim(2) - xlim(1));
                xy_data(2,:) = (xy_data(2,:) - ylim(1)) / (ylim(2) - ylim(1));
                
                % find closest
                dist = sqrt(sum((xy_data - pos_click_fig').^2));
                [~, min_index] = nanmin(dist);
                
                % select new particle
                app.spinner_particle.Value = app.contrast_info.indices(min_index);
                
                % Update particle
                UpdateSwitch(app, app.spinner_particle.Value)
                % Update dataset if need be
                if app.valid_tt
                    UpdateTT(app, app.spinner_particle.Value)
                end
                if app.valid_hsm
                    UpdateHSM(app, app.spinner_particle.Value)
                end
                % update particle views
                UpdateFOVTT(app, app.spinner_particle.Value)
                UpdateFOVHSM(app, app.spinner_particle.Value)
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            % Start up
            % ----------------------
            app.parent_app = mainapp;
            clear mainapp;
            app.lamp_tt.Color = 'Red';
            app.lamp_spectra.Color = 'Red';
            app.rois = fieldnames(app.parent_app.file_results);
            
            % set dropdowns
            app.drop_tt.Items = app.parent_app.tts;
            app.drop_spectra.Items = app.parent_app.hsms;
            
            % if cannot set dropdown, this means that there is no valid
            % TT/HSM present, will skip steps accordingly
            try
                app.name_timetrace = app.drop_tt.Items{1};
                app.valid_tt = true;
            catch
                app.name_timetrace = '';
                app.valid_tt = false;
            end
            try
                app.name_hsm = app.drop_spectra.Items{1};
                app.valid_hsm =  true;
            catch
                app.name_hsm = '';
                app.valid_hsm = false;
            end
            
            % couple click to contrast plot
            app.UIFigure.WindowButtonDownFcn = createCallbackFcn(app, @ClickOnContrast, true);
            
            % if SingleParticle does not exist, create it
            % (and set all single particles to 1)
            if ~isfield(app.parent_app.file_results.(app.rois{1}), 'SingleParticle')
                DetermineSingleParticlesStandardSettings(app)
                DetermineSingleParticles(app)
            end
            
            % set switches and buttons
            app.switch_single.ItemsData = [ 0 , 1 ];
            set(app.button_show_all, 'Enable','on')
            app.spinner_particle.Limits = [1 length(app.rois)];
            
            % check if single particles present, otherwise turn of buttons
            CheckSingleParticles(app)
            UpdateSwitch(app, app.spinner_particle.Value)
            
            % update everything to show particle 1
            UpdateWithNewParticle(app, 1)
            
            % set green
            if app.valid_tt
                app.lamp_tt.Color = 'Green';
            end
            if app.valid_hsm
                app.lamp_spectra.Color = 'Green';
            end
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % Function to close window
            % ----------------------
            try
                CloseChildApp(app.parent_app);
                try
                    app.delete;
                catch
                    
                end
            catch
                app.delete;
            end
        end

        % Value changed function: drop_tt
        function DropTTChanged(app, event)
            % Function to change value of TT dropdown
            % ----------------------
            % Load new TT
            app.lamp_tt.Color = 'Yellow';
            app.name_timetrace = app.drop_tt.Value;
            % Change the plots
            if app.valid_tt
                UpdateTT(app, app.spinner_particle.Value)
            end
            app.lamp_tt.Color = 'Green';
        end

        % Value changed function: drop_spectra
        function DropSpectraChanged(app, event)
            % Function to change value of HSM dropdown
            % ----------------------
            app.lamp_spectra.Color = 'Yellow';
            app.name_hsm = app.drop_spectra.Value;
            app.lamp_spectra.Color = 'Green';
            %Change the plots
            if app.valid_hsm
                UpdateHSM(app, app.spinner_particle.Value)
            end
        end

        % Value changed function: spinner_particle
        function SpinnerParticleChanged(app, event)
            % Function that is triggered when new particle is selected
            % ----------------------
            % Update particle
            UpdateSwitch(app, app.spinner_particle.Value)
            % Update dataset if need be
            if app.valid_tt
                UpdateTT(app, app.spinner_particle.Value)
            end
            if app.valid_hsm
                UpdateHSM(app, app.spinner_particle.Value)
            end
            % update particle views
            UpdateFOVTT(app, app.spinner_particle.Value)
            UpdateFOVHSM(app, app.spinner_particle.Value)
        end

        % Value changed function: entry_ref_frame
        function TTFrame(app, event)
            % Function triggered when reference frame is changed
            % ----------------------
            if app.valid_tt && app.valid_hsm
                UpdateTTContrast(app, app.spinner_particle.Value)
            end
        end

        % Value changed function: button_normalize
        function TTNormalize(app, event)
            % Function triggered when normalize is clicked
            % ----------------------
            if app.valid_tt
                UpdateTTPlot(app, app.spinner_particle.Value)
            end
        end

        % Value changing function: slider_frame
        function SliderFrameChanging(app, event)
            % Function triggered when TT frame slider is moved
            % ----------------------
            % round value and update spinnner
            app.slider_frame.Value = round(event.Value);
            app.spinner_frame.Value = app.slider_frame.Value;
            
            if app.valid_tt
                UpdateTTPlot(app, app.spinner_particle.Value)
                UpdateFOVTT(app, app.spinner_particle.Value)
            end
        end

        % Value changed function: button_rmv_spikes
        function TTRMVSpikes(app, event)
            % Function triggered when remove spikes is clicked
            % ----------------------
            if app.valid_tt
                UpdateTTPlot(app, app.spinner_particle.Value)
                if app.valid_hsm
                    UpdateTTContrast(app, app.spinner_particle.Value, false)
                end
            end
        end

        % Value changed function: switch_single
        function SwitchSingleChanged(app, event)
            % Function triggered when particle status (single) is changed
            % ----------------------
            % Change value and light
            app.parent_app.file_results.(app.rois{app.spinner_particle.Value}).SingleParticle = app.switch_single.Value;
            if app.switch_single.Value
                app.lamp_single.Color = 'Green';
            else
                app.lamp_single.Color = 'Red';
            end
            % check again if there are still single particles
            % left
            CheckSingleParticles(app)
            
            % update contrast plot if only single are shown
            if app.valid_hsm && app.valid_tt && app.button_show_single.Value
                UpdateTTContrast(app, app.spinner_particle.Value, false)
            end
        end

        % Value changed function: spinner_frame
        function SpinnerFrameChanged(app, event)
            % Function triggered when TT frame spinner is changed
            % ----------------------
            % round value and update slider
            app.spinner_frame.Value = round(event.Value);
            app.slider_frame.Value = app.spinner_frame.Value;
            
            if app.valid_tt
                UpdateTTPlot(app, app.spinner_particle.Value)
                UpdateFOVTT(app, app.spinner_particle.Value)
            end
            
        end

        % Button pushed function: button_contrast
        function ActionsContrast(app, event)
            % Function triggered when saving contrast figure is asked
            % ----------------------
            UpdateTTContrast(app, app.spinner_particle.Value, true)
        end

        % Button pushed function: button_save_particle
        function ActionsSaveParticle(app, event)
            % Function triggered when saving individual particle is asked
            % ----------------------
            TTPageSaveParticle(app.parent_app.file_dir, app.parent_app.file_results.(app.rois{app.spinner_particle.Value}), app.valid_tt, app.name_timetrace, app.valid_hsm, app.name_hsm, app.parent_app.file_metadata)
        end

        % Button pushed function: button_save_all
        function ActionSaveAll(app, event)
            % Function triggered when saving all particles is asked
            % ----------------------
            for k=1:numel(app.rois)
                TTPageSaveParticle(app.parent_app.file_dir, app.parent_app.file_results.(app.rois{k}), app.valid_tt, app.name_timetrace, app.valid_hsm, app.name_hsm, app.parent_app.file_metadata)
            end
        end

        % Selection changed function: panel_show_particles
        function ParticleSelectionChanged(app, event)
            % Function triggered when asked to use different particle to make images
            % ----------------------
            if app.valid_tt && app.valid_hsm
                UpdateTTContrast(app, app.spinner_particle.Value, false)
            end
        end

        % Value changed function: button_manual_y_axis
        function TTManualAxis(app, event)
            % Function triggered when asked to use manual axis
            % ----------------------
            if app.valid_tt
                UpdateTTPlot(app, app.spinner_particle.Value)
                if app.valid_hsm
                    UpdateTTContrast(app, app.spinner_particle.Value, false)
                end
            end
        end

        % Value changed function: checkbox_show_drift_correction
        function DriftCorrectionShowCheckbox(app, event)
            % Function triggered when you want to see the drift correction
            % ---------------------
            if app.checkbox_drift_correction.Value
                app.checkbox_show_drift_correction.Value = 0;
                warndlg('You only apply drift correction or show drift correction, not at the same time!','!! Warning !!')
            end
            if app.valid_tt
                UpdateTTScatter(app, app.spinner_particle.Value)
            end
        end

        % Value changed function: entry_y_axis_min
        function TTYMinChanged(app, event)
            % Function triggered when you change the min_y for manual y-axis
            % ---------------------
            app.entry_y_axis_max.Limits = [app.entry_y_axis_min.Value Inf];
            if app.valid_tt
                UpdateTTPlot(app, app.spinner_particle.Value)
                if app.valid_hsm
                    UpdateTTContrast(app, app.spinner_particle.Value, false)
                end
            end
        end

        % Value changed function: entry_y_axis_max
        function TTYMaxChanged(app, event)
            % Function triggered when you change the max_y for manual y-axis
            % ---------------------
            app.entry_y_axis_min.Limits = [-Inf app.entry_y_axis_max.Value];
            if app.valid_tt
                UpdateTTPlot(app, app.spinner_particle.Value)
                if app.valid_hsm
                    UpdateTTContrast(app, app.spinner_particle.Value, false)
                end
            end
        end

        % Value changed function: checkbox_drift_correction
        function DriftCorrectionCheckbox(app, event)
            % Function triggered when you want to see the drift corrected
            % results
            % ---------------------
            if app.checkbox_show_drift_correction.Value
                app.checkbox_drift_correction.Value = 0;
                warndlg('You only apply drift correction or show drift correction, not at the same time!','!! Warning !!')
            end
            
            if app.valid_tt
                UpdateTTScatter(app, app.spinner_particle.Value)
            end
        end

        % Value changed function: entry_contrast_frame
        function ContrastReferenceFrameChanged(app, event)
            % Function triggered when you change the contrast reference frame
            % ---------------------
            if app.valid_tt && app.valid_hsm
                UpdateTTContrast(app, app.spinner_particle.Value, false)
            end
        end

        % Value changing function: slider_hsm_frame
        function SliderHSMFrameChanging(app, event)
            % Function triggered when you change the HSM frame slider
            % ---------------------
            % update FOV HSM when moving the slider
            app.slider_hsm_frame.Value = round(event.Value);
            UpdateFOVHSM(app, app.spinner_particle.Value)
        end

        % Button pushed function: manual_fit_button
        function DoManualHSMFit(app, event)
            % Function triggered when you click the manual fit button of HSM, generating a manual fit
            % ---------------------
            ManualHSMFit(app, app.spinner_particle.Value, false)
        end

        % Button pushed function: manual_fit_button_save
        function ManualFitSave(app, event)
            % Function triggered when you click the manual fit button of HSM WITH SAVE, generating a manual
            % fit and saving it
            % ---------------------
            ManualHSMFit(app, app.spinner_particle.Value, true)
        end

        % Button pushed function: button_single_particles
        function ButtonDetermineSingleParticls(app, event)
            % Function triggered when you want to determine the single particles
            % ---------------------
            DetermineSingleParticles(app)
            UpdateSingleValues(app, app.spinner_particle.Value)
            % update contrast plot if only single are shown
            if app.valid_hsm && app.valid_tt && app.button_show_single.Value
                UpdateTTContrast(app, app.spinner_particle.Value, false)
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.panel_particle = uipanel(app.UIFigure);
            app.panel_particle.Title = 'Particle';
            app.spinner_particle = uispinner(app.panel_particle);
            app.spinner_particle.Limits = [1 Inf];
            app.spinner_particle.RoundFractionalValues = 'on';
            app.spinner_particle.ValueDisplayFormat = '%.0f';
            app.spinner_particle.ValueChangedFcn = createCallbackFcn(app, @SpinnerParticleChanged, true);
            app.spinner_particle.Value = 1;
            app.switch_single = uiswitch(app.panel_particle, 'slider');
            app.switch_single.Items = {'CLUSTER', 'SINGLE'};
            app.switch_single.ItemsData = {'0', '1'};
            app.switch_single.ValueChangedFcn = createCallbackFcn(app, @SwitchSingleChanged, true);
            app.switch_single.Value = '1';
            app.lamp_single = uilamp(app.panel_particle);
            app.panel_single_particles = uipanel(app.panel_particle);
            app.panel_single_particles.Title = 'Determine single particles';
            app.label_r2 = uilabel(app.panel_single_particles);
            app.label_r2.Text = 'R^2';
            app.button_single_particles = uibutton(app.panel_single_particles, 'push');
            app.button_single_particles.ButtonPushedFcn = createCallbackFcn(app, @ButtonDetermineSingleParticls, true);
            app.button_single_particles.Text = {'Determine single'; ' particles'};
            app.entry_r2_min = uieditfield(app.panel_single_particles, 'numeric');
            app.entry_r2_min.Value = 0.9;
            app.label_r2_min = uilabel(app.panel_single_particles);
            app.label_r2_min.Text = 'Min';
            app.entry_lambda_max = uieditfield(app.panel_single_particles, 'numeric');
            app.entry_lambda_max.Value = 1.1;
            app.entry_lambda_min = uieditfield(app.panel_single_particles, 'numeric');
            app.entry_lambda_min.Value = 0.9;
            app.label_lambda_min = uilabel(app.panel_single_particles);
            app.label_lambda_min.Text = 'Min';
            app.label_lambda_max = uilabel(app.panel_single_particles);
            app.label_lambda_max.Text = 'Max';
            app.label_lambda = uilabel(app.panel_single_particles);
            app.label_lambda.Text = 'Lambda';
            app.entry_linewidth_max = uieditfield(app.panel_single_particles, 'numeric');
            app.entry_linewidth_max.Value = 1.1;
            app.entry_linewidth_min = uieditfield(app.panel_single_particles, 'numeric');
            app.entry_linewidth_min.Value = 0.9;
            app.label_linewidth_min = uilabel(app.panel_single_particles);
            app.label_linewidth_min.Text = 'Min';
            app.label_linewidth_max = uilabel(app.panel_single_particles);
            app.label_linewidth_max.Text = 'Max';
            app.label_linewidth = uilabel(app.panel_single_particles);
            app.label_linewidth.Text = 'Linewidth';
            app.panel_datasets = uipanel(app.UIFigure);
            app.panel_datasets.Title = 'Datasets';
            app.SpectraLabel = uilabel(app.panel_datasets);
            app.SpectraLabel.Text = 'Spectra';
            app.drop_spectra = uidropdown(app.panel_datasets);
            app.drop_spectra.Items = {'', 'Option 2', 'Option 3', 'Option 4'};
            app.drop_spectra.ValueChangedFcn = createCallbackFcn(app, @DropSpectraChanged, true);
            app.drop_spectra.Value = '';
            app.TimetraceDropDownLabel = uilabel(app.panel_datasets);
            app.TimetraceDropDownLabel.Text = 'Timetrace';
            app.drop_tt = uidropdown(app.panel_datasets);
            app.drop_tt.Items = {'', 'Option 2', 'Option 3', 'Option 4'};
            app.drop_tt.ValueChangedFcn = createCallbackFcn(app, @DropTTChanged, true);
            app.drop_tt.Value = '';
            app.lamp_spectra = uilamp(app.panel_datasets);
            app.lamp_tt = uilamp(app.panel_datasets);
            app.plot_contrast = uiaxes(app.UIFigure);
            title(app.plot_contrast, 'Contrast')
            xlabel(app.plot_contrast, 'Wavelength (nm)')
            ylabel(app.plot_contrast, 'Contrast (arb.u.)')
            app.panel_contrast_settings = uipanel(app.UIFigure);
            app.panel_contrast_settings.Title = 'Contrast settings';
            app.panel_show_particles = uibuttongroup(app.panel_contrast_settings);
            app.panel_show_particles.SelectionChangedFcn = createCallbackFcn(app, @ParticleSelectionChanged, true);
            app.panel_show_particles.Title = 'Show particles:';
            app.button_show_all = uitogglebutton(app.panel_show_particles);
            app.button_show_all.Text = 'All';
            app.button_show_all.Value = true;
            app.button_show_single = uitogglebutton(app.panel_show_particles);
            app.button_show_single.Text = 'Single';
            app.ContrastframeLabel = uilabel(app.panel_contrast_settings);
            app.ContrastframeLabel.Text = 'Contrast frame:';
            app.entry_contrast_frame = uieditfield(app.panel_contrast_settings, 'numeric');
            app.entry_contrast_frame.Limits = [0 Inf];
            app.entry_contrast_frame.ValueDisplayFormat = '%.0f';
            app.entry_contrast_frame.ValueChangedFcn = createCallbackFcn(app, @ContrastReferenceFrameChanged, true);
            app.entry_contrast_frame.Value = 6000;
            app.ContrastreferenceframeLabel = uilabel(app.panel_contrast_settings);
            app.ContrastreferenceframeLabel.Text = {'Contrast reference'; 'frame'};
            app.entry_ref_frame = uieditfield(app.panel_contrast_settings, 'numeric');
            app.entry_ref_frame.Limits = [4 Inf];
            app.entry_ref_frame.ValueDisplayFormat = '%.0f';
            app.entry_ref_frame.ValueChangedFcn = createCallbackFcn(app, @TTFrame, true);
            app.entry_ref_frame.Value = 200;
            app.panel_spectrum = uipanel(app.UIFigure);
            app.panel_spectrum.Title = 'Spectrum';
            app.plot_spectra = uiaxes(app.panel_spectrum);
            title(app.plot_spectra, 'Spectrum')
            xlabel(app.plot_spectra, 'Wavelength (nm)')
            ylabel(app.plot_spectra, 'Scat. cross sec. (arb.u.)')
            app.plot_spectra_label_SP_lambda = uilabel(app.panel_spectrum);
            app.plot_spectra_label_SP_gamma = uilabel(app.panel_spectrum);
            app.panel_manual_fit = uipanel(app.panel_spectrum);
            app.panel_manual_fit.Title = 'Manual spectrum fit';
            app.manual_fit_par2 = uieditfield(app.panel_manual_fit, 'numeric');
            app.ParametersEditFieldLabel = uilabel(app.panel_manual_fit);
            app.ParametersEditFieldLabel.Text = 'Parameters';
            app.manual_fit_par1 = uieditfield(app.panel_manual_fit, 'numeric');
            app.manual_fit_par4 = uieditfield(app.panel_manual_fit, 'numeric');
            app.manual_fit_button = uibutton(app.panel_manual_fit, 'push');
            app.manual_fit_button.ButtonPushedFcn = createCallbackFcn(app, @DoManualHSMFit, true);
            app.manual_fit_button.Text = 'Fit';
            app.manual_fit_button_save = uibutton(app.panel_manual_fit, 'push');
            app.manual_fit_button_save.ButtonPushedFcn = createCallbackFcn(app, @ManualFitSave, true);
            app.manual_fit_button_save.Text = 'Save';
            app.DatapointsLabel = uilabel(app.panel_manual_fit);
            app.DatapointsLabel.Text = 'Datapoints';
            app.manual_fit_datapoints = uieditfield(app.panel_manual_fit, 'text');
            app.manual_fit_par3 = uieditfield(app.panel_manual_fit, 'numeric');
            app.plot_hsm_fov = uiaxes(app.panel_spectrum);
            app.HSMFrameLabel = uilabel(app.panel_spectrum);
            app.HSMFrameLabel.Text = 'HSM Frame';
            app.slider_hsm_frame = uislider(app.panel_spectrum);
            app.slider_hsm_frame.MajorTickLabels = {'0', '20', '40', '60', '80', '100'};
            app.slider_hsm_frame.ValueChangingFcn = createCallbackFcn(app, @SliderHSMFrameChanging, true);
            app.slider_hsm_frame.MinorTicks = [];
            app.plot_spectra_label_SP_r2 = uilabel(app.panel_spectrum);
            app.panel_save = uipanel(app.UIFigure);
            app.panel_save.Title = 'Save figures';
            app.button_contrast = uibutton(app.panel_save, 'push');
            app.button_contrast.ButtonPushedFcn = createCallbackFcn(app, @ActionsContrast, true);
            app.button_contrast.Text = 'Contrast';
            app.button_save_particle = uibutton(app.panel_save, 'push');
            app.button_save_particle.ButtonPushedFcn = createCallbackFcn(app, @ActionsSaveParticle, true);
            app.button_save_particle.Text = 'Particle';
            app.button_save_all = uibutton(app.panel_save, 'push');
            app.button_save_all.ButtonPushedFcn = createCallbackFcn(app, @ActionSaveAll, true);
            app.button_save_all.Text = 'All Particles';
            app.panel_tt = uipanel(app.UIFigure);
            app.panel_tt.Title = 'Timetrace';
            app.plot_tt_fov = uiaxes(app.panel_tt);
            app.panel_tt_settings = uipanel(app.panel_tt);
            app.panel_tt_settings.Title = 'TT Settings';
            app.button_normalize = uibutton(app.panel_tt_settings, 'state');
            app.button_normalize.ValueChangedFcn = createCallbackFcn(app, @TTNormalize, true);
            app.button_normalize.Text = 'Normalize';
            app.button_manual_y_axis = uibutton(app.panel_tt_settings, 'state');
            app.button_manual_y_axis.ValueChangedFcn = createCallbackFcn(app, @TTManualAxis, true);
            app.button_manual_y_axis.Text = 'Manual Y axis';
            app.entry_y_axis_max = uieditfield(app.panel_tt_settings, 'numeric');
            app.entry_y_axis_max.ValueChangedFcn = createCallbackFcn(app, @TTYMaxChanged, true);
            app.entry_y_axis_max.Value = 1.1;
            app.entry_y_axis_min = uieditfield(app.panel_tt_settings, 'numeric');
            app.entry_y_axis_min.ValueChangedFcn = createCallbackFcn(app, @TTYMinChanged, true);
            app.entry_y_axis_min.Value = 0.9;
            app.button_rmv_spikes = uibutton(app.panel_tt_settings, 'state');
            app.button_rmv_spikes.ValueChangedFcn = createCallbackFcn(app, @TTRMVSpikes, true);
            app.button_rmv_spikes.Text = 'Remove spikes';
            app.label_y_axis_min = uilabel(app.panel_tt_settings);
            app.label_y_axis_min.Text = 'Min';
            app.label_y_axis_max = uilabel(app.panel_tt_settings);
            app.label_y_axis_max.Text = 'Max';
            app.plot_scatter = uiaxes(app.panel_tt);
            app.panel_tt_scatter_settings = uipanel(app.panel_tt);
            app.panel_tt_scatter_settings.Title = 'Scatter settings';
            app.checkbox_drift_correction = uicheckbox(app.panel_tt_scatter_settings);
            app.checkbox_drift_correction.ValueChangedFcn = createCallbackFcn(app, @DriftCorrectionCheckbox, true);
            app.checkbox_drift_correction.Text = {'Plot drift '; 'corrected '; 'results'};
            app.checkbox_show_drift_correction = uicheckbox(app.panel_tt_scatter_settings);
            app.checkbox_show_drift_correction.ValueChangedFcn = createCallbackFcn(app, @DriftCorrectionShowCheckbox, true);
            app.checkbox_show_drift_correction.Text = {'Show drift'; 'correction'};
            app.plot_tt = uiaxes(app.panel_tt);
            title(app.plot_tt, 'Title')
            xlabel(app.plot_tt, 'Time (s)')
            ylabel(app.plot_tt, 'Scattered intensity (arb.u.)')
            app.slider_frame = uislider(app.panel_tt);
            app.slider_frame.ValueChangingFcn = createCallbackFcn(app, @SliderFrameChanging, true);
            app.label_time = uilabel(app.panel_tt);
            app.label_time.Text = 'Time (s):';
            app.field_time = uieditfield(app.panel_tt, 'numeric');
            app.field_time.Editable = 'off';
            app.label_frame_spinner = uilabel(app.panel_tt);
            app.label_frame_spinner.Text = 'Frame:';
            app.spinner_frame = uispinner(app.panel_tt);
            app.spinner_frame.ValueChangedFcn = createCallbackFcn(app, @SpinnerFrameChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Page_Visualization(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end