classdef SettingsTimetrace < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TimetraceanalysissettingsLabel  matlab.ui.control.Label
        NameEditFieldLabel              matlab.ui.control.Label
        NameField                       matlab.ui.control.EditField
        MethodButtonGroup               matlab.ui.container.ButtonGroup
        ROIMeanButton                   matlab.ui.control.RadioButton
        ROISumButton                    matlab.ui.control.RadioButton
        TwoDGaussianfitButton           matlab.ui.control.RadioButton
        LogTwoDGaussianfitButton        matlab.ui.control.RadioButton
        ROIsizepxEditFieldLabel         matlab.ui.control.Label
        ROIsizeField                    matlab.ui.control.NumericEditField
        FileListBoxLabel                matlab.ui.control.Label
        ND2FileList                     matlab.ui.control.ListBox
        ParticlesButtonGroup            matlab.ui.container.ButtonGroup
        AllPclesButton                  matlab.ui.control.RadioButton
        SinglePclesButton               matlab.ui.control.RadioButton
        SignificantPclesButton          matlab.ui.control.RadioButton
        AllLamp                         matlab.ui.control.Lamp
        SingleLamp                      matlab.ui.control.Lamp
        SignificantLamp                 matlab.ui.control.Lamp
        DriftCorrectionBox              matlab.ui.control.CheckBox
        RunAnalysisButton               matlab.ui.control.Button
        ProcessCroppedROICheckBox       matlab.ui.control.CheckBox
    end


    properties (Access = private)
%         ParticleFile 
        FileFolder
        FileName 
        FilesND2
        MainApp
    end
    
    properties (Access = public)
        ParticleFile 
        
%         FileFolder
%         FileName 
%         FilesND2
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, FileFolder, FileName)

            clc;
            
            app.MainApp = mainapp;
                        
            app.FileFolder = FileFolder;
            app.FileName = FileName;
            
            % Filling the fields in the app
            app.FilesND2 = dir([ app.FileFolder '\' '*.nd2']);  % Loading all nd2 files in the specified folder
            app.ND2FileList.Items = {app.FilesND2.name};
            
            app.NameField.Value = ['Timetrace'];
            app.ROIsizeField.Value = 19;
            
            if exist( [app.FileFolder '\' app.FileName '_CoordinatesFile.mat' ], 'file') == 2
                
                app.AllLamp.Color = 'Green'; 
                
                for i = app.MainApp.ParticleFile(1).Number:app.MainApp.ParticleFile(end).Number                    
                    SingleVector(i) = app.MainApp.ParticleFile(i).SinglePart;
                    SignificantVector(i) = app.MainApp.ParticleFile(i).SignificantPart;                
                end
                
                if SingleVector == zeros(size(SingleVector,1),size(SingleVector,2))                  
                    app.SingleLamp.Color = 'Red';
                    app.SinglePclesButton.Enable = 'off';
                else
                    app.SingleLamp.Color = 'Green';
                    app.SinglePclesButton.Enable = 'on';
                end
                                               
                if SignificantVector == zeros(size(SignificantVector,1),size(SignificantVector,2))                  
                    app.SignificantLamp.Color = 'Red'; 
                    app.SignificantPclesButton.Enable = 'off';
                else
                    app.SignificantLamp.Color = 'Green'; 
                    app.SignificantPclesButton.Enable = 'on';
                end
                
            else
                app.AllLamp.Color = 'Red'; 
                app.SingleLamp.Color = 'Red';
                app.SignificantLamp.Color = 'Red'; 
            end             


        end

        % Button pushed function: RunAnalysisButton
        function RunAnalysisButtonPushed(app, event)
          
            newFieldName = app.NameField.Value;
            FileND2 = [ app.FileFolder '\' app.ND2FileList.Value ];
            ROI_size_gauss = app.ROIsizeField.Value;
            DriftCorrection = app.DriftCorrectionBox.Value;
                        
            if app.ROIMeanButton.Value == 1
                MethodToUse = 1;
            end
            if app.ROISumButton.Value == 1
                MethodToUse = 2;
            end                        
            if app.TwoDGaussianfitButton.Value == 1
                MethodToUse = 3;
            end
            if app.LogTwoDGaussianfitButton.Value == 1
                MethodToUse = 4;
            end       
            
            if app.AllPclesButton.Value == 1
                PclesToAnal = 1;
            end
            if app.SinglePclesButton.Value == 1
                PclesToAnal = 2;
            end
            if app.SignificantPclesButton.Value == 1
                PclesToAnal = 3;
            end            


            % Send values back to main window by calling its public function
            RunTimetraceAnal(app.MainApp,newFieldName,FileND2,app.ROIsizeField.Value,app.DriftCorrectionBox.Value,app.ProcessCroppedROICheckBox.Value, MethodToUse,PclesToAnal);            

            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TimetraceanalysissettingsLabel = uilabel(app.UIFigure);
            app.TimetraceanalysissettingsLabel.Text = 'Timetrace analysis: settings';
            app.NameEditFieldLabel = uilabel(app.UIFigure);
            app.NameEditFieldLabel.Text = 'Name:';
            app.NameField = uieditfield(app.UIFigure, 'text');
            app.MethodButtonGroup = uibuttongroup(app.UIFigure);
            app.MethodButtonGroup.Title = 'Method:';
            app.ROIMeanButton = uiradiobutton(app.MethodButtonGroup);
            app.ROIMeanButton.Text = 'ROI Mean';
            app.ROIMeanButton.Value = true;
            app.ROISumButton = uiradiobutton(app.MethodButtonGroup);
            app.ROISumButton.Text = 'ROI Sum';
            app.TwoDGaussianfitButton = uiradiobutton(app.MethodButtonGroup);
            app.TwoDGaussianfitButton.Text = '2D Gaussian fit';
            app.LogTwoDGaussianfitButton = uiradiobutton(app.MethodButtonGroup);
            app.LogTwoDGaussianfitButton.Text = 'log 2D Gaussian fit';
            app.ROIsizepxEditFieldLabel = uilabel(app.UIFigure);
            app.ROIsizepxEditFieldLabel.Text = 'ROI size (px):';
            app.ROIsizeField = uieditfield(app.UIFigure, 'numeric');
            app.ROIsizeField.Limits = [1 Inf];
            app.ROIsizeField.Value = 19;
            app.FileListBoxLabel = uilabel(app.UIFigure);
            app.FileListBoxLabel.Text = 'File:';
            app.ND2FileList = uilistbox(app.UIFigure);
            app.ParticlesButtonGroup = uibuttongroup(app.UIFigure);
            app.ParticlesButtonGroup.Title = 'Particles:';
            app.AllPclesButton = uiradiobutton(app.ParticlesButtonGroup);
            app.AllPclesButton.Text = 'All';
            app.AllPclesButton.Value = true;
            app.SinglePclesButton = uiradiobutton(app.ParticlesButtonGroup);
            app.SinglePclesButton.Text = 'Single';
            app.SignificantPclesButton = uiradiobutton(app.ParticlesButtonGroup);
            app.SignificantPclesButton.Text = 'Significant';
            app.AllLamp = uilamp(app.ParticlesButtonGroup);
            app.SingleLamp = uilamp(app.ParticlesButtonGroup);
            app.SignificantLamp = uilamp(app.ParticlesButtonGroup);
            app.DriftCorrectionBox = uicheckbox(app.UIFigure);
            app.DriftCorrectionBox.Text = 'Drift correction';
            app.RunAnalysisButton = uibutton(app.UIFigure, 'push');
            app.RunAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @RunAnalysisButtonPushed, true);
            app.RunAnalysisButton.Text = 'Run analysis';
            app.ProcessCroppedROICheckBox = uicheckbox(app.UIFigure);
            app.ProcessCroppedROICheckBox.Text = 'Process Cropped ROI';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SettingsTimetrace(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end