classdef TimetraceAnalApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        ActionsPanel            matlab.ui.container.Panel
        ContrastButton          matlab.ui.control.Button
        SaveparticleButton      matlab.ui.control.Button
        FrmLabel                matlab.ui.control.Label
        ContrastFrameField      matlab.ui.control.NumericEditField
        SaveallButton           matlab.ui.control.Button
        SNRButton               matlab.ui.control.Button
        SignalHistButton        matlab.ui.control.Button
        TimetracePlot           matlab.ui.control.UIAxes
        FOVPlot                 matlab.ui.control.UIAxes
        SpectraPlot             matlab.ui.control.UIAxes
        LabelSPlambda           matlab.ui.control.Label
        LabelSPgamma            matlab.ui.control.Label
        FrameLabel              matlab.ui.control.Label
        FrameSlider             matlab.ui.control.Slider
        ParticlePanel           matlab.ui.container.Panel
        PartSpinner             matlab.ui.control.Spinner
        SwitchSingle            matlab.ui.control.Switch
        LampSingle              matlab.ui.control.Lamp
        LampSignificant         matlab.ui.control.Lamp
        SwitchSignificant       matlab.ui.control.Switch
        MeasurementsPanel       matlab.ui.container.Panel
        SpectraLabel            matlab.ui.control.Label
        HSMDropDown             matlab.ui.control.DropDown
        TimetraceDropDownLabel  matlab.ui.control.Label
        TimetraceDropDown       matlab.ui.control.DropDown
        LoadHSM                 matlab.ui.control.Lamp
        LoadTimetrace           matlab.ui.control.Lamp
        ContrastPlot            matlab.ui.control.UIAxes
        SNRPlot                 matlab.ui.control.UIAxes
        SampleFOVPlot           matlab.ui.control.UIAxes
        SignalLevelsPlot        matlab.ui.control.UIAxes
        TimetracesettingsPanel  matlab.ui.container.Panel
        NormalizeButton         matlab.ui.control.StateButton
        ReffrmLabel             matlab.ui.control.Label
        RefFrameField           matlab.ui.control.NumericEditField
        ManualYaxisButton       matlab.ui.control.StateButton
        YaxisMaxField           matlab.ui.control.NumericEditField
        YaxisMinField           matlab.ui.control.NumericEditField
        RmvspikesButton         matlab.ui.control.StateButton
        ShowDriftCheckBox       matlab.ui.control.CheckBox
        ShowPartsGroup          matlab.ui.container.ButtonGroup
        AllPartsButton          matlab.ui.control.ToggleButton
        SinglePartsButton       matlab.ui.control.ToggleButton
        SignificantPartsButton  matlab.ui.control.ToggleButton
        BinWidthSliderLabel     matlab.ui.control.Label
        BinWidthSlider          matlab.ui.control.Slider
        XYtracePlot             matlab.ui.control.UIAxes
        FrameSpinnerLabel       matlab.ui.control.Label
        FrameSpinner            matlab.ui.control.Spinner
        TimesEditFieldLabel     matlab.ui.control.Label
        TimeField               matlab.ui.control.NumericEditField
        CorrectXYCheckBox       matlab.ui.control.CheckBox
    end

    properties (Access = private)

         TimetraceName
         HSMName
         PolarName
         WorkFieldName
         TimetraceToPlot
         BinWidth
         SampleFOV 
         PcleLocation
         
    end

    properties (Access = public)
         MainApp
    end
    
    
    methods (Access = private)
        
        function NBins = numhistbins(app,data)
            % Utility function to compute the number of histogram bins
            
                if app.BinWidth == 1
                    binwidth = 0.000001;   
                end
                if app.BinWidth == 2
                    binwidth = 0.000005;   
                                end
                if app.BinWidth == 3
                    binwidth = 0.00001;   
                end
                if app.BinWidth == 4
                    binwidth = 0.00005;   
                end              
                if app.BinWidth == 5
                    binwidth = 0.0001;   
                end   
                if app.BinWidth == 6
                    binwidth = 0.0005;   
                end  
                if app.BinWidth == 7
                    binwidth = 0.001;   
                end  
                if app.BinWidth == 8
                    binwidth = 0.005;   
                end  
                if app.BinWidth == 9
                    binwidth = 0.01;   
                end   
                if app.BinWidth == 10
                    binwidth = 0.05;   
                end  
                if app.BinWidth == 11
                    binwidth = 0.1;   
                end  
                if app.BinWidth == 12
                    binwidth = 0.5;   
                end
                if app.BinWidth == 13
                    binwidth = 1;   
                end 
 
                range2plot = floor(min(data)):mean(data)*binwidth:ceil(max(data));                   
                NBins = size(range2plot,2);
        end
      
    end    


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
        
        app.MainApp = mainapp;
        clear mainapp;
                     
        app.LoadTimetrace.Color = 'Red';
        app.LoadHSM.Color = 'Red';
            
        app.TimetraceDropDown.Items = fieldnames(app.MainApp.ParticleFile);
        app.HSMDropDown.Items = fieldnames(app.MainApp.ParticleFile);
        
        app.TimetraceName = app.TimetraceDropDown.Items{1};        
        app.HSMName = app.HSMDropDown.Items{1};
                
        app.SwitchSingle.ItemsData = [ 0 , 1 ];
        app.SwitchSignificant.ItemsData = [ 0 , 1 ];
        
                app.AllPartsButton.Enable = 'on';
        
                for i = app.MainApp.ParticleFile(1).Number:app.MainApp.ParticleFile(end).Number                    
                    SingleVector(i) = app.MainApp.ParticleFile(i).SinglePart;
                    SignificantVector(i) = app.MainApp.ParticleFile(i).SignificantPart;                
                end
                
                if SingleVector == zeros(size(SingleVector,1),size(SingleVector,2))                  
                    app.SinglePartsButton.Enable = 'off';
                else
                    app.SinglePartsButton.Enable = 'on'; 
                end
                                               
                if SignificantVector == zeros(size(SignificantVector,1),size(SignificantVector,2))                  
                    app.SignificantPartsButton.Enable = 'off';
                else
                    app.SignificantPartsButton.Enable = 'on';
                end
        
        app.SwitchSingle.Value = app.MainApp.ParticleFile(app.PartSpinner.Value).SinglePart;
        app.SwitchSignificant.Value = app.MainApp.ParticleFile(app.PartSpinner.Value).SignificantPart;
        
           
            if app.SwitchSingle.Value == 1
                app.LampSingle.Color = 'Green';
            else
                app.LampSingle.Color = 'Red';
            end
            
            if app.SwitchSignificant.Value == 1
                app.LampSignificant.Color = 'Cyan';
            else
                app.LampSignificant.Color = 'Red';
            end
        
        app.BinWidthSlider.MajorTicks = [1 2 3 4 5 6 7 8 9 10 11 12 13];
        app.BinWidthSlider.MinorTicks = [];
        app.BinWidthSlider.FontSize = 10;
        % app.BinWidthSlider.MajorTickLabels = {'0.000001','0.000005','0.00001', '0.00005', '0.0001', '0.005', '0.001', '0.005', '0.01', '0.05', '0.1', '0.5', '1'}';        
        app.BinWidthSlider.MajorTickLabels = {'','','', '', '', '', '', '', '', '', '', '', ''}';                
        app.BinWidthSlider.Limits = [ 1 13 ];
        app.BinWidthSlider.Value = 7;
        app.BinWidth = app.BinWidthSlider.Value;
                  
        Population = load([app.MainApp.FileFolder '\' app.MainApp.FileName '_CoordinatesFile.mat' ]);    
        app.SampleFOV = Population.FOVmerged; 
        app.SampleFOV = flipdim(app.SampleFOV,1);
        
        app.PcleLocation = Population.Pcles.Location;
        app.PcleLocation(:,2) = size(app.SampleFOV,2) - app.PcleLocation(:,2);
        
        UpdateSampleFOVPlot(app);             
       
        
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
        
        CallBack(app.MainApp);        
        delete(app)
            
        end

        % Value changed function: TimetraceDropDown
        function TimetraceDropDownValueChanged(app, event)
                    
            app.LoadTimetrace.Color = 'Yellow'; 
            app.TimetraceName = app.TimetraceDropDown.Value;
            
            app.PartSpinner.Limits = [app.MainApp.ParticleFile(1).Number  app.MainApp.ParticleFile(end).Number];
            
            app.LoadTimetrace.Color = 'Green';
                         
            %Change the plots             
            UpdateSNRPlot(app); 
            
            %Settings and preparation for Timetrace plotting
            if isfield(app.MainApp.ParticleFile(app.PartSpinner.Value).(app.TimetraceName), 'Intensity') == 1
                
                % setting the frame slider and spinner                
                app.FrameSlider.Limits = [ 1 length(app.MainApp.ParticleFile(app.PartSpinner.Value).(app.TimetraceName).Time)  ];
                app.FrameSpinner.Limits = [  1 length(app.MainApp.ParticleFile(app.PartSpinner.Value).(app.TimetraceName).Time) ];
                app.ContrastFrameField.Value = length(app.MainApp.ParticleFile(app.PartSpinner.Value).(app.TimetraceName).Time);
                                                
                cla(app.TimetracePlot)
                cla(app.FOVPlot);
                hold(app.FOVPlot,'on');
                app.FOVPlot.XLim = [ 1 size(app.MainApp.ParticleFile(app.PartSpinner.Value).(app.TimetraceName).RawData,1)  ];
                app.FOVPlot.YLim = [ 1 size(app.MainApp.ParticleFile(app.PartSpinner.Value).(app.TimetraceName).RawData,2)  ];
                app.FOVPlot.XTick = [];
                app.FOVPlot.YTick = [];  
                
                if isfield(app.MainApp.ParticleFile(app.PartSpinner.Value).(app.TimetraceName), 'DriftCorrTrace') == 1
                    app.ShowDriftCheckBox.Enable = 'on';
                else
                    app.ShowDriftCheckBox.Enable = 'off';                    
                end  
               
            end

            UpdateTimetracePlot(app); 
            UpdateSignalLevelsPlot(app); 
            UpdateContrastPlot(app); 
            UpdateXYtracePlot(app);
            
        end

        % Value changed function: HSMDropDown
        function HSMDropDownValueChanged(app, event)
            
            app.LoadHSM.Color = 'Yellow'; 
            app.HSMName = app.HSMDropDown.Value;            
            app.LoadHSM.Color = 'Green';
            
            %Change the plots                     
            UpdateSpectraPlot(app);
            UpdateContrastPlot(app);
                     
        end

        % Callback function
        function HSM2DropDownValueChanged(app, event)
      
        end

        % Value changed function: PartSpinner
        function PartSpinnerValueChanged(app, event)
          
           
            clc;
            cla(app.TimetracePlot);
                        
            app.SwitchSingle.Value = app.MainApp.ParticleFile(app.PartSpinner.Value).SinglePart;
            app.SwitchSignificant.Value = app.MainApp.ParticleFile(app.PartSpinner.Value).SignificantPart;
            
            if app.SwitchSingle.Value == 1
                app.LampSingle.Color = 'Green';
            else
                app.LampSingle.Color = 'Red';
            end
            
            if app.SwitchSignificant.Value == 1
                app.LampSignificant.Color = 'Cyan';
            else
                app.LampSignificant.Color = 'Red';
            end   


            UpdateSampleFOVPlot(app); 
            
            % Particle spectra plot                       
            UpdateSpectraPlot(app);
            
            % Contrast plot
            UpdateContrastPlot(app);
          
            % SNR plot
            UpdateSNRPlot(app);                
                
           
            %Settings and preparation for Timetrace plotting
            if isfield(app.MainApp.ParticleFile(app.PartSpinner.Value).(app.TimetraceName), 'Intensity') == 1                
                
                % setting the frame slider and spinner
                app.FrameSlider.Limits = [ 1 length(app.MainApp.ParticleFile(app.PartSpinner.Value).(app.TimetraceName).Time)  ];
                app.FrameSpinner.Limits = [  1 length(app.MainApp.ParticleFile(app.PartSpinner.Value).(app.TimetraceName).Time) ];
                app.ContrastFrameField.Value = length(app.MainApp.ParticleFile(app.PartSpinner.Value).(app.TimetraceName).Time);
                
                cla(app.TimetracePlot);
                cla(app.FOVPlot);
                hold(app.FOVPlot,'on');
                app.FOVPlot.XLim = [ 1 size(app.MainApp.ParticleFile(app.PartSpinner.Value).(app.TimetraceName).RawData,1)  ];
                app.FOVPlot.YLim = [ 1 size(app.MainApp.ParticleFile(app.PartSpinner.Value).(app.TimetraceName).RawData,2)  ];
                app.FOVPlot.XTick = [];
                app.FOVPlot.YTick = [];
                
                if isfield(app.MainApp.ParticleFile(app.PartSpinner.Value).(app.TimetraceName), 'DriftCorrTrace') == 1
                    app.ShowDriftCheckBox.Enable = 'on';
                else
                    app.ShowDriftCheckBox.Enable = 'off';                    
                end                
                
                                
            end
                
            UpdateTimetracePlot(app); 
            UpdateSignalLevelsPlot(app);
            UpdateXYtracePlot(app);
            
        end

        % Value changed function: RefFrameField
        function RefFrameFieldValueChanged(app, event)
                           
            UpdateTimetracePlot(app); 
            UpdateSignalLevelsPlot(app); 
            UpdateContrastPlot(app); 
            UpdateSNRPlot(app);

        end

        % Value changed function: FrameSlider
        function FrameSliderValueChanged(app, event)

%          erase this somehow!!!    
            
        end

        % Value changed function: NormalizeButton
        function NormalizeButtonValueChanged(app, event)
                
            UpdateTimetracePlot(app);
            UpdateSignalLevelsPlot(app);

        end

        % Value changing function: FrameSlider
        function FrameSliderValueChanging(app, event)
            
            app.FrameSlider.Value = round(event.Value);
            app.FrameSpinner.Value = app.FrameSlider.Value;
                        
            UpdateTimetracePlot(app);
            UpdateXYtracePlot(app);
                              
        end

        % Value changed function: RmvspikesButton
        function RmvspikesButtonValueChanged(app, event)
              
            UpdateTimetracePlot(app);
            UpdateSignalLevelsPlot(app);

        end

        % Value changed function: SwitchSingle
        function SwitchSingleValueChanged(app, event)
            
            app.MainApp.ParticleFile(app.PartSpinner.Value).SinglePart = app.SwitchSingle.Value;
                        
            if app.SwitchSingle.Value == 1
                app.LampSingle.Color = 'Green';
            else
                app.LampSingle.Color = 'Red';
            end    
            
                for i = app.MainApp.ParticleFile(1).Number:app.MainApp.ParticleFile(end).Number                    
                    SingleVector(i) = app.MainApp.ParticleFile(i).SinglePart;
                    SignificantVector(i) = app.MainApp.ParticleFile(i).SignificantPart;                
                end
                
                if SingleVector == zeros(size(SingleVector,1),size(SingleVector,2))                  
                    app.SinglePartsButton.Enable = 'off';
                else
                    app.SinglePartsButton.Enable = 'on'; 
                end
                                               
                if SignificantVector == zeros(size(SignificantVector,1),size(SignificantVector,2))                  
                    app.SignificantPartsButton.Enable = 'off';
                else
                    app.SignificantPartsButton.Enable = 'on';
                end
            
        
        end

        % Value changed function: SwitchSignificant
        function SwitchSignificantValueChanged(app, event)
        
            app.MainApp.ParticleFile(app.PartSpinner.Value).SignificantPart = app.SwitchSignificant.Value;
  
            if app.SwitchSignificant.Value == 1
                app.LampSignificant.Color = 'Cyan';
            else
                app.LampSignificant.Color = 'Red';
            end
            
                for i = app.MainApp.ParticleFile(1).Number:app.MainApp.ParticleFile(end).Number                    
                    SingleVector(i) = app.MainApp.ParticleFile(i).SinglePart;
                    SignificantVector(i) = app.MainApp.ParticleFile(i).SignificantPart;                
                end
                
                if SingleVector == zeros(size(SingleVector,1),size(SingleVector,2))                  
                    app.SinglePartsButton.Enable = 'off';
                else
                    app.SinglePartsButton.Enable = 'on'; 
                end
                                               
                if SignificantVector == zeros(size(SignificantVector,1),size(SignificantVector,2))                  
                    app.SignificantPartsButton.Enable = 'off';
                else
                    app.SignificantPartsButton.Enable = 'on';
                end
            
            
        end

        % Value changed function: FrameSpinner
        function FrameSpinnerValueChanged(app, event)
            
            app.FrameSpinner.Value = round(event.Value);
            app.FrameSlider.Value = app.FrameSpinner.Value;
              
            UpdateTimetracePlot(app);
            UpdateXYtracePlot(app);

        end

        % Callback function
        function PlasmonShiftButtonPushed(app, event)
            
%          erase this somehow!!!   
            
        end

        % Button pushed function: ContrastButton
        function ContrastButtonPushed(app, event)
          
            UpdateContrastPlot(app,event)
          
        end

        % Button pushed function: SaveparticleButton
        function SaveparticleButtonPushed(app, event)
            
            SaveFunctTimetracePlot(app.MainApp.FileFolder,app.TimetraceName,app.HSMXName,app.HSMYName,app.MainApp.ParticleFile(app.PartSpinner.Value).(app.TimetraceName).Time,app.TimetraceToPlot,app.MainApp.ParticleFile(app.PartSpinner.Value).(app.HSMXName).Lambda,app.MainApp.ParticleFile(app.PartSpinner.Value).(app.HSMYName).Lambda,app.MainApp.ParticleFile(app.PartSpinner.Value).(app.HSMXName).Intensity,app.MainApp.ParticleFile(app.PartSpinner.Value).(app.HSMYName).Intensity,app.MainApp.ParticleFile(app.PartSpinner.Value).(app.HSMXName).Lorentz,app.MainApp.ParticleFile(app.PartSpinner.Value).(app.HSMYName).Lorentz,app.PartSpinner.Value);      
                      
        end

        % Button pushed function: SaveallButton
        function SaveallButtonPushed(app, event)


        for i = app.MainApp.ParticleFile(1).Number:app.MainApp.ParticleFile(end).Number     
        
            if isfield(app.MainApp.ParticleFile(i).(app.TimetraceName), 'Intensity') == 1

                timetrace = app.MainApp.ParticleFile(i).(app.TimetraceName).Intensity;
                
                if app.NormalizeButton.Value == 1   
                    ref = mean(hampel(app.MainApp.ParticleFile(i).(app.TimetraceName).Intensity(3:app.RefFrameField.Value),5));            
                    timetrace = timetrace ./ ref;
                end
                if app.RmvspikesButton.Value == 1                                   
                    timetrace = hampel(timetrace,5);                    
                end
      
                    SaveFunctTimetracePlot(app.MainApp.FileFolder,app.TimetraceName,app.HSMXName,app.HSMYName,app.MainApp.ParticleFile(i).(app.TimetraceName).Time,timetrace,app.MainApp.ParticleFile(i).(app.HSMXName).Lambda,app.MainApp.ParticleFile(i).(app.HSMYName).Lambda,app.MainApp.ParticleFile(i).(app.HSMXName).Intensity,app.MainApp.ParticleFile(i).(app.HSMYName).Intensity,app.MainApp.ParticleFile(i).(app.HSMXName).Lorentz,app.MainApp.ParticleFile(i).(app.HSMYName).Lorentz,i);      
             
                close all;
                clear timetrace;
            
            end
                        
        end     
            
        
        end

        % Selection changed function: ShowPartsGroup
        function ShowPartsGroupSelectionChanged(app, event)
           
            UpdateSampleFOVPlot(app); 
            UpdateContrastPlot(app);
            UpdateSpectraPlot(app);            
                        
        end

        % Size changed function: UIFigure
        function UpdateContrastPlot(app, event)
   
        %% Contrast distribution plot
        % show contrast for all particles with measured timetrace and HSM        
        
        if app.AllPartsButton.Value == 1                
            inx = 1;
            for i = app.MainApp.ParticleFile(1).Number:app.MainApp.ParticleFile(end).Number
                if isfield(app.MainApp.ParticleFile(i).(app.TimetraceName), 'Intensity') == 1 & isfield(app.MainApp.ParticleFile(i).(app.HSMName), 'Intensity') == 1          

                            if app.RmvspikesButton.Value == 1                                                         
                                if app.ContrastFrameField.Value < 6                    
                                    sig = mean(hampel(app.MainApp.ParticleFile(i).(app.TimetraceName).Intensity(1:10),5));
                                elseif app.ContrastFrameField.Value > length(app.MainApp.ParticleFile(i).(app.TimetraceName).Intensity)-6                     
                                    sig = mean(hampel(app.MainApp.ParticleFile(i).(app.TimetraceName).Intensity(end-10:end),5));
                                else
                                    sig = mean(hampel(app.MainApp.ParticleFile(i).(app.TimetraceName).Intensity(app.ContrastFrameField.Value-5:app.ContrastFrameField.Value+5),5));
                                end                                
                                ref = mean(hampel(app.MainApp.ParticleFile(i).(app.TimetraceName).Intensity(3:app.RefFrameField.Value),5)); %reference value for the contrast                                                                                            
                            else
                                if app.ContrastFrameField.Value < 6                    
                                    sig = mean(app.MainApp.ParticleFile(i).(app.TimetraceName).Intensity(1:10));
                                elseif app.ContrastFrameField.Value > length(app.MainApp.ParticleFile(i).(app.TimetraceName).Intensity)-6                     
                                    sig = mean(app.MainApp.ParticleFile(i).(app.TimetraceName).Intensity(end-10:end));
                                else
                                    sig = mean(app.MainApp.ParticleFile(i).(app.TimetraceName).Intensity(app.ContrastFrameField.Value-5:app.ContrastFrameField.Value+5));
                                end                                
                                ref = mean(app.MainApp.ParticleFile(i).(app.TimetraceName).Intensity(3:app.RefFrameField.Value)); %reference value for the contrast                                                                                                                           
                            end
                            
                            contrast(inx) = sig./ref - 1;                
                            wavelength(inx) = app.MainApp.ParticleFile(i).(app.HSMName).SPlambda;
                            
                                if i == app.PartSpinner.Value
                                    contrast_active = contrast(inx);
                                    wavelength_active = wavelength(inx);
                                end 
                                                                                           
                            inx = inx+1;                        
                end    
            end   
        end 
 
        % show contrast for only single particles with measured timetrace and HSM
        if app.SinglePartsButton.Value == 1                
            inx = 1;
            for i = app.MainApp.ParticleFile(1).Number:app.MainApp.ParticleFile(end).Number
                if isfield(app.MainApp.ParticleFile(i).(app.TimetraceName), 'Intensity') == 1 & isfield(app.MainApp.ParticleFile(i).(app.HSMName), 'Intensity') == 1          
                    if app.MainApp.ParticleFile(i).SinglePart == 1  
                            if app.RmvspikesButton.Value == 1                                                         
                                if app.ContrastFrameField.Value < 6                    
                                    sig = mean(hampel(app.MainApp.ParticleFile(i).(app.TimetraceName).Intensity(1:10),5));
                                elseif app.ContrastFrameField.Value > length(app.MainApp.ParticleFile(i).(app.TimetraceName).Intensity)-6                     
                                    sig = mean(hampel(app.MainApp.ParticleFile(i).(app.TimetraceName).Intensity(end-10:end),5));
                                else
                                    sig = mean(hampel(app.MainApp.ParticleFile(i).(app.TimetraceName).Intensity(app.ContrastFrameField.Value-5:app.ContrastFrameField.Value+5),5));
                                end                                
                                ref = mean(hampel(app.MainApp.ParticleFile(i).(app.TimetraceName).Intensity(3:app.RefFrameField.Value),5)); %reference value for the contrast                                                                                            
                            else
                                if app.ContrastFrameField.Value < 6                    
                                    sig = mean(app.MainApp.ParticleFile(i).(app.TimetraceName).Intensity(1:10));
                                elseif app.ContrastFrameField.Value > length(app.MainApp.ParticleFile(i).(app.TimetraceName).Intensity)-6                     
                                    sig = mean(app.MainApp.ParticleFile(i).(app.TimetraceName).Intensity(end-10:end));
                                else
                                    sig = mean(app.MainApp.ParticleFile(i).(app.TimetraceName).Intensity(app.ContrastFrameField.Value-5:app.ContrastFrameField.Value+5));
                                end                                
                                ref = mean(app.MainApp.ParticleFile(i).(app.TimetraceName).Intensity(3:app.RefFrameField.Value)); %reference value for the contrast                                                                                                                           
                            end
                            
                            contrast(inx) = sig./ref - 1;                
                            wavelength(inx) = app.MainApp.ParticleFile(i).(app.HSMName).SPlambda;                                                      
                                                        
                                if i == app.PartSpinner.Value
                                    contrast_active = contrast(inx);
                                    wavelength_active = wavelength(inx);
                                end 
                                
                            inx = inx+1;     
                    end   
                end    
            end   
        end 
 
 
        % show contrast for only significant particles with measured timetrace and HSM
        if app.SignificantPartsButton.Value == 1                
            inx = 1;
            for i = app.MainApp.ParticleFile(1).Number:app.MainApp.ParticleFile(end).Number
                if isfield(app.MainApp.ParticleFile(i).(app.TimetraceName), 'Intensity') == 1 & isfield(app.MainApp.ParticleFile(i).(app.HSMName), 'Intensity') == 1          
                    if app.MainApp.ParticleFile(i).SignificantPart == 1  
                            if app.RmvspikesButton.Value == 1                                                         
                                if app.ContrastFrameField.Value < 6                    
                                    sig = mean(hampel(app.MainApp.ParticleFile(i).(app.TimetraceName).Intensity(1:10),5));
                                elseif app.ContrastFrameField.Value > length(app.MainApp.ParticleFile(i).(app.TimetraceName).Intensity)-6                     
                                    sig = mean(hampel(app.MainApp.ParticleFile(i).(app.TimetraceName).Intensity(end-10:end),5));
                                else
                                    sig = mean(hampel(app.MainApp.ParticleFile(i).(app.TimetraceName).Intensity(app.ContrastFrameField.Value-5:app.ContrastFrameField.Value+5),5));
                                end                                
                                ref = mean(hampel(app.MainApp.ParticleFile(i).(app.TimetraceName).Intensity(3:app.RefFrameField.Value),5)); %reference value for the contrast                                                                                            
                            else
                                if app.ContrastFrameField.Value < 6                    
                                    sig = mean(app.MainApp.ParticleFile(i).(app.TimetraceName).Intensity(1:10));
                                elseif app.ContrastFrameField.Value > length(app.MainApp.ParticleFile(i).(app.TimetraceName).Intensity)-6                     
                                    sig = mean(app.MainApp.ParticleFile(i).(app.TimetraceName).Intensity(end-10:end));
                                else
                                    sig = mean(app.MainApp.ParticleFile(i).(app.TimetraceName).Intensity(app.ContrastFrameField.Value-5:app.ContrastFrameField.Value+5));
                                end                                
                                ref = mean(app.MainApp.ParticleFile(i).(app.TimetraceName).Intensity(3:app.RefFrameField.Value)); %reference value for the contrast                                                                                                                           
                            end
                            
                            contrast(inx) = sig./ref - 1;                
                            wavelength(inx) = app.MainApp.ParticleFile(i).(app.HSMName).SPlambda; 
                                                                                    
                                if i == app.PartSpinner.Value
                                    contrast_active = contrast(inx);
                                    wavelength_active = wavelength(inx);
                                end 
                                                                                           
                            inx = inx+1;     
                    end   
                end    
            end   
        end  

        
         if exist('wavelength') ~= 0
                 % Contrast additional calculation and plotting
                 wavelength = wavelength( abs(contrast)<2.5 );
                 contrast = contrast( abs(contrast)<2.5 );   
                 wavelength_mu = mean(wavelength);
                 wavelength_sigma = std(wavelength);
                
                 cla(app.ContrastPlot);
                 hold(app.ContrastPlot,'on');
                 scatter(app.ContrastPlot,wavelength,contrast,'bo');  
                 if exist('contrast_active') ~= 0
                     scatter(app.ContrastPlot,wavelength_active,contrast_active,'ro', 'filled');
                 end    
                 
                 app.ContrastPlot.XLim = [round(wavelength_mu-3*wavelength_sigma) round(wavelength_mu+3*wavelength_sigma)];
                 
                 if app.ManualYaxisButton.Value == 1 
                     app.ContrastPlot.YLim = [app.YaxisMinField.Value-1  app.YaxisMaxField.Value-1 ];
                     limY = [app.YaxisMinField.Value-1  app.YaxisMaxField.Value-1 ];
                 else  
                     if -max(contrast) < max(contrast)
                         app.ContrastPlot.YLim = [-max(contrast)*1.1  max(contrast)*1.1]; 
                         limY = [-max(contrast)*1.1  max(contrast)*1.1];
                     else
                         app.ContrastPlot.YLim = [max(contrast)*1.1  -max(contrast)*1.1];
                         limY = [max(contrast)*1.1  -max(contrast)*1.1];
                     end                       
                 end
                 
                 if exist('event') ~= 0 
                     if event.EventName == 'ButtonPushed'
                            time = app.MainApp.ParticleFile(app.PartSpinner.Value).(app.TimetraceName).Time(app.ContrastFrameField.Value);        
                            SaveTimetraceContrast(wavelength,contrast,app.MainApp.FileFolder,app.TimetraceName,app.ContrastFrameField.Value,time,limY); 
                     
                     end
                 end    
         else
                 cla(app.ContrastPlot);            
         end    


        end

        % Size changed function: ActionsPanel
        function UpdateSpectraPlot(app, event)
            
            %% Particle spectra plot            
            if isfield(app.MainApp.ParticleFile(app.PartSpinner.Value).(app.HSMName), 'Intensity') == 1            
                
                Lorentz_eV = linspace(1248/(min(app.MainApp.ParticleFile(app.PartSpinner.Value).(app.HSMName).Lambda)-30),1248/(max(app.MainApp.ParticleFile(app.PartSpinner.Value).(app.HSMName).Lambda)+30),100);                                                          
                plot(app.SpectraPlot,app.MainApp.ParticleFile(app.PartSpinner.Value).(app.HSMName).Lambda,app.MainApp.ParticleFile(app.PartSpinner.Value).(app.HSMName).Intensity,'ro',1248./Lorentz_eV,Lorentzfunction(app.MainApp.ParticleFile(app.PartSpinner.Value).(app.HSMName).Lorentz,Lorentz_eV),'r-');    
                app.SpectraPlot.XLim = [ (min(app.MainApp.ParticleFile(app.PartSpinner.Value).(app.HSMName).Lambda)-30)  (max(app.MainApp.ParticleFile(app.PartSpinner.Value).(app.HSMName).Lambda)+30) ];
                %  app.TimetracePlot.Title.String = [];            
                app.LabelSPlambda.Text = ['λ = ' num2str(round(app.MainApp.ParticleFile(app.PartSpinner.Value).(app.HSMName).SPlambda,1)) ' nm'];
                app.LabelSPgamma.Text = ['Γ = ' num2str(round(app.MainApp.ParticleFile(app.PartSpinner.Value).(app.HSMName).Linewidth,1)) ' meV'];
               
            else            
                cla(app.SpectraPlot); 
                app.LabelSPlambda.Text = ['No data'];
                app.LabelSPgamma.Text = ['No data'];
            end  
                    
        end

        % Value changed function: ContrastFrameField
        function UpdateSNRPlot(app, event)

            
        % show SNR andN (shot noise) for all particles with measured timetrace
        if app.AllPartsButton.Value == 1                
            inx = 1;
            for i = app.MainApp.ParticleFile(1).Number:app.MainApp.ParticleFile(end).Number
                if isfield(app.MainApp.ParticleFile(i).(app.TimetraceName), 'Intensity') == 1                          

                            if app.RmvspikesButton.Value == 1                                                                                        
                                ref = mean(hampel(app.MainApp.ParticleFile(i).(app.TimetraceName).Intensity(3:app.RefFrameField.Value),5)); %reference value for the contrast                                                                                            
                                ref_std = std(hampel(app.MainApp.ParticleFile(i).(app.TimetraceName).Intensity(3:app.RefFrameField.Value),5)); %reference value for the contrast                                                                                                                                                        
                            else                               
                                ref = mean(app.MainApp.ParticleFile(i).(app.TimetraceName).Intensity(3:app.RefFrameField.Value)); %reference value for the contrast                                                                                                                           
                                ref_std = std(app.MainApp.ParticleFile(i).(app.TimetraceName).Intensity(3:app.RefFrameField.Value)); %reference value for the contrast                                                                                                                                                       
                            end
                            
                            signalN(inx) = ref;
                            % app.MainApp.ParticleFile(i).(app.TimetraceName).NormSignalN = ref;
                            signalSTD(inx) = ref_std;
                            % app.MainApp.ParticleFile(i).(app.TimetraceName).NormSignalSTD = ref_std;
                            signalSN(inx) = sqrt(ref);
                            % app.MainApp.ParticleFile(i).(app.TimetraceName).NormSignalSN = sqrt(ref);                            
                            
                                if i == app.PartSpinner.Value
                                    signalN_active = signalN(inx);
                                    signalSTD_active = signalSTD(inx);
                                    signalSN_active = signalSN(inx);
                                end  
                                                                                           
                            inx = inx+1;                        
                end    
            end   
        end          
            
            
        % show contrast for only single particles with measured timetrace
        if app.SinglePartsButton.Value == 1                
            inx = 1;
            for i = app.MainApp.ParticleFile(1).Number:app.MainApp.ParticleFile(end).Number
                if isfield(app.MainApp.ParticleFile(i).(app.TimetraceName), 'Intensity') == 1          
                    if app.MainApp.ParticleFile(i).SinglePart == 1  
                            
                            if app.RmvspikesButton.Value == 1                                                                                        
                                ref = mean(hampel(app.MainApp.ParticleFile(i).(app.TimetraceName).Intensity(3:app.RefFrameField.Value),5)); %reference value for the contrast                                                                                            
                                ref_std = std(hampel(app.MainApp.ParticleFile(i).(app.TimetraceName).Intensity(3:app.RefFrameField.Value),5)); %reference value for the contrast                                                                                                                                                        
                            else                               
                                ref = mean(app.MainApp.ParticleFile(i).(app.TimetraceName).Intensity(3:app.RefFrameField.Value)); %reference value for the contrast                                                                                                                           
                                ref_std = std(app.MainApp.ParticleFile(i).(app.TimetraceName).Intensity(3:app.RefFrameField.Value)); %reference value for the contrast                                                                                                                                                       
                            end
                            
                            signalN(inx) = ref;
                            % app.MainApp.ParticleFile(i).(app.TimetraceName).NormSignalN = ref;
                            signalSTD(inx) = ref_std;
                            % app.MainApp.ParticleFile(i).(app.TimetraceName).NormSignalSTD = ref_std;
                            signalSN(inx) = sqrt(ref);
                            % app.MainApp.ParticleFile(i).(app.TimetraceName).NormSignalSN = sqrt(ref);                            
                            
                                if i == app.PartSpinner.Value
                                    signalN_active = signalN(inx);
                                    signalSTD_active = signalSTD(inx);
                                    signalSN_active = signalSN(inx);
                                end 
                                
                            inx = inx+1;     
                    end   
                end    
            end   
        end             
            
            
        % show contrast for only significant particles with measured timetrace and HSM
        if app.SignificantPartsButton.Value == 1                
            inx = 1;
            for i = app.MainApp.ParticleFile(1).Number:app.MainApp.ParticleFile(end).Number
                if isfield(app.MainApp.ParticleFile(i).(app.TimetraceName), 'Intensity') == 1        
                    if app.MainApp.ParticleFile(i).SignificantPart == 1  
                            
                            if app.RmvspikesButton.Value == 1                                                                                        
                                ref = mean(hampel(app.MainApp.ParticleFile(i).(app.TimetraceName).Intensity(3:app.RefFrameField.Value),5)); %reference value for the contrast                                                                                            
                                ref_std = std(hampel(app.MainApp.ParticleFile(i).(app.TimetraceName).Intensity(3:app.RefFrameField.Value),5)); %reference value for the contrast                                                                                                                                                        
                            else                               
                                ref = mean(app.MainApp.ParticleFile(i).(app.TimetraceName).Intensity(3:app.RefFrameField.Value)); %reference value for the contrast                                                                                                                           
                                ref_std = std(app.MainApp.ParticleFile(i).(app.TimetraceName).Intensity(3:app.RefFrameField.Value)); %reference value for the contrast                                                                                                                                                       
                            end
                            
                            signalN(inx) = ref;
                            % app.MainApp.ParticleFile(i).(app.TimetraceName).NormSignalN = ref;
                            signalSTD(inx) = ref_std;
                            % app.MainApp.ParticleFile(i).(app.TimetraceName).NormSignalSTD = ref_std;
                            signalSN(inx) = sqrt(ref);
                            % app.MainApp.ParticleFile(i).(app.TimetraceName).NormSignalSN = sqrt(ref);                            
                            
                                if i == app.PartSpinner.Value
                                    signalN_active = signalN(inx);
                                    signalSTD_active = signalSTD(inx);
                                    signalSN_active = signalSN(inx);
                                end 
                                                                                           
                            inx = inx+1;     
                    end   
                end    
            end   
        end            
            
        
        if exist('signalN') ~= 0
               
                cla(app.SNRPlot)
                hold(app.SNRPlot,'on')
                scatter(app.SNRPlot,signalN,signalSTD,'bo');
                scatter(app.SNRPlot,signalN,signalSN,'ro'); 
                if exist('signalN_active') ~= 0
                    scatter(app.SNRPlot,signalN_active,signalSTD_active,'co', 'filled');
                    scatter(app.SNRPlot,signalN_active,signalSN_active,'yo', 'filled');                    
                end 
                app.SNRPlot.XLim = [0.9*min(signalN) 1.1*max(signalN)];
                app.SNRPlot.YLim = [-Inf Inf];
                app.SNRPlot.XScale = 'log';
                app.SNRPlot.YScale = 'log';
               
       
                 if exist('event') ~= 0 
                     if event.EventName == 'ButtonPushed'
                           
                         time = app.MainApp.ParticleFile(app.PartSpinner.Value).(app.TimetraceName).Time(app.RefFrameField.Value);                                    
                         SaveTimetraceSNR(signalN,signalSTD,signalSN,app.MainApp.FileFolder,app.TimetraceName,app.RefFrameField.Value,time); 
                     
                     end
                 end 
                
        else
                cla(app.SNRPlot);            
        end  
            
   
        end

        % Value changed function: ManualYaxisButton
        function ManualYaxisButtonValueChanged(app, event)
            
            % Contrast plot
            UpdateTimetracePlot(app);
            UpdateContrastPlot(app);            
            UpdateSignalLevelsPlot(app);
            
        end

        % Callback function
        function UpdateTimetracePlot(app, event)

        if isfield(app.MainApp.ParticleFile(app.PartSpinner.Value).(app.TimetraceName), 'Intensity') == 1
        
            app.TimetraceToPlot = app.MainApp.ParticleFile(app.PartSpinner.Value).(app.TimetraceName).Intensity;
            
            app.TimeField.Value = app.MainApp.ParticleFile(app.PartSpinner.Value).(app.TimetraceName).Time(app.FrameSpinner.Value);
            
            if app.RmvspikesButton.Value == 1                                   
                app.TimetraceToPlot = hampel(app.TimetraceToPlot,5);                    
            end  
            
            if app.NormalizeButton.Value == 1                
                ref = mean(app.TimetraceToPlot(3:app.RefFrameField.Value));
                app.TimetraceToPlot = app.TimetraceToPlot ./ ref;                    
            end
            
                % Updating some information fields
                app.TimeField.Value = app.MainApp.ParticleFile(app.PartSpinner.Value).(app.TimetraceName).Time(app.FrameSpinner.Value);
            
                % Plotting the timetrace
                cla(app.TimetracePlot)
                hold(app.TimetracePlot,'on')
                plot(app.TimetracePlot,app.MainApp.ParticleFile(app.PartSpinner.Value).(app.TimetraceName).Time(1:app.FrameSpinner.Value), app.TimetraceToPlot(1:app.FrameSpinner.Value) ,'-','color', [1 0 0]);    
                plot(app.TimetracePlot,app.MainApp.ParticleFile(app.PartSpinner.Value).(app.TimetraceName).Time(app.FrameSpinner.Value:end), app.TimetraceToPlot(app.FrameSpinner.Value:end) ,'-','color', [1 0.5 0.5]);                  
                app.TimetracePlot.Title.String = [ app.TimetraceName ];
                app.TimetracePlot.XLim = [app.MainApp.ParticleFile(app.PartSpinner.Value).(app.TimetraceName).Time(1)  app.MainApp.ParticleFile(app.PartSpinner.Value).(app.TimetraceName).Time(end) ];
                
                    if app.ManualYaxisButton.Value == 1 
                        app.TimetracePlot.YLim = [app.YaxisMinField.Value  app.YaxisMaxField.Value ];                    
                    else            
                        app.TimetracePlot.YLim = [-Inf Inf]; 
                    end
                
                % Plot triangles for frames where the drift correction happened  
                if app.ShowDriftCheckBox.Value == 1
                
                    if isfield(app.MainApp.ParticleFile(app.PartSpinner.Value).(app.TimetraceName), 'DriftCorrTrace') == 1
                        
                        time_axis = app.MainApp.ParticleFile(app.PartSpinner.Value).(app.TimetraceName).Time; 
                        drift_trace = app.MainApp.ParticleFile(app.PartSpinner.Value).(app.TimetraceName).DriftCorrTrace;
                        
                        time_axis(drift_trace==0) = [];
                        time_axis = reshape(time_axis,1,[]);
                        
                        drift_trace(drift_trace==0) = [];
                        drift_trace = reshape(drift_trace,1,[]);
                        
                        if app.ManualYaxisButton.Value == 1 
                            drift_trace =  ones(size(drift_trace)) .* app.YaxisMaxField.Value;   % ones(size(drift_trace)) .* 0.9 .* app.YaxisMaxField.Value                
                        else            
                            drift_trace =  ones(size(drift_trace)) .*(max(app.TimetraceToPlot));   % ones(size(drift_trace)) .* 0.9 .* app.YaxisMaxField.Value                            
                        end
                        
                        if isempty('drift_trace') ~= 1
                            scatter(app.TimetracePlot,time_axis,drift_trace,'kv','filled');
                        end                                                   
                    end
                end
                
                % Plotting the ROI
                imagesc(app.FOVPlot,app.MainApp.ParticleFile(app.PartSpinner.Value).(app.TimetraceName).RawData(:,:,app.FrameSpinner.Value));             
          
        else
            cla(app.TimetracePlot); 
            cla(app.FOVPlot);             
        end    
 
            
        end

        % Value changed function: BinWidthSlider
        function BinWidthSliderValueChanged(app, event)
            
            % Update the histogram as the slider value for bindwidth changes         
            app.BinWidth = round(app.BinWidthSlider.Value);
            UpdateSignalLevelsPlot(app);
                        
        end

        % Value changing function: BinWidthSlider
        function BinWidthSliderValueChanging(app, event)
            
            % Update the histogram as the slider value for bindwidth changes            
            app.BinWidth = round(event.Value);
            UpdateSignalLevelsPlot(app);
                                    
        end

        % Callback function
        function UpdateSignalLevelsPlot(app, event)
        
        if isfield(app.MainApp.ParticleFile(app.PartSpinner.Value).(app.TimetraceName), 'Intensity') == 1
                           
                cla(app.SignalLevelsPlot)
                hold(app.SignalLevelsPlot,'on')
                NBins = numhistbins(app,app.TimetraceToPlot);
               
                histogram(app.SignalLevelsPlot,app.TimetraceToPlot,NBins,'BinLimits',[floor(min(app.TimetraceToPlot)) ceil(max(app.TimetraceToPlot))],'FaceColor','red','Orientation','horizontal','LineWidth',0.3); 
                
                    if app.ManualYaxisButton.Value == 1 
                        app.SignalLevelsPlot.YLim = [app.YaxisMinField.Value  app.YaxisMaxField.Value ]; 
                        limY = [app.YaxisMinField.Value  app.YaxisMaxField.Value ];                         
                    else
                        app.SignalLevelsPlot.YLim = [min(app.TimetraceToPlot) max(app.TimetraceToPlot)]; 
                        limY = [min(app.TimetraceToPlot) max(app.TimetraceToPlot)]; 
                    end
            
                 
                 if exist('event') ~= 0 
                     if event.EventName == 'ButtonPushed'                                                          
                         SaveSignalHist(app.TimetraceToPlot,NBins,[floor(min(app.TimetraceToPlot)) ceil(max(app.TimetraceToPlot))],app.MainApp.FileFolder,app.TimetraceName,limY,app.PartSpinner.Value);                      
                     end
                 end     
                    
                    
        else            
            cla(app.SignalLevelsPlot);             
        end    
            
            
        end

        % Callback function
        function UpdateSampleFOVPlot(app, event)
            
   
        % show SNR andN (shot noise) for all particles with measured timetrace
        if app.AllPartsButton.Value == 1                
            inx = 1;
            for i = app.MainApp.ParticleFile(1).Number:app.MainApp.ParticleFile(end).Number
                
                location(inx,:) = app.PcleLocation(i,:);                          
                    if i == app.PartSpinner.Value
                        location_active = location(inx,:); 
                    end                  
                inx = inx+1;                        
                    
            end   
        end          
            
            
        % show contrast for only single particles with measured timetrace
        if app.SinglePartsButton.Value == 1                
            inx = 1;
            for i = app.MainApp.ParticleFile(1).Number:app.MainApp.ParticleFile(end).Number
          
                if app.MainApp.ParticleFile(i).SinglePart == 1  
                    location(inx,:) = app.PcleLocation(i,:);                          
                        if i == app.PartSpinner.Value
                            location_active = location(inx,:); 
                        end                  
                    inx = inx+1;     
                end   
                    
            end   
        end             
            
            
        % show contrast for only significant particles with measured timetrace and HSM
        if app.SignificantPartsButton.Value == 1                
            inx = 1;
            for i = app.MainApp.ParticleFile(1).Number:app.MainApp.ParticleFile(end).Number
                        
                 if app.MainApp.ParticleFile(i).SignificantPart == 1  
                    location(inx,:) = app.PcleLocation(i,:);                          
                        if i == app.PartSpinner.Value
                            location_active = location(inx,:); 
                        end                  
                    inx = inx+1;    
                 end   
               
            end   
        end            
       
             
        if exist('location') ~= 0
               
            cla(app.SampleFOVPlot);
            hold(app.SampleFOVPlot,'on');
            imagesc(app.SampleFOVPlot,app.SampleFOV);            
            app.SampleFOVPlot.XLim = [1 size(app.SampleFOV,1)];
            app.SampleFOVPlot.YLim = [1 size(app.SampleFOV,2)];
            app.SampleFOVPlot.ZLim = [0 10000];
            app.SampleFOVPlot.CLim = [0 10000];

            
            app.SampleFOVPlot.XTick = [];
            app.SampleFOVPlot.YTick = [];  
            
            for i_Beads = 1:size(location,1)
                rectangle(app.SampleFOVPlot,'Position',[location(i_Beads,1)-5,location(i_Beads,2)-5,11,11],'EdgeColor','white','Curvature',[1,1],'LineWidth',1);
            end   
            
            if exist('location_active') ~= 0
                rectangle(app.SampleFOVPlot,'Position',[location_active(1)-5,location_active(2)-5,11,11],'EdgeColor','red','Curvature',[1,1],'LineWidth',1,'FaceColor','red');
            end    

        else
                cla(app.SampleFOVPlot);            
        end        


        end

        % Value changed function: ShowDriftCheckBox
        function ShowDriftCheckBoxValueChanged(app, event)

            UpdateTimetracePlot(app)
            
        end

        % Value changed function: YaxisMinField
        function YaxisMinFieldValueChanged(app, event)
            
            UpdateTimetracePlot(app); 
            UpdateSignalLevelsPlot(app); 
            UpdateContrastPlot(app); 
            UpdateSNRPlot(app);
                        
        end

        % Value changed function: YaxisMaxField
        function YaxisMaxFieldValueChanged(app, event)
            
            UpdateTimetracePlot(app); 
            UpdateSignalLevelsPlot(app); 
            UpdateContrastPlot(app); 
            UpdateSNRPlot(app);
                        
        end

        % Callback function
        function UpdateXYtracePlot(app, event)
       
        if isfield(app.MainApp.ParticleFile(app.PartSpinner.Value).(app.TimetraceName), 'Intensity') == 1
            
            if isfield(app.MainApp.ParticleFile(app.PartSpinner.Value).(app.TimetraceName), 'Tracking') == 1                                                   

                    Xtrace = app.MainApp.ParticleFile(app.PartSpinner.Value).(app.TimetraceName).Tracking(:,1);
                    Ytrace = app.MainApp.ParticleFile(app.PartSpinner.Value).(app.TimetraceName).Tracking(:,2);
                           
                    if app.CorrectXYCheckBox.Value == 1                
                        Xtrace = detrend(Xtrace);
                        Ytrace = detrend(Ytrace);
                    end
                
                    Xlim_mean = mean(Xtrace);
                    Ylim_mean = mean(Ytrace);
                    Xlim_std = std(Xtrace);
                    Ylim_std = std(Ytrace);
                    
                    Xtrace = Xtrace - Xlim_mean;
                    Ytrace = Ytrace - Ylim_mean;
   
                cla(app.XYtracePlot)
                hold(app.XYtracePlot,'on')
                scatter(app.XYtracePlot,Xtrace(:),Ytrace(:),'r.');
                scatter(app.XYtracePlot,Xtrace(app.FrameSpinner.Value),Ytrace(app.FrameSpinner.Value),'bx');                            
                    
                if Xlim_std >= Ylim_std
                    app.XYtracePlot.XLim = [-3*Xlim_std 3*Xlim_std];
                    app.XYtracePlot.YLim = [-3*Xlim_std 3*Xlim_std];
                else    
                    app.XYtracePlot.XLim = [-3*Ylim_std 3*Ylim_std];
                    app.XYtracePlot.YLim = [-3*Ylim_std 3*Ylim_std];
                end 
                
            else
                cla(app.XYtracePlot);    
            end    
            
        else
            cla(app.XYtracePlot);
        end    
            
        end

        % Button pushed function: SNRButton
        function SNRButtonPushed(app, event)

            UpdateSNRPlot(app,event);          
            
        end

        % Button pushed function: SignalHistButton
        function SignalHistButtonPushed(app, event)
            
            UpdateSignalLevelsPlot(app,event);   
           
        end

        % Value changed function: CorrectXYCheckBox
        function CorrectXYCheckBoxValueChanged(app, event)
                        
           UpdateXYtracePlot(app); 
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @UpdateContrastPlot, true);
            app.ActionsPanel = uipanel(app.UIFigure);
            app.ActionsPanel.Title = 'Actions';
            app.ActionsPanel.SizeChangedFcn = createCallbackFcn(app, @UpdateSpectraPlot, true);
            app.ContrastButton = uibutton(app.ActionsPanel, 'push');
            app.ContrastButton.ButtonPushedFcn = createCallbackFcn(app, @ContrastButtonPushed, true);
            app.ContrastButton.Text = 'Contrast';
            app.SaveparticleButton = uibutton(app.ActionsPanel, 'push');
            app.SaveparticleButton.ButtonPushedFcn = createCallbackFcn(app, @SaveparticleButtonPushed, true);
            app.SaveparticleButton.Text = 'Save particle';
            app.FrmLabel = uilabel(app.ActionsPanel);
            app.FrmLabel.Text = 'Frm:';
            app.ContrastFrameField = uieditfield(app.ActionsPanel, 'numeric');
            app.ContrastFrameField.Limits = [0 Inf];
            app.ContrastFrameField.ValueDisplayFormat = '%.0f';
            app.ContrastFrameField.ValueChangedFcn = createCallbackFcn(app, @UpdateSNRPlot, true);
            app.ContrastFrameField.Value = 6000;
            app.SaveallButton = uibutton(app.ActionsPanel, 'push');
            app.SaveallButton.ButtonPushedFcn = createCallbackFcn(app, @SaveallButtonPushed, true);
            app.SaveallButton.Text = 'Save all';
            app.SNRButton = uibutton(app.ActionsPanel, 'push');
            app.SNRButton.ButtonPushedFcn = createCallbackFcn(app, @SNRButtonPushed, true);
            app.SNRButton.Text = 'SNR';
            app.SignalHistButton = uibutton(app.ActionsPanel, 'push');
            app.SignalHistButton.ButtonPushedFcn = createCallbackFcn(app, @SignalHistButtonPushed, true);
            app.SignalHistButton.Text = 'Signal Hist';
            app.TimetracePlot = uiaxes(app.UIFigure);
            title(app.TimetracePlot, 'Title')
            xlabel(app.TimetracePlot, 'Time (s)')
            ylabel(app.TimetracePlot, 'Scattered intensity (arb.u.)')
            app.FOVPlot = uiaxes(app.UIFigure);
            app.SpectraPlot = uiaxes(app.UIFigure);
            title(app.SpectraPlot, 'Particle spectra')
            xlabel(app.SpectraPlot, 'Wavelength (nm)')
            ylabel(app.SpectraPlot, 'Scat. cross sec. (arb.u.)')
            app.LabelSPlambda = uilabel(app.UIFigure);
            app.LabelSPgamma = uilabel(app.UIFigure);
            app.FrameLabel = uilabel(app.UIFigure);
            app.FrameLabel.Text = 'Frame';
            app.FrameSlider = uislider(app.UIFigure);
            app.FrameSlider.ValueChangedFcn = createCallbackFcn(app, @FrameSliderValueChanged, true);
            app.FrameSlider.ValueChangingFcn = createCallbackFcn(app, @FrameSliderValueChanging, true);
            app.ParticlePanel = uipanel(app.UIFigure);
            app.ParticlePanel.Title = 'Particle';
            app.PartSpinner = uispinner(app.ParticlePanel);
            app.PartSpinner.Limits = [1 Inf];
            app.PartSpinner.RoundFractionalValues = 'on';
            app.PartSpinner.ValueDisplayFormat = '%.0f';
            app.PartSpinner.ValueChangedFcn = createCallbackFcn(app, @PartSpinnerValueChanged, true);
            app.PartSpinner.Value = 1;
            app.SwitchSingle = uiswitch(app.ParticlePanel, 'slider');
            app.SwitchSingle.Items = {'CLUSTER', 'SINGLE'};
            app.SwitchSingle.ItemsData = {'0', '1'};
            app.SwitchSingle.ValueChangedFcn = createCallbackFcn(app, @SwitchSingleValueChanged, true);
            app.SwitchSingle.Value = '1';
            app.LampSingle = uilamp(app.ParticlePanel);
            app.LampSignificant = uilamp(app.ParticlePanel);
            app.SwitchSignificant = uiswitch(app.ParticlePanel, 'slider');
            app.SwitchSignificant.Items = {'no', 'SIGNIFICANT'};
            app.SwitchSignificant.ItemsData = {'0', '1'};
            app.SwitchSignificant.ValueChangedFcn = createCallbackFcn(app, @SwitchSignificantValueChanged, true);
            app.SwitchSignificant.Value = '1';
            app.MeasurementsPanel = uipanel(app.UIFigure);
            app.MeasurementsPanel.Title = 'Measurements';
            app.SpectraLabel = uilabel(app.MeasurementsPanel);
            app.SpectraLabel.Text = 'Spectra';
            app.HSMDropDown = uidropdown(app.MeasurementsPanel);
            app.HSMDropDown.Items = {'', 'Option 2', 'Option 3', 'Option 4'};
            app.HSMDropDown.ValueChangedFcn = createCallbackFcn(app, @HSMDropDownValueChanged, true);
            app.HSMDropDown.Value = '';
            app.TimetraceDropDownLabel = uilabel(app.MeasurementsPanel);
            app.TimetraceDropDownLabel.Text = 'Timetrace';
            app.TimetraceDropDown = uidropdown(app.MeasurementsPanel);
            app.TimetraceDropDown.Items = {'', 'Option 2', 'Option 3', 'Option 4'};
            app.TimetraceDropDown.ValueChangedFcn = createCallbackFcn(app, @TimetraceDropDownValueChanged, true);
            app.TimetraceDropDown.Value = '';
            app.LoadHSM = uilamp(app.MeasurementsPanel);
            app.LoadTimetrace = uilamp(app.MeasurementsPanel);
            app.ContrastPlot = uiaxes(app.UIFigure);
            title(app.ContrastPlot, 'Contrast')
            xlabel(app.ContrastPlot, 'Wavelength (nm)')
            ylabel(app.ContrastPlot, 'Contrast (arb.u.)')
            app.SNRPlot = uiaxes(app.UIFigure);
            title(app.SNRPlot, 'SNR')
            xlabel(app.SNRPlot, 'N')
            ylabel(app.SNRPlot, 'SNR and SN')
            app.SampleFOVPlot = uiaxes(app.UIFigure);
            app.SignalLevelsPlot = uiaxes(app.UIFigure);
            app.TimetracesettingsPanel = uipanel(app.UIFigure);
            app.TimetracesettingsPanel.Title = 'Timetrace settings';
            app.NormalizeButton = uibutton(app.TimetracesettingsPanel, 'state');
            app.NormalizeButton.ValueChangedFcn = createCallbackFcn(app, @NormalizeButtonValueChanged, true);
            app.NormalizeButton.Text = 'Normalize';
            app.ReffrmLabel = uilabel(app.TimetracesettingsPanel);
            app.ReffrmLabel.Text = 'Ref frm:';
            app.RefFrameField = uieditfield(app.TimetracesettingsPanel, 'numeric');
            app.RefFrameField.Limits = [4 Inf];
            app.RefFrameField.ValueDisplayFormat = '%.0f';
            app.RefFrameField.ValueChangedFcn = createCallbackFcn(app, @RefFrameFieldValueChanged, true);
            app.RefFrameField.Value = 200;
            app.ManualYaxisButton = uibutton(app.TimetracesettingsPanel, 'state');
            app.ManualYaxisButton.ValueChangedFcn = createCallbackFcn(app, @ManualYaxisButtonValueChanged, true);
            app.ManualYaxisButton.Text = 'Manual Yaxis';
            app.YaxisMaxField = uieditfield(app.TimetracesettingsPanel, 'numeric');
            app.YaxisMaxField.ValueChangedFcn = createCallbackFcn(app, @YaxisMaxFieldValueChanged, true);
            app.YaxisMaxField.Value = 1.1;
            app.YaxisMinField = uieditfield(app.TimetracesettingsPanel, 'numeric');
            app.YaxisMinField.ValueChangedFcn = createCallbackFcn(app, @YaxisMinFieldValueChanged, true);
            app.YaxisMinField.Value = 0.9;
            app.RmvspikesButton = uibutton(app.TimetracesettingsPanel, 'state');
            app.RmvspikesButton.ValueChangedFcn = createCallbackFcn(app, @RmvspikesButtonValueChanged, true);
            app.RmvspikesButton.Text = 'Rmv spikes';
            app.ShowDriftCheckBox = uicheckbox(app.TimetracesettingsPanel);
            app.ShowDriftCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowDriftCheckBoxValueChanged, true);
            app.ShowDriftCheckBox.Text = {'Show drift'; 'correction'};
            app.ShowPartsGroup = uibuttongroup(app.UIFigure);
            app.ShowPartsGroup.SelectionChangedFcn = createCallbackFcn(app, @ShowPartsGroupSelectionChanged, true);
            app.ShowPartsGroup.Title = 'Show particles:';
            app.AllPartsButton = uitogglebutton(app.ShowPartsGroup);
            app.AllPartsButton.Text = 'All';
            app.AllPartsButton.Value = true;
            app.SinglePartsButton = uitogglebutton(app.ShowPartsGroup);
            app.SinglePartsButton.Text = 'Single';
            app.SignificantPartsButton = uitogglebutton(app.ShowPartsGroup);
            app.SignificantPartsButton.Text = 'Significant';
            app.BinWidthSliderLabel = uilabel(app.UIFigure);
            app.BinWidthSliderLabel.Text = 'Bin Width';
            app.BinWidthSlider = uislider(app.UIFigure);
            app.BinWidthSlider.Limits = [1 9];
            app.BinWidthSlider.MajorTicks = [1 2 3 4 5 6 7 8 9];
            app.BinWidthSlider.ValueChangedFcn = createCallbackFcn(app, @BinWidthSliderValueChanged, true);
            app.BinWidthSlider.ValueChangingFcn = createCallbackFcn(app, @BinWidthSliderValueChanging, true);
            app.BinWidthSlider.MinorTicks = [];
            app.BinWidthSlider.Value = 5;
            app.XYtracePlot = uiaxes(app.UIFigure);
            app.FrameSpinnerLabel = uilabel(app.UIFigure);
            app.FrameSpinnerLabel.Text = 'Frame:';
            app.FrameSpinner = uispinner(app.UIFigure);
            app.FrameSpinner.ValueChangedFcn = createCallbackFcn(app, @FrameSpinnerValueChanged, true);
            app.TimesEditFieldLabel = uilabel(app.UIFigure);
            app.TimesEditFieldLabel.Text = 'Time (s):';
            app.TimeField = uieditfield(app.UIFigure, 'numeric');
            app.TimeField.Editable = 'off';
            app.CorrectXYCheckBox = uicheckbox(app.UIFigure);
            app.CorrectXYCheckBox.ValueChangedFcn = createCallbackFcn(app, @CorrectXYCheckBoxValueChanged, true);
            app.CorrectXYCheckBox.Text = 'Correct drift';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TimetraceAnalApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end